classdef MosaicAnalyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        LoadMosaicButton            matlab.ui.control.Button
        RecordROIButton             matlab.ui.control.Button
        LoadCTDataButton            matlab.ui.control.Button
        SegmentCellsButton          matlab.ui.control.Button
        BoxFOVsButton               matlab.ui.control.Button
        CountSpotsButton            matlab.ui.control.Button
        AddAnnotationtoTableButton  matlab.ui.control.Button
        LoadMosaicfirstthenLoadCTdatathenexploreLabel  matlab.ui.control.Label
        SaveAnnotationTableButton   matlab.ui.control.Button
        ShowCTspotsButton           matlab.ui.control.Button
        SliderLabel                 matlab.ui.control.Label
        Slider                      matlab.ui.control.Slider
        EditField                   matlab.ui.control.EditField
        ClearImageButton            matlab.ui.control.Button
        ShowAnnotationsButton       matlab.ui.control.Button
    end

    
    properties (Access = public)
        pars % list of editable parameters, as a structure, indexed by button name
        status % list of toggle box status
        nca % handle to the NcolorGUI
        fovBoxes % array of the corner points for all FOV referenced in the mosaic
        mosaicTable % records some data from mosaic, including FOV ULs and trim size
        mosaicSpotXYf = [] % fiducial spot XY positions in from CT in mosaic coordinates
        annotations % 
    end
    
    methods (Access = public)
        
        function  spotXY = XYabsToXYview(app,absXY)
           currOrigin = [app.nca.pixelBox.x(1)-1,app.nca.pixelBox.y(1)-1];
           spotXY = absXY*(app.nca.scale)^app.nca.currScale;
           spotXY = spotXY - repmat(currOrigin,size(spotXY,1),1);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            % Parameter default values (may be changed using varargin)
            % GUI defaults on startup
            defaults = cell(0,3);
            defaults(end+1,:) = {'title','string','Mosaic Analyzer'};
            startup_pars = ParseVariableArguments(varargin,defaults,'MosaicAnalyzer');
            app.UIFigure.Name = startup_pars.title;
            % -- defaults for buttons
            % ----- LoadMosaic parameters
            defaults = cell(0,3);
            defaults(end+1,:) = {'saveFolder','string',''};
            defaults(end+1,:) = {'rootName','string',''};
            defaults(end+1,:) = {'verbose','boolean',true};
            defaults(end+1,:) = {'figHandle','freeType',100}; %  
            app.pars.LoadMosaic = ParseVariableArguments(varargin,defaults,'MosaicAnalyzer');
            % ----- LoadCTData parameters
            defaults = cell(0,3);
            defaults(end+1,:) = {'folder','string',''};
            defaults(end+1,:) = {'verbose','boolean',true};
            app.pars.LoadCTData = ParseVariableArguments(varargin,defaults,'MosaicAnalyzer');
            % ----- SegmentCells parameters
            defaults = cell(0,3);
            defaults(end+1,:) = {'method',{'Watershed CT Data','use Channel'},'watershed CT Data'};
            defaults(end+1,:) = {'minSep','integer',10};
            defaults(end+1,:) = {'cellSize','integer',25};
            app.pars.SegmentCells = ParseVariableArguments(varargin,defaults,'MosaicAnalyzer');
            % ----- Record ROI parameters
            defaults = cell(0,3);
            defaults(end+1,:) = {'Points','array',[]};
            defaults(end+1,:) = {'PolygonData','freeType',[]};  % No option for type 'table'
            defaults(end+1,:) = {'MarkerTags','cell',{'1','2','3','4','5','6','7','8','9','10','11','12','13','14'}};
            defaults(end+1,:) = {'MarkerShape','integer','x'};
            defaults(end+1,:) = {'MarkerDefaultColor','array',[1,1,1]};
            app.pars.RecordROI = ParseVariableArguments(varargin,defaults,'MosaicAnalyzer');
            % ----- CountSpots parameters
            defaults = cell(0,3);
            defaults(end+1,:) = {'threshold','fraction',.1};
            app.pars.CountSpots = ParseVariableArguments(varargin,defaults,'MosaicAnalyzer');
            % ----- Add Annotation parameters
            defaults = cell(0,3);
            defaults(end+1,:) = {'annotation','freeType',[]};
            app.pars.AddAnnotation = ParseVariableArguments(varargin,defaults,'MosaicAnalyzer');
            % ----- Save Annotation parameters
            defaults = cell(0,3);
            defaults(end+1,:) = {'saveName','string',''};
            defaults(end+1,:) = {'saveFolder','string',''};
            app.pars.SaveAnnotation = ParseVariableArguments(varargin,defaults,'MosaicAnalyzer');
            % ----- Show Annotations parameters
            defaults = cell(0,3);
            defaults(end+1,:) = {'annotation',{'counted spots','ROI polygons','cell edges','box FOVs','CT spots'},'counted spots'};
            defaults(end+1,:) = {'showOnly','string',''};
            app.pars.ShowAnnotation = ParseVariableArguments(varargin,defaults,'MosaicAnalyzer');
            % -- defaults for toggle buttons
            app.status.BoxButtonON = false; % button not pressed 
            app.status.ShowCTButtonON = false;
            % -- some storage arrays for data export
            app.annotations.polyData = {};
            app.annotations.lastUpdate = '';
            app.annotations.cellBordersXY = [];
            app.annotations.spotCountTable = table(); % all saved RNA spot counts, in mosaic xy coords
            app.annotations.polygonTable = table(); % all saved ROI polygons, in mosaic xy coords
        end

        % Button pushed function: LoadMosaicButton
        function LoadMosaicButtonPushed(app, event)
            disp('select Mosaic tif file to load. Leave blank to launch a file browser to navigate to the file. Select just first image in the stack.')
            [app.pars.LoadMosaic,canceled] = SimpleParameterGUI(app.pars.LoadMosaic);
            mosaicFolder = app.pars.LoadMosaic.saveFolder;
            saveRoot = app.pars.LoadMosaic.rootName;
            verbose = app.pars.LoadMosaic.verbose;
            if canceled
                return
            end
            if isempty(saveRoot)
                [saveRoot,mosaicFolder] = uigetfile([mosaicFolder,'*.tif']);
                saveRoot = regexprep(saveRoot,'.tif','');
            end
            if strcmp(saveRoot(end-4:end),'_c001')
                fid = imread([mosaicFolder,saveRoot,'.tif']); % load chn1 to get size;
                saveRoot = regexprep(saveRoot,'_c001',''); % remove c1
            else
                fid = imread([mosaicFolder,saveRoot,'.tif'],1);  % load chn1 to get size;
            end
            % load channel names 
            chnNames = readtable([mosaicFolder,saveRoot,'Labels.txt'],'delimiter','\t');
            if isempty(chnNames)
                chnNames = {};
            else
                try
                    chnNames = chnNames.chnName;
                catch
                    chnNames = chnNames{:,1};
                end
            end
            nChns = length(chnNames);           
            bigMosaic = zeros([size(fid),nChns],'uint16'); % note this is very large. 
            if verbose
                disp('loading mosaic image, please wait...');
            end
            for c=1:nChns
                if verbose
                    disp(['loading channel ',num2str(c),'...']);
                end
                filesFound = cellstr(ls([mosaicFolder,saveRoot,'*.tif']));
                if contains(filesFound{1},'c001')
                    bigMosaic(:,:,c) = imread([mosaicFolder,filesFound{c}]);
                else % single tif file
                    bigMosaic(:,:,c) = imread([mosaicFolder,filesFound{1}],c);
                end
            end
            if verbose
                disp('mosaic loaded'); 
            end
            % load tile info (for overlays)
            mosTable = readtable([mosaicFolder,saveRoot,'tileULs.csv']);
            app.fovBoxes = [mosTable.x,mosTable.y,mosTable.w,mosTable.h];
            app.mosaicTable = mosTable; % save for other buttons
            app.annotations.chnNames = chnNames;
            % display tile
            tic
            app.nca = NcolorApp(bigMosaic,'names',chnNames,'figHandle',app.pars.LoadMosaic.figHandle); % allow access to NcolorApp props
            toc;
            
        end

        % Button pushed function: BoxFOVsButton
        function BoxFOVsButtonPushed(app, event)
            % disp(app.status)
            if ~app.status.BoxButtonON
                disp('showing FOV boxes')
                boxPostions = app.fovBoxes*(app.nca.scale)^app.nca.currScale;
                NcolorViewerFigure('figHandle',app.pars.LoadMosaic.figHandle,'display','none'); % call figure to the foreground
                hold on;
                for f=1:size(app.fovBoxes,1) % plot all the boxes 
                    rectangle('Position',boxPostions(f,:),'EdgeColor','y');
                end
                app.status.BoxButtonON = true;
                hold off;
            else
                allRectangles = findobj(gca,'Type','rectangle');
                delete(allRectangles);
                app.status.BoxButtonON = false;
            end
        end

        % Button pushed function: LoadCTDataButton
        function LoadCTDataButtonPushed(app, event)
            disp('select folder containing fov*_AllFits.csv files to load.')
            app.pars.LoadCTData = SimpleParameterGUI(app.pars.LoadCTData);
            mosTable = app.mosaicTable; % from load mosaic
            fitFolder = app.pars.LoadCTData.folder; 
            % 'L:\Leslie\Analyzed\2017-11-30_WT10-12hrs_HoxRNA_3kbBXC_CT2out\DNA\'; % parameter
            % -- Load data and convert coordinates
            % ---- maybe this should be a sparate function
            if ~isempty(fitFolder)
                fitFiles = cellstr(ls([fitFolder,'fov*_AllFits.csv']));
                numFOV = size(app.fovBoxes,1);
                XYf = cell(numFOV,1);
                for f=1:numFOV
                    fovFits = readtable([fitFolder,fitFiles{f}]);
                    if f==1
                        if any(strcmp(fovFits.Properties.VariableNames,'fsr')) 
                            pixnm = input('enter nm per pixel (scope1/2=154, scope3=108)  ');
                        else
                            pixnm =1; 
                        end
                    end
                    % -- plot spots 
                    [spotNums,spotStarts] = unique(fovFits.s,'stable');
                    numSpots = length(spotNums);
                    if app.pars.LoadCTData.verbose
                        disp(['loaded data for ',num2str(numSpots), ' chromosomes.'])
                    end
                    spotXY = [fovFits.locusX(spotStarts),fovFits.locusY(spotStarts)]/pixnm;
                    % ------- fix spot transpose/flip
                    if mosTable.transpose
                       spotXY = fliplr(spotXY); % exchange X and Y 
                    end
                    if mosTable.fliplr(f)
                        spotXY(:,1) = mosTable.w(f) + 2*mosTable.trim(f) - spotXY(:,1);
                    end
                    if mosTable.flipud(f)
                        spotXY(:,2) = mosTable.h(f) + 2*mosTable.trim(f) - spotXY(:,2);
                    end
                    XYf{f} = [app.fovBoxes(f,1)+spotXY(:,1) - mosTable.trim(f),...
                              app.fovBoxes(f,2)+spotXY(:,2) - mosTable.trim(f),...
                              repmat(f,numSpots,1),...
                              spotNums,...  % spot numbers (s)
                              spotStarts];  % idx spot starts
                end
                XYf = cat(1,XYf{:}); % one big table. 
                app.mosaicSpotXYf = XYf; % not dealing with double counting yet. 
                if app.pars.LoadCTData.verbose
                   disp('finished loading CT data') 
                end
            end
        end

        % Button pushed function: ShowCTspotsButton
        function ShowCTspotsButtonPushed(app, event)
            if isempty(app.mosaicSpotXYf)
               error('No CT data found to plot. Load CT data first and try again.')
            end
            if ~app.status.ShowCTButtonON
                disp('plotting spots')
                absXY = app.mosaicSpotXYf(:,1:2);
                spotXY = XYabsToXYview(app,absXY);
                % display image
                NcolorViewerFigure('figHandle',app.pars.LoadMosaic.figHandle,'display','none');
                hold on;
                plot(spotXY(:,1),spotXY(:,2),'yo');
                app.status.ShowCTButtonON = true;
                hold off;
            else
                disp('clearing spots')
                NcolorViewerFigure('figHandle',app.pars.LoadMosaic.figHandle,'display','none');
                spots = findobj(gca,'Type','Line');
                delete(spots);
                app.status.ShowCTButtonON = false;
            end
        end

        % Button pushed function: SegmentCellsButton
        function SegmentCellsButtonPushed(app, event)
            disp('segmenting all cells in mosaic, using CT spots as reference.')
            % ----- parameters
            [app.pars.SegmentCells,canceled] = SimpleParameterGUI(app.pars.SegmentCells);
            if ~canceled
                disp('this may be slow, please wait...')
                sc = 1; % downsampling parameter for segmentation.  this is MUCH faster if we downsample by 2 or 4 (6 vs 130s), but I haven't written the code to upsample again accurately yet
                % if we converted these to cell polygons (x,y lines, not pixels) then we could easily rescale up to full sized images? Polygons still have gaps of course after expanding...    
                minSep = app.pars.SegmentCells.minSep; % minSep = 10         
                cellSize = round(app.pars.SegmentCells.cellSize/sc); % 25
                % -- Main function
                % ------ Find and remove overlapping points 
                %       Overlap happens because of the 3D to 2D cell stacking, and
                %       because of imperfect matches edges. We need to increase
                %       the sparsity to get reasonable 2D cell segmentation. 
                xSpots = app.mosaicSpotXYf(:,1);
                ySpots = app.mosaicSpotXYf(:,2);
                maxX = app.nca.maxBox.x(2);
                maxY = app.nca.maxBox.y(2);
                h = ceil(maxY/minSep);
                w = ceil(maxX/minSep);
                cellID = sub2ind([h,w],round(ySpots/minSep),round(xSpots/minSep));
                [~,id] = unique(cellID);
                cellXY = [xSpots(id),ySpots(id)];
                            %-- troubleshooting plot
                % figure(1); clf; plot(xSpots,ySpots,'c.');
                % hold on; plot(cellXY(:,1),cellXY(:,2),'bo');
                % app.annotations.cellXY = cellXY; % record result
                % -- Actual segmentation based on cellXY
                tic;
                h = ceil(maxY/sc);
                w = ceil(maxX/sc);
                cellMap = false(h,w); % downsampled map
                idx = sub2ind([h,w],round(cellXY(:,2)/sc),round(cellXY(:,1)/sc));
                cellMap(idx) = true;
                % ------ watersheding the cell map
                im1 = bwdist(cellMap);
                im1 = watershed(im1);
                bw0 = im1==0; % cuts between cells
                bw1 = imdilate(cellMap,strel('disk',cellSize)); 
                bw1 = bw1 & ~bw0; 
                bw2 = imdilate(cellMap,strel('disk',cellSize+1)); % cell-areas
                bw = (bw2 & ~bw1); % borders of cells 
                toc
                % test
                % figure(1); clf; imagesc(bw);
                
                % ------- record results as a plotable array
                % app.annotations.cellEdges = find(bw); % save cell edges
                cellBorderXY = regionprops(bw1,'ConvexHull','Area','Centroid'); % a bit slow (20s), scales with number of 'on' objects? on pixels?.  (fast for bw)
                toc
                % -- convert cell array to a Nx2 XY Matrix, cells separated by NaNs   
                nCells = length(cellBorderXY);
                borderXY = cell(nCells,1);
                for n=1:length(cellBorderXY)
                    borderXY{n} = [cellBorderXY(n).ConvexHull*sc; NaN,NaN];
                end
                borderXY = cat(1,borderXY{:});
                app.annotations.cellBordersXY = borderXY;             
                % -- plot
                NcolorViewerFigure('figHandle',app.pars.LoadMosaic.figHandle,'display','none');
                % figure(1); clf;
                spotXY = XYabsToXYview(app,borderXY);  % app.annotations.cellBordersXY
                hold on;
                plot(spotXY(:,1),spotXY(:,2),'-'); 
                hold off;  
                app.annotations.lastUpdate = 'SegmentCells';
            end
        end

        % Button pushed function: RecordROIButton
        function RecordROIButtonPushed(app, event)
            disp('Enter the marker names you wish to record, (e.g. "Embryo1","Embryo2",... or "T1","T2","T3",...).')
            disp('All spots in the region displayed will be classified as one of these names.')
            disp('New marker names will be recorded as new columns in the annotations table.')
            disp('Reused marker names will be added as members of existing data column using logical OR.')
            % prompt update parameters
            [app.pars.RecordROI,canceled] = SimpleParameterGUI(app.pars.RecordROI);
            if ~canceled
                % get spotXY in current view
                currOrigin = [app.nca.pixelBox.x(1)-1,app.nca.pixelBox.y(1)-1];
                if ~isempty(app.mosaicSpotXYf)
                    spotXY = app.mosaicSpotXYf(:,1:2)*(app.nca.scale)^app.nca.currScale;
                    spotXY = spotXY - repmat(currOrigin,size(spotXY,1),1);
                else
                    warning('no CT data loaded. Load CT data first if you want it assigned within an ROI.')
                    spotXY = [];
                end
                % call Image_Segmentation GUI
                [segmentIDs, polygonData] = SegmentationGUI(app.pars.LoadMosaic.figHandle,... % just pass the current figure handle
                    'parameters',app.pars.RecordROI,'Points',spotXY);
                % convert polygonData.xy back to mosaic coordinates
                nROI = height(polygonData);
                for n=1:nROI
                    roiXY = polygonData.Positions{n};
                    roiXY = (roiXY + repmat(currOrigin,size(roiXY,1),1)) /(app.nca.scale)^app.nca.currScale;
                    polygonData.Positions{n} = roiXY;
                end
                app.annotations.polyDataTemp = polygonData;
                app.annotations.segIDs = segmentIDs; % strings, length spotXY. empty for spots not assigned annotation in this round.
                app.annotations.lastUpdate = 'RecordROI';
                % so the trick is just to not update IDs for unlabeled spots).
                disp('select "Add Annotation to Table" Button to save these annotations. If not added, they will be overwritten when Record ROI is pressed again.')
            end
        end

        % Button pushed function: CountSpotsButton
        function CountSpotsButtonPushed(app, event)
            % prompt update parameters
            % app.pars.CountSpots = SimpleParameterGUI(app.pars.CountSpots);
            %    - only parameter is in the slider.
            % ---- read in current view and threshold 
            disp('use slider to select threshold for spot counting.')
            disp('it is recommended that you first zoom in on a region of interest in the Ncolor GUI to calibrate the threshold.')
            disp('Then zoom out to the full mosaic to count all spots in the complete mosaic')
            disp('select Add Annotation to Table button to save your results.')
            xl = floor(app.nca.viewBox.x);
            yl = floor(app.nca.viewBox.y);
            sc = (app.nca.scale)^app.nca.currScale;
            c = app.nca.selectedChannels; % 27 = snail
            rnaIm = app.nca.im_orig(yl(1):yl(2),xl(1):xl(2),c); % apply to raw, non-contrasted image
            imMax = max(rnaIm(:));
            theta = app.Slider.Value/100*imMax;
            % ----- apply simple absolute threshold
            bw = imregionalmax(rnaIm); 
            bw2 = bw;
            bw2(rnaIm<theta)  = 0;
            [h,w] = size(rnaIm(:,:,1));
            [y,x] = ind2sub([h,w],find(bw2));
            brightness = rnaIm(bw2); 
            spotName = cellstr(repmat(app.annotations.chnNames{c},length(x),1)); 
            if length(x) <= 1
                disp('no spots found!');
            else
                 spotCounts = table(x,y,brightness,spotName);
                % ----- record results
                app.annotations.spotCountTemp = spotCounts;
                % ----- plot results
                % figure(2); clf; imagesc(rnaIm); hold on;
                NcolorViewerFigure('figHandle',app.pars.LoadMosaic.figHandle,'display','none');
                delete(findobj(gca,'Type','line')); % clear existing lines; 
                hold on;
                plot(x*sc,y*sc,'yo');
                hold off;
                app.annotations.lastUpdate = 'CountSpots';
            end
           
        end

        % Button pushed function: AddAnnotationtoTableButton
        function AddAnnotationtoTableButtonPushed(app, event)
            % app.pars.AddAnnotation = SimpleParameterGUI(app.pars.AddAnnotation);
            if ~isfield(app.annotations,'tableData')
                x =  app.mosaicSpotXYf(:,1);
                y =  app.mosaicSpotXYf(:,2);
                fov = app.mosaicSpotXYf(:,3);
                s = app.mosaicSpotXYf(:,4);
                sidx = app.mosaicSpotXYf(:,5);
                app.annotations.tableData = table(x,y,fov,s,sidx);
            end      
            % nSpots = height(app.annotations.tableData); % all fiducial spots. 
            switch app.annotations.lastUpdate
                case 'RecordROI'
                    % segIDs is a list of length nSpots with strings = new
                    % ID values (probably should change to a table). 
                    addedFields = unique(app.annotations.segIDs);
                    addedFields(cellfun(@isempty,addedFields)) = []; % remove empty fields
                    currentFields = app.annotations.tableData.Properties.VariableNames;
                    newFields = setdiff(addedFields,currentFields);
                    for f=1:length(newFields)
                        app.annotations.tableData.(newFields{f}) = app.annotations.segIDs == newFields{f};
                    end
                    fieldsToUpdate = intersect(currentFields,addedFields);
                    for f=1:length(fieldsToUpdate)
                        newValues = app.annotations.segIDs == fieldsToUpdate{f};
                        oldValues = app.annotations.tableData.(fieldsToUpdate{f});
                        app.annotations.tableData.(fieldsToUpdate{f}) = newValues | oldValues;
                    end
                    app.annotations.polygonTable = cat(1,...
                        app.annotations.polygonTable,...
                        app.annotations.polyDataTemp);
                    disp('ROI added to tableData');
                case 'CountSpots'
                    app.annotations.spotCountTable = cat(1,...
                            app.annotations.spotCountTable,...
                            app.annotations.spotCountTemp);
                        % x,y,brightness,chnName
                    disp('spot counts added to table')                  
                case 'SegmentCells'
                    NcolorViewerFigure('figHandle',app.pars.LoadMosaic.figHandle,'display','none');
                    % figure(1); clf;
                    spotXY = XYabsToXYview(app,app.annotations.cellBordersXY);  % 
                    hold on;
                    plot(spotXY(:,1),spotXY(:,2),'-'); 
                    hold off;                     
                otherwise
                    disp('no annotations to add')
            end
            
        end

        % Button pushed function: SaveAnnotationTableButton
        function SaveAnnotationTableButtonPushed(app, event)
            [app.pars.SaveAnnotation,canceled] = SimpleParameterGUI(app.pars.SaveAnnotation);
            if ~canceled
                saveFolder = app.pars.SaveAnnotation.saveFolder;
                saveRoot = app.pars.SaveAnnotation.saveName;
                if isempty(saveRoot)
                    saveFolder = uigetdir(app.pars.LoadMosaic.saveFolder);
                end
                disp('saving data')
                % write spot table
                tableData = app.annotations.tableData;
                tableName = [saveFolder,filesep,saveRoot,'_tableData.csv'];
                writetable(tableData,tableName); disp(['wrote ',tableName]);
                % write polygon table
                tableData = app.annotations.polygonTable;
                tableName = [saveFolder,filesep,saveRoot,'_polygonTable.csv'];
                writetable(tableData,tableName); disp(['wrote ',tableName]);
                % write RNA table
                tableData = app.annotations.spotCountTable;
                tableName = [saveFolder,filesep,saveRoot,'_spotCountTable.csv'];
                writetable(tableData,tableName); disp(['wrote ',tableName]);
                % write cell borders 
                cellBorders = (app.annotations.cellBordersXY); % convert nan to zeros
                % save([saveFolder,'cellBorders.mat'],'cellBorders','-mat'); % this is much faster
                if ~isempty(cellBorders)
                    fileName = [saveFolder,filesep,saveRoot,'_cellBordersXY.csv'];
                    fid = fopen(fileName,'w+');
                    fprintf(fid,'%5.1f %5.1f \n',cellBorders');
                    fclose(fid);
                end
                % cell borders segment cells;
            end
            
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            app.EditField.Value = num2str(value);
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            app.Slider.Value = str2double(value);
            
        end

        % Button pushed function: ClearImageButton
        function ClearImageButtonPushed(app, event)
            % clears all annotations from image
            NcolorViewerFigure('figHandle',app.pars.LoadMosaic.figHandle,'display','none');
            delete(findobj(gca,'Type','Line'));
            delete(findobj(gca,'Type','Scatter'));
            delete(findobj(gca,'Type','Text'));
            delete(findobj(gca,'Type','Group'));
            delete(findobj(gca,'Type','Patch'));
        end

        % Button pushed function: ShowAnnotationsButton
        function ShowAnnotationsButtonPushed(app, event)
            % Displays selected annotations
            disp(['Select annotation to show: ','counted spots','; ','ROI polygons','; ','cell edges','; ','box FOVs','; ','CT spots']);
            [app.pars.ShowAnnotation,canceled] = SimpleParameterGUI(app.pars.ShowAnnotation);
            if ~canceled
                switch app.pars.ShowAnnotation.annotation
                    case 'counted spots'
                        if ~isempty(app.annotations.spotCountTable)
                            spotTable = app.annotations.spotCountTable ;
                            disp(spotTable(1:3,:)); % needs testing
                            [flavors,idxStart] = unique(spotTable.spotName);
                            idxStart = cat(1,idxStart,height(spotTable));
                            disp('found the following spot types:'); disp(flavors);
                            % app.pars.ShowAnnotation.showOnly = '';
                            if ~isempty(app.pars.ShowAnnotation.showOnly)
                                selected = strsplit(app.pars.ShowAnnotation.showOnly,',');    
                                selected = contains(flavors,selected);
                            else
                                selected = 1:length(flavors);
                            end
                            sc = (app.nca.scale)^app.nca.currScale;
                            NcolorViewerFigure('figHandle',app.pars.LoadMosaic.figHandle,'display','none');
                            hold on;
                            cmap = hsv(length(selected)+1);
                            spotXY = XYabsToXYview(app,spotTable{:,1:2}); 
                            for c=selected
                                idxStart(c)
                                i = idxStart(c):idxStart(c+1)-1;
                                plot(spotXY(i,1),spotXY(i,2),'Marker','o','color',cmap(c,:),'LineStyle','none');
                                disp(['showing ',flavors{c}]); pause(.1);
                            end
                            legend(flavors(selected));
                            hold off;
                        else
                            disp('spotCount table is empty!')
                        end
                        
                        % now we need to plot these on the figure
                    case 'ROI polygons'
                        disp('annotations in polygon table:')
                        disp(app.annotations.polygonTable); % needs testing
                        inPoly = app.annotations.tableData{:,6:end}; % boolean of in polygon
                        inPolyName = app.annotations.tableData.Properties.VariableNames(6:end);
                        disp('annotations in spot table:');
                        disp(inPolyName')
                        nPolys = height(app.annotations.polygonTable);
                        hold on;
                        for n=1:nPolys % draw all the polygons
                            xy = app.annotations.polygonTable.Positions{n};
                            xy = cat(1,xy,xy(1,:)); % close the loop
                            xy = XYabsToXYview(app,xy);
                            plot(xy(:,1),xy(:,2),'.-');
                            text(xy(1,1),xy(1,2),app.annotations.polygonTable.Tag{n},'color','w');
                        end
                        nAnnot = size(inPoly,2);
                        for n=1:nAnnot % mark all the spots (should be inside the polygons)
                           xy = app.annotations.tableData{inPoly(:,n),{'x','y'}};
                           xy = XYabsToXYview(app,xy);
                           plot(xy(:,1),xy(:,2),'+');
                           text(xy(end,1),xy(end,2),inPolyName{n},'color','w');
                        end
                        hold off;
                        
                        % plot these on the figure
                    case 'cell edges'
                        NcolorViewerFigure('figHandle',app.pars.LoadMosaic.figHandle,'display','none');
                        % figure(1); clf;
                        spotXY = XYabsToXYview(app,app.annotations.cellBordersXY);  % 
                        hold on;
                        plot(spotXY(:,1),spotXY(:,2),'-'); 
                        hold off;   
                    case 'box FOVs'
                        BoxFOVsButtonPushed(app, event);
                        % already written as a buttong (could consolidate)
                    case 'CT spots'
                        ShowCTspotsButtonPushed(app, event);
                        % already written as a buttong (could consolidate)
                    otherwise 
            
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.LoadMosaicButton = uibutton(app.UIFigure, 'push');
            app.LoadMosaicButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMosaicButtonPushed, true);
            app.LoadMosaicButton.Text = 'Load Mosaic';
            app.RecordROIButton = uibutton(app.UIFigure, 'push');
            app.RecordROIButton.ButtonPushedFcn = createCallbackFcn(app, @RecordROIButtonPushed, true);
            app.RecordROIButton.Text = 'Record ROI';
            app.LoadCTDataButton = uibutton(app.UIFigure, 'push');
            app.LoadCTDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCTDataButtonPushed, true);
            app.LoadCTDataButton.Text = 'Load CT Data';
            app.SegmentCellsButton = uibutton(app.UIFigure, 'push');
            app.SegmentCellsButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentCellsButtonPushed, true);
            app.SegmentCellsButton.Text = 'Segment Cells';
            app.BoxFOVsButton = uibutton(app.UIFigure, 'push');
            app.BoxFOVsButton.ButtonPushedFcn = createCallbackFcn(app, @BoxFOVsButtonPushed, true);
            app.BoxFOVsButton.Text = 'Box FOVs';
            app.CountSpotsButton = uibutton(app.UIFigure, 'push');
            app.CountSpotsButton.ButtonPushedFcn = createCallbackFcn(app, @CountSpotsButtonPushed, true);
            app.CountSpotsButton.Text = 'Count Spots';
            app.AddAnnotationtoTableButton = uibutton(app.UIFigure, 'push');
            app.AddAnnotationtoTableButton.ButtonPushedFcn = createCallbackFcn(app, @AddAnnotationtoTableButtonPushed, true);
            app.AddAnnotationtoTableButton.Text = 'Add Annotation to Table';
            app.LoadMosaicfirstthenLoadCTdatathenexploreLabel = uilabel(app.UIFigure);
            app.LoadMosaicfirstthenLoadCTdatathenexploreLabel.Text = 'Load Mosaic first, then Load CT data, then explore.';
            app.SaveAnnotationTableButton = uibutton(app.UIFigure, 'push');
            app.SaveAnnotationTableButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAnnotationTableButtonPushed, true);
            app.SaveAnnotationTableButton.Text = 'Save Annotation Table';
            app.ShowCTspotsButton = uibutton(app.UIFigure, 'push');
            app.ShowCTspotsButton.ButtonPushedFcn = createCallbackFcn(app, @ShowCTspotsButtonPushed, true);
            app.ShowCTspotsButton.Text = 'Show CT spots';
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Value = 99;
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.ClearImageButton = uibutton(app.UIFigure, 'push');
            app.ClearImageButton.ButtonPushedFcn = createCallbackFcn(app, @ClearImageButtonPushed, true);
            app.ClearImageButton.Text = 'Clear Image';
            app.ShowAnnotationsButton = uibutton(app.UIFigure, 'push');
            app.ShowAnnotationsButton.ButtonPushedFcn = createCallbackFcn(app, @ShowAnnotationsButtonPushed, true);
            app.ShowAnnotationsButton.Text = 'Show Annotations';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MosaicAnalyzer(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end