classdef AnalysisStepApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        FileMenu          matlab.ui.container.Menu
        Menu              matlab.ui.container.Menu
        ParametersButton  matlab.ui.control.Button
        RunStepButton     matlab.ui.control.Button
        BackStepButton    matlab.ui.control.Button
        TextArea          matlab.ui.control.TextArea
        NextStepButton    matlab.ui.control.Button
    end

    
    properties (Access = public)
        % This is app is a stub for step GUIs
        pars % parameters
        data
        props
    end
    
    methods (Access = public)
        
        function StepSetup(app)
            n = app.props.currStep;
            app.TextArea.Value = app.props.stepDescriptions{n};
            app.RunStepButton.Text = app.props.stepNames{n};
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
             % default parameters
            defaults = cell(0,3);
            defaults(end+1,:) = {'figHandle','integer',200};
            defaults(end+1,:) = {'saveFolder','string',''};
            defaults(end+1,:) = {'eTableXLS','string',''};
            app.pars = ParseVariableArguments(varargin,defaults,mfilename); 
            
            %% Step parameters
            app.props.stepNames = {};
            app.props.stepDescriptions = {};
            app.props.currStep = 1;
            app.pars.stepPars = {};
            
            %%----  step specific pars
            % (using ParseVariableArguments helps support copy paste)
            % Step 1
            app.props.stepNames{end+1} = 'Load eTable';
            app.props.stepDescriptions{end+1} = ['Load Experiment' ...
                '  Layout table and set a default save path.'];
            app.pars.stepPars{end+1} = GetFunctionOptions('LoadDaxFromEtable.m');
            app.pars.stepPars{end}.fov = inf; % update default options
            app.pars.stepPars{end}.hybNumber = inf; % add additional parameters
            
            % Step 2
            app.props.stepNames{end+1} = 'Align Images';
            app.props.stepDescriptions{end+1} = 'Align images from between all hyb folders in experiment. Registration files are saved as fovNNN_regData.csv';
            app.pars.stepPars{end+1} = GetFunctionOptions('ChrTracer3p2_FixGlobalDrift.m');
            
            % Step 3
            app.props.stepNames{end+1} = 'Find Borders';
            app.props.stepDescriptions{end+1} = 'Find cell centroids first, then find cell borders. Results are saved as fovNNN_cellBorders.csv';
            app.pars.stepPars{end+1} = GetFunctionOptions('FindNucleiGUI.m');
            app.pars.stepPars{end}.selFOV = []; % add additional parameters
            
            % Step 3
            app.props.stepNames{end+1} = 'Fit Spots';
            app.props.stepDescriptions{end+1} = 'Find 3D positions of the 2 brightest spots in each cell. Results are saved as fovNNN_spotTable.csv';
            app.pars.stepPars{end+1} = GetFunctionOptions('SpotsInCellsFromDax.m');
            app.pars.stepPars{end}.selFOV = []; % add additional parameters
            
            %%----- General App handling
            % handle multiple instances,
            guiName = 'CellSpotFitter';
            app.UIFigure.Tag = guiName;
            handles = findall(0,'Tag',app.UIFigure.Tag);
            app.pars.instanceID = length(handles);
            app.pars.title = [guiName,num2str(app.pars.instanceID)];
            app.UIFigure.Name  = app.pars.title;
            % link a figure
            app.pars.figHandle = app.pars.figHandle + app.pars.instanceID-1; 
            % export handle to the base 
            assignin('base',app.pars.title,app);
            
            % final setup
            StepSetup(app);
        end

        % Button pushed function: RunStepButton
        function RunStepButtonPushed(app, event)
            % All the (GUI specific) action happens here
            %   Typically each step will load parameters and call an
            %   external function
            if app.props.currStep==1
                par = app.pars.stepPars{1};
                if isempty(app.pars.eTableXLS)
                    [eFile,app.pars.dataFolder] = uigetfile('*.xlsx');
                    app.pars.eTableXLS = [app.pars.dataFolder,eFile]; 
                end
                app.TextArea.Value='Loading data, please wait...'; pause(.01);
                app.data.daxInHybFov = LoadDaxFromEtable(app.pars.eTableXLS,'parameters',par);
                app.TextArea.Value='data loaded, select Next Step.';
                % daxInHybFov = nHybs x nFOVs x datachannels
                % dataType 'all'  daxInHybFov  datachannels are fid, dataRed, dataBlue
                
            elseif app.props.currStep==2
                par = app.pars.stepPars{2};
                if isempty(app.pars.saveFolder)
                    saveFolder = uigetdir(app.pars.dataFolder,'Select a save path for alignment files.');
                    app.pars.saveFolder = [saveFolder,filesep]; % enfoce filesep ending for folders
                end
                app.TextArea.Value='Aligning images, please wait...';
                ChrTracer3p2_FixGlobalDrift( app.data.daxInHybFov(:,:,1),...
                    'saveFolder',app.pars.saveFolder,'parameters',par);
                app.TextArea.Value='drift correction complete, select Next Step.';
                % saves fovNNN_regData to specified save folder
                
            elseif app.props.currStep==3               
                par = app.pars.stepPars{3};
                nFOV = size(app.data.daxInHybFov,2);
                if isempty(par.selFOV)
                    selFOV = 1:nFOV;
                end
                for fov=selFOV
                    selectionComplete = false;
                    bordersComplete = false;
                    % check for existing cellBorders.csv
                    saveName = [app.pars.saveFolder,'fov',num2str(fov,'%03d'),'_cellBorders.csv'];
                    if exist(saveName,'file')~=0
                        cellBorderTable = readtable(saveName);
                        nCells = max(cellBorderTable.cellNum);
                    else
                        nCells = 0;
                    end
                    % select an image frame of each field of view
                    %   if a previous selection was saved, start there
                    app.TextArea.Value='Select a reference image. Close image to continue';
                    refImage = [app.pars.saveFolder,'fov',num2str(fov,'%03d'),'_refImage.jpg'];
                    if exist(refImage,'file')~=0
                        selImage = makeuint(imread(refImage),16);
                        selectionComplete = true;
                        % ---- Plot cell borders
                        figure(app.pars.figHandle); clf;  imagesc(selImage);
                        colorbar; hold on;
                        cMap = GetColorMap('hsvCut',nCells);
                        for c=1:nCells % won't execute if nCells = 0;
                            isC = cellBorderTable.cellNum == c;
                            plot(cellBorderTable.x(isC),cellBorderTable.y(isC),'.-','color',cMap(c,:));
                        end
                        answer = questdlg('Please choose:', ... % question
                                          'Reply ', ...  % pop-up label
                                          'Select new image','Refit this image','Skip to next image','Skip to next image'); % op1 op2 op3 default
                        if strcmp(answer,'Select new image') 
                            selectionComplete = false;
                            bordersComplete = false; 
                        elseif strcmp(answer,'Refit this image')
                            selectionComplete = true;
                            bordersComplete = false;
                        elseif strcmp(answer,'Skip to next image')
                            selectionComplete = true;
                            bordersComplete = true;
                        end
                    end
                    % select an image that resolves the cells nicely
                    imPars.currHyb = 1;
                    imPars.currChn = 1;
                    while ~selectionComplete
                        c = imPars.currChn;
                        h = imPars.currHyb;
                        selImage = app.data.daxInHybFov{h,fov,c};
                        fh = figure(app.pars.figHandle); clf;  imagesc(selImage);
                        colorbar; 
                        uiwait(fh);
                        imPars = SimpleParameterGUI(imPars);
                        if imPars.currChn == c && imPars.currHyb == h % if no change
                            selectionComplete = true;
                        end
                    end
                    if ~bordersComplete
                        imwrite(makeuint(selImage,8),refImage);
                        cellBorderTable = FindNucleiGUI(selImage,'parameters',par);
                        writetable(cellBorderTable,saveName);
                        disp(['wrote ', saveName]);
                        % find Nuclei and write results to disk
                    end
                end
                app.TextArea.Value='Finished processing Cell Borders, select Next Step.';
                
            elseif app.props.currStep==4
                par = app.pars.stepPars{4};
                nFOV = size(app.data.daxInHybFov,2);
                if isempty(par.selFOV)
                    selFOV = 1:nFOV;
                end
                for fov=selFOV
                    skip = false;
                    saveName = [app.pars.saveFolder,'fov',num2str(fov,'%03d'),'_spotFits.csv'];
                    if exist(saveName) %#ok<*EXIST> 
                         answer = questdlg(['Found existing fits for FOV',num2str(fov)], ... % question
                                          'Please choose: ', ...  % pop-up label
                                          'Overwrite','Skip','Skip'); 
                         if strcmp(answer,'Skip')
                            skip = true;
                         end
                    end
                    cellBordName = [app.pars.saveFolder,'fov',num2str(fov,'%03d'),'_cellBorders.csv'];
                    if exist(cellBordName)
                        cellBorderTable = readtable(cellBordName);
                    else
                        warning(['did not find cell borders for FOV',num2str(fov)]);
                        skip = true;
                    end
                    if ~skip
                        spotTablePerRead = SpotsInCellsFromDax(cellBorderTable,fov,...
                            app.pars.eTableXLS,...
                            'dataFolder',app.pars.dataFolder, ...
                            'analysisFolder',app.pars.saveFolder, ...
                            'parameters',par);
                        save(regexprep(saveName,'.csv','.mat'),"spotTablePerRead");
                        % writetable(spotTablePerRead,saveName);
                        disp(['wrote ', saveName]);
                    end
                    % optional image outputs currently repressed:cellProj,cropSpotsPerRead.   
                end 
            end
        end

        % Button pushed function: ParametersButton
        function ParametersButtonPushed(app, event)
            n = app.props.currStep;
            app.pars.stepPars{n} = SimpleParameterGUI(app.pars.stepPars{n});
        end

        % Button pushed function: NextStepButton
        function NextStepButtonPushed(app, event)
            app.props.currStep = app.props.currStep + 1;
            StepSetup(app);
        end

        % Button pushed function: BackStepButton
        function BackStepButtonPushed(app, event)
            app.props.currStep = app.props.currStep - 1;
            StepSetup(app);            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.Menu = uimenu(app.FileMenu);
            app.NextStepButton = uibutton(app.UIFigure, 'push');
            app.NextStepButton.ButtonPushedFcn = createCallbackFcn(app, @NextStepButtonPushed, true);
            app.NextStepButton.Text = 'Next Step';
            app.TextArea = uitextarea(app.UIFigure);
            app.BackStepButton = uibutton(app.UIFigure, 'push');
            app.BackStepButton.ButtonPushedFcn = createCallbackFcn(app, @BackStepButtonPushed, true);
            app.BackStepButton.Text = 'Back Step';
            app.RunStepButton = uibutton(app.UIFigure, 'push');
            app.RunStepButton.ButtonPushedFcn = createCallbackFcn(app, @RunStepButtonPushed, true);
            app.RunStepButton.Text = 'Run Step';
            app.ParametersButton = uibutton(app.UIFigure, 'push');
            app.ParametersButton.ButtonPushedFcn = createCallbackFcn(app, @ParametersButtonPushed, true);
            app.ParametersButton.Text = 'Parameters';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AnalysisStepApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end