classdef DaxApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        FileMenu                  matlab.ui.container.Menu
        LoadDaxMenu               matlab.ui.container.Menu
        Load2CamDaxMenu           matlab.ui.container.Menu
        LoadHybSeriesMenu         matlab.ui.container.Menu
        LoadFitsMenu              matlab.ui.container.Menu
        AnalysisMenu              matlab.ui.container.Menu
        FitParametersMenu         matlab.ui.container.Menu
        FitSpotsMenu              matlab.ui.container.Menu
        FitZdataMenu              matlab.ui.container.Menu
        SaveFitParametersMenu     matlab.ui.container.Menu
        FitAllFramesMenu          matlab.ui.container.Menu
        Toolbar                   matlab.ui.container.Toolbar
        UpdateTool                matlab.ui.container.toolbar.PushTool
        ChangeContrastTool        matlab.ui.container.toolbar.PushTool
        FitSpotsTool              matlab.ui.container.toolbar.PushTool
        ZprojectTool              matlab.ui.container.toolbar.PushTool
        PlotOffsetTool            matlab.ui.container.toolbar.PushTool
        cframeperzEditField       matlab.ui.control.NumericEditField
        cframeperzEditFieldLabel  matlab.ui.control.Label
        zframepertEditField       matlab.ui.control.NumericEditField
        zframepertEditFieldLabel  matlab.ui.control.Label
        tSpinner                  matlab.ui.control.Spinner
        tSpinnerLabel             matlab.ui.control.Label
    end

    
    properties (Access = public)
        pars % parameters    
        data % data
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'Dax Viewer App';
            % show dax init pars
            defaults = cell(0,3);
            defaults(end+1,:) = {'figHandle','handle',100};
            defaults(end+1,:) = {'daxType',{'Zstack','Movie','2cam'},'Zstack'};
            defaults(end+1,:) = {'numChannels','integer',1};
            defaults(end+1,:) = {'framesPerTime','integer',1}; % load all
            defaults(end+1,:) = {'channelOrder',{'alternate','sequential'},'alternate'};
            defaults(end+1,:) = {'showOverlay','boolean',true};
            defaults(end+1,:) = {'autoContrastMin','fraction',.3};
            defaults(end+1,:) = {'autoContrastMax','fraction',.9999};
            defaults(end+1,:) = {'currTime','integer',0};  % current time to show
            app.pars.showDaxPars = ParseVariableArguments([],defaults,mfilename); 


            % Parse default parameters
            defaults = cell(0,3);
            defaults(end+1,:) = {'parsFile','string',''}; %  use this parameter file for analysis, ignore other entries
            defaults(end+1,:) = {'threshold','nonnegative',45}; 
            defaults(end+1,:) = {'iterations','nonnegative',20}; 
            defaults(end+1,:) = {'sigma','nonnegative',1.3}; % PSF sigma 
            defaults(end+1,:) = {'background_sigma','nonnegative',8}; % 
            defaults(end+1,:) = {'foreground_sigma','nonnegative',2}; %
            defaults(end+1,:) = {'camera_offset','nonnegative',100}; %  
            defaults(end+1,:) = {'find_max_radius','nonnegative',10}; % 
            defaults(end+1,:) = {'model',{'2d','2dfixed','3d','Z'},'2d'}; % 
            defaults(end+1,:) = {'pixel_size','nonnegative',100}; %  
            app.pars.daoFitPars = ParseVariableArguments([],defaults);
            
            % intialize some GUI pars
            app.pars.currFolder = '';
            app.pars.daxFile = '';

        end

        % Menu selected function: LoadDaxMenu
        function LoadDaxMenuSelected(app, event)
                [daxName,folder] = uigetfile('*.dax');
                if ~isempty(daxName); % do nouthing if cancelled
                    app.pars.daxFile = [folder,filesep,daxName];
                    [app.data.im,~,~,~,~,app.data.daxData] = ShowDax(app.pars.daxFile,'parameters',app.pars.showDaxPars);
                end
        end

        % Value changed function: tSpinner
        function tSpinnerValueChanged(app, event)
            value = app.tSpinner.Value;
            app.pars.showDaxPars.currTime = value;
            figure(app.pars.showDaxPars.figHandle);
            xl = floor(get(gca,'xlim')); 
            yl = floor(get(gca,'ylim'));
            app.data.im = ShowDax(app.pars.daxFile,'parameters',app.pars.showDaxPars,'xylim',[xl,yl]);
        end

        % Value changed function: zframepertEditField
        function zframepertEditFieldValueChanged(app, event)
            value = app.zframepertEditField.Value;
            app.pars.showDaxPars.framesPerTime = value;
        end

        % Value changed function: cframeperzEditField
        function cframeperzEditFieldValueChanged(app, event)
            value = app.cframeperzEditField.Value;
            app.pars.showDaxPars.numChannels = value;
        end

        % Callback function: UpdateTool
        function UpdateToolClicked(app, event)
             app.data.im = ShowDax(app.pars.daxFile,'parameters',app.pars.showDaxPars);
        end

        % Callback function: ZprojectTool
        function ZprojectToolClicked(app, event)
            figure(app.pars.showDaxPars.figHandle);
            xl = floor(get(gca,'xlim')); 
            yl = floor(get(gca,'ylim'));
            ShowXZ(app.data.im,'parameters',app.pars.showDaxPars,'xylim',[xl,yl]); 
        end

        % Callback function: ChangeContrastTool
        function ChangeContrastToolClicked(app, event)
            currValues.contrastMin = app.pars.showDaxPars.autoContrastMin;
            currValues.contrastMax = app.pars.showDaxPars.autoContrastMax;
            newValues = SimpleParameterGUI(currValues);
            app.pars.showDaxPars.autoContrastMin = newValues.contrastMin;
            app.pars.showDaxPars.autoContrastMax = newValues.contrastMax;  
        end

        % Callback function: FitSpotsTool
        function FitSpotsToolClicked(app, event)
            % parsFile = 'C:\Users\Alistair.BOETTIGERSERVER\Documents\MATLAB\fitPars_allframe_iters.xml';
            numChannels = length(app.data.im);
            app.data.currFits = cell(1,numChannels);
            spotXY = cell(1,numChannels);
            figure(app.pars.showDaxPars.figHandle);
            xl = floor(get(gca,'xlim')); 
            yl = floor(get(gca,'ylim'));
            for c=1:numChannels
                % fit all frames of the image
                currIm = app.data.im{c}(yl(1)+1:yl(2),xl(1)+1:xl(2),:);
                 cfits = DaoFit(currIm,...
                  'parameters',app.pars.daoFitPars, ...
                   'max_frame',-1,'overwrite','overwrite','showPlots',0); % this saves 
                 % % %   to Scratch so we want to be sure we do overwrite
                 %   existing files and not reload them, and that we
                 %   analyze all frames passed (sine this writes a new
                 %   dax).
                %  cfits = DaoFit(currIm,'parsFile',parsFile,'showPlots',0,'overwriteFit',true,'rerun',true); 
                % figure(1); clf; imagesc(currIm); hold on;
                % figure(app.pars.showDaxPars.figHandle); hold on;
                % ;
                app.data.currFits{c} = cfits;
                x =  cat(1, cfits.x)+xl(1) +1;
                y = cat(1,cfits.y)+yl(1)+1;
                spotXY{c} = [x, y];
                
            end
            ShowXY(app.data.im,'spotXY',spotXY,'parameters',app.pars.showDaxPars,'xylim',[xl,yl]);
        end

        % Menu selected function: FitParametersMenu
        function FitParametersMenuSelected(app, event)
            newValues = SimpleParameterGUI(app.pars.daoFitPars);
            disp(newValues)
            app.pars.daoFitPars = newValues;
        end

        % Menu selected function: LoadHybSeriesMenu
        function LoadHybSeriesMenuSelected(app, event)
            if ~isfield(app.pars,'loadHybOptions')
                newValues.dataFolder = '';
                newValues.hybFolderRoot = 'Hyb*';
                newValues.daxName = 'ConvZscan_01.dax';
                newValues.selectHybes = 0;
                newValues.offsetFrame = 0;
                newValues.numChannels = 2;
                newValues.selectChannels = 1;
                newValues.driftCorrectUsingChn=0;
                newValues.analysisFolder = './Analysis/';
                newValues = SimpleParameterGUI(newValues);
                app.pars.loadHybOptions = newValues;
            else
                newValues = app.pars.loadHybOptions;
                newValues = SimpleParameterGUI(newValues);
                app.pars.loadHybOptions = newValues;
            end
            if ~isempty(newValues)
                dataFolders = FindFiles([newValues.dataFolder, filesep, newValues.hybFolderRoot],'onlyFolders',true);
                daxFiles = strcat(dataFolders,newValues.daxName);
                if all(strcmp(newValues.analysisFolder(1:2),'./'))
                    newValues.analysisFolder = [newValues.dataFolder,newValues.analysisFolder(3:end)];
                end
                [app.data.im,app.data.xyMax,app.data.xyOut] = ShowDaxSeries(daxFiles,'parameters',newValues); 
            end
        end

        % Menu selected function: Load2CamDaxMenu
        function Load2CamDaxMenuSelected(app, event)
            newValues.daxFileC1 = '';
            newValues.alignment_file = 'F:\DefaultPars\alignmentData.txt';
            newValues = SimpleParameterGUI(newValues);
            if isempty(newValues.daxFileC1)
                [daxName,folder] = uigetfile([app.pars.currFolder,'*.dax']);
                app.pars.currFolder = folder;
            else
                [folder,daxName] = fileparts(newValues.daxFileC1);
                if ~isempty(daxName)
                    daxName = [daxName,'.dax'];
                end
            end
            if ~isempty(daxName) && daxName(1) ~= 0 % if canceled, returns empty, do nothing
                app.pars.daxFile = [folder,filesep,daxName];
                app.pars.showDaxPars.daxType = '2cam';
                app.pars.showDaxPars.alignment_file = newValues.alignment_file;
                app.data.im = ShowDax(app.pars.daxFile,'parameters',app.pars.showDaxPars);
            end
        end

        % Menu selected function: FitSpotsMenu
        function FitSpotsMenuSelected(app, event)
            FitSpotsToolClicked(app, event)
        end

        % Menu selected function: SaveFitParametersMenu
        function SaveFitParametersMenuSelected(app, event)
            global scratchPath;
            currFolder = fileparts(app.pars.daxFile);
            [file,path] = uiputfile(currFolder);
            if ~isempty(file) % if canceled, returns empty, do nothing
                newFilePars = [path,filesep,file];
                scratchXmlFile = [scratchPath,'tempDaoPars.xml'];
                disp(['writing ',newFilePars]);
                system(['copy ',scratchXmlFile, ' ',newFilePars]);
            end
        end

        % Menu selected function: FitAllFramesMenu
        function FitAllFramesMenuSelected(app, event)
            % get parameters
            app.pars.daxFile = [folder,filesep,daxName]; % daxfile
            scratchXmlFile = [scratchPath,'tempDaoPars.xml']; % pars file
            updateFields = {'max_frame','start_frame'};
            updateValues = {0,-1};
            UpdateTxtPars(scratchXmlFile,scratchXmlFile,updateFields,updateValues,'verbose',false); % update parameters
            % write parameters
            app.pars.currFolder = fileparts(app.pars.daxFile);
            [file,app.pars.currFolder] = uiputfile(app.pars.currFolder);
            if ~isempty(app.pars.currFolder)  % do nothing if cancelled
                newFilePars = [app.pars.currFolder,file];
                disp(['writing ',newFilePars]);
                system(['copy ',scratchXmlFile, ' ',newFilePars]);
                DaoFit(app.pars.daxFile,'parsFile',newFilePars,'runExternal',true);
            end
        end

        % Menu selected function: LoadFitsMenu
        function LoadFitsMenuSelected(app, event)
            disp('hold down control to select multiple fit files'); % ,'*.*'
            folder = app.pars.currFolder;
            [filenames,app.pars.currFolder] = uigetfile([folder,'*.hdf5'],  'Select fit files','MultiSelect','on');
            [chromFile,currFolder] = uigetfile([folder,'*.mat'],  'Select chromatic file, or cancel to use default');
            app.pars.showDaxPars.chrom_correct_file = [currFolder,chromFile];
            if isempty(chromFile) || chromFile==0
                app.pars.showDaxPars.chrom_correct_file = 'F:\DefaultPars\tform3D.mat';
            end
            if ~isempty(filenames) % do nothing if cancelled
                if ~iscell(filenames) 
                    filenames = {filenames};
                end
                binNames = strcat(app.pars.currFolder,filenames);
                app.pars.showDaxPars.fitFile = binNames;
                app.data.im = ShowDax(app.pars.daxFile,'parameters',app.pars.showDaxPars);
            end
        end

        % Callback function: PlotOffsetTool
        function PlotOffsetToolClicked(app, event)
            if ~isempty(app.data.daxData)
                offsetTable = app.data.daxData.offsetTable;
            else
                [offName,dataFolder] = uigetfile('*.off','select offset file to display');
                if ~isempty(offName)
                    offsetFile = [dataFolder,offName];
                    offsetTable = ReadTableFile(offsetFile,'delimiter',' ');
                    app.data.daxData.offsetTable = offsetTable;
                else 
                    offsetTable = [];
                end
            end
            if ~isempty(offsetTable)
                disp(offsetTable)
                figure(102); plot(offsetTable.offset,'.-');
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.LoadDaxMenu = uimenu(app.FileMenu);
            app.LoadDaxMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadDaxMenuSelected, true);
            app.LoadDaxMenu.Text = 'Load Dax';
            app.Load2CamDaxMenu = uimenu(app.FileMenu);
            app.Load2CamDaxMenu.MenuSelectedFcn = createCallbackFcn(app, @Load2CamDaxMenuSelected, true);
            app.Load2CamDaxMenu.Text = 'Load 2-Cam Dax';
            app.LoadHybSeriesMenu = uimenu(app.FileMenu);
            app.LoadHybSeriesMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadHybSeriesMenuSelected, true);
            app.LoadHybSeriesMenu.Text = 'Load Hyb Series';
            app.LoadFitsMenu = uimenu(app.FileMenu);
            app.LoadFitsMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadFitsMenuSelected, true);
            app.LoadFitsMenu.Text = 'Load Fits';
            app.AnalysisMenu = uimenu(app.UIFigure);
            app.AnalysisMenu.Text = 'Analysis';
            app.FitParametersMenu = uimenu(app.AnalysisMenu);
            app.FitParametersMenu.MenuSelectedFcn = createCallbackFcn(app, @FitParametersMenuSelected, true);
            app.FitParametersMenu.Text = 'Fit Parameters';
            app.FitSpotsMenu = uimenu(app.AnalysisMenu);
            app.FitSpotsMenu.MenuSelectedFcn = createCallbackFcn(app, @FitSpotsMenuSelected, true);
            app.FitSpotsMenu.Text = 'Fit Spots';
            app.FitZdataMenu = uimenu(app.AnalysisMenu);
            app.FitZdataMenu.Text = 'Fit Zdata';
            app.SaveFitParametersMenu = uimenu(app.AnalysisMenu);
            app.SaveFitParametersMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveFitParametersMenuSelected, true);
            app.SaveFitParametersMenu.Text = 'Save Fit Parameters';
            app.FitAllFramesMenu = uimenu(app.AnalysisMenu);
            app.FitAllFramesMenu.MenuSelectedFcn = createCallbackFcn(app, @FitAllFramesMenuSelected, true);
            app.FitAllFramesMenu.Text = 'Fit All Frames';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.UpdateTool = uipushtool(app.Toolbar);
            app.UpdateTool.Tooltip = {'update display'};
            app.UpdateTool.ClickedCallback = createCallbackFcn(app, @UpdateToolClicked, true);
            app.UpdateTool.Icon = 'AutoSetContrast.png';
            app.ChangeContrastTool = uipushtool(app.Toolbar);
            app.ChangeContrastTool.Tooltip = {'update contrast range'};
            app.ChangeContrastTool.ClickedCallback = createCallbackFcn(app, @ChangeContrastToolClicked, true);
            app.ChangeContrastTool.Icon = 'SetContrast.png';
            app.FitSpotsTool = uipushtool(app.Toolbar);
            app.FitSpotsTool.Tooltip = {'Find spots'};
            app.FitSpotsTool.ClickedCallback = createCallbackFcn(app, @FitSpotsToolClicked, true);
            app.FitSpotsTool.Icon = 'FindDots.png';
            app.ZprojectTool = uipushtool(app.Toolbar);
            app.ZprojectTool.Tooltip = {'view x-z projection'};
            app.ZprojectTool.ClickedCallback = createCallbackFcn(app, @ZprojectToolClicked, true);
            app.ZprojectTool.Icon = 'MaxProject.png';
            app.PlotOffsetTool = uipushtool(app.Toolbar);
            app.PlotOffsetTool.Tooltip = {'plot offset values'};
            app.PlotOffsetTool.ClickedCallback = createCallbackFcn(app, @PlotOffsetToolClicked, true);
            app.tSpinnerLabel = uilabel(app.UIFigure);
            app.tSpinnerLabel.Text = 't';
            app.tSpinner = uispinner(app.UIFigure);
            app.tSpinner.ValueChangedFcn = createCallbackFcn(app, @tSpinnerValueChanged, true);
            app.zframepertEditFieldLabel = uilabel(app.UIFigure);
            app.zframepertEditFieldLabel.Text = 'z-frame per t';
            app.zframepertEditField = uieditfield(app.UIFigure, 'numeric');
            app.zframepertEditField.ValueChangedFcn = createCallbackFcn(app, @zframepertEditFieldValueChanged, true);
            app.zframepertEditField.Tooltip = {'set to ''0'' to load all frames as z-stack'};
            app.zframepertEditField.Value = 1;
            app.cframeperzEditFieldLabel = uilabel(app.UIFigure);
            app.cframeperzEditFieldLabel.Text = 'c-frame per z';
            app.cframeperzEditField = uieditfield(app.UIFigure, 'numeric');
            app.cframeperzEditField.ValueChangedFcn = createCallbackFcn(app, @cframeperzEditFieldValueChanged, true);
            app.cframeperzEditField.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DaxApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end