classdef DaxViewer3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        FileMenu              matlab.ui.container.Menu
        LoadMulticolor3DMenu  matlab.ui.container.Menu
        ExitMenu              matlab.ui.container.Menu
        ViewMenu              matlab.ui.container.Menu
        AutoContrastMenu      matlab.ui.container.Menu
        ManualContrastMenu    matlab.ui.container.Menu
        MaxProjectMenu        matlab.ui.container.Menu
        FindSpotsMenu         matlab.ui.container.Menu
        SplitChannelsMenu     matlab.ui.container.Menu
        LockZoomMenu          matlab.ui.container.Menu
        Rotate3DMenu          matlab.ui.container.Menu
        OverlayChannelsMenu   matlab.ui.container.Menu
        AnalyzeMenu           matlab.ui.container.Menu
        Menu                  matlab.ui.container.Menu
        Toolbar               matlab.ui.container.Toolbar
        ToolAutoContrast      matlab.ui.container.toolbar.PushTool
        ToolManualContrast    matlab.ui.container.toolbar.PushTool
        ToolMaxProject        matlab.ui.container.toolbar.PushTool
        ToolSplitChannels     matlab.ui.container.toolbar.PushTool
        ToolLockZoom          matlab.ui.container.toolbar.PushTool
        Tool3DView            matlab.ui.container.toolbar.PushTool
        ToolOverlayChannels   matlab.ui.container.toolbar.PushTool
        ToolFindSpots         matlab.ui.container.toolbar.PushTool
        ToggleMaxProjection   matlab.ui.control.StateButton
        ToggleLockZoom        matlab.ui.control.StateButton
        FrameSpinner          matlab.ui.control.Spinner
        FrameSpinnerLabel     matlab.ui.control.Label
    end

    
    properties (Access = private)
        Property % Description
    end
    
    properties (Access = public)
        data % data
        pars % parameters   
    end
    
    methods (Access = public)
        function LoadData(app)
            app.data.dax = ReadDax(app.pars.daxFile);
            SplitChannels(app); % creates app.data.chns cell array
            AutoContrast(app); % creates app.pars.contrastMin and Max
            colormap(app.pars.colormap); % above calls fig
        end
        
        % UPDATE IMAGE
        function UpdateImage(app)
            par = app.pars;
            nChns = app.pars.numChannels;
            fh = figure(app.pars.figHandle);
            % -- optional tether figure to viewer
            if app.pars.tether
                dvPos = app.UIFigure.Position;
                figPos = fh.Position;
                fh.Position = [dvPos(1),dvPos(2)+200+dvPos(4),figPos(3:4)];
            end
            % ---- color overlay
            if app.pars.overlayOn
                clf;
                imStack = cell(nChns,1);
                for c=1:nChns
                    im = app.data.chns{c}(:,:,par.currFrame);
                    im(im<par.contrastMin(c)) = 0;
                    im = uint16(double(im)/double(par.contrastMax(c))*2^16);
                    imStack{c} = im;
                end
                im = Ncolor(cat(3,imStack{:}));
                imagesc(im);
                if app.pars.lockZoom
                    xlim(app.pars.xs);
                    ylim(app.pars.ys);
                end
            % ---- side-by-side channel view
            else
                for c=1:app.pars.numChannels
                    sh = subplot(1,app.pars.numChannels,c);
                    dispImage = app.data.chns{c}(:,:,par.currFrame);
                    imagesc(sh,dispImage);
                    caxis([par.contrastMin(c),par.contrastMax(c)]);
                    if app.pars.colorbar
                        colorbar;
                    end
                    if app.pars.lockZoom
                        xlim(app.pars.xs);
                        ylim(app.pars.ys);
                    end
                end
            end
            
            fh.Name = app.pars.title;
        end
        
        % SPLIT CHANNEL
        function SplitChannels(app)
            nChns = app.pars.numChannels;
            chns = cell(1,nChns);
            if strcmp(app.pars.channelOrder,'alternate')
                for c=1:nChns
                    chns{c} = app.data.dax(:,:,c:nChns:end);
                end
            end
            app.data.chns = chns;
            % update options
            [xs,ys,zs] = size(app.data.chns{1});
            app.data.dataSize = [xs,ys,zs];
            app.FrameSpinner.Limits = [0,zs];
        end
        
        % AUTO CONTRAST
        function AutoContrast(app)
            nChns = app.pars.numChannels;
            contrastMin = zeros(1,nChns);
            contrastMax = zeros(1,nChns);
            for c=1:nChns
                dat = app.data.chns{c}(:,:,app.pars.currFrame);
                contrastMin(c) = quantile(dat(:),app.pars.autoContrastMin);
                contrastMax(c) = quantile(dat(:),app.pars.autoContrastMax);
            end
            app.pars.contrastMin = contrastMin;
            app.pars.contrastMax = contrastMax;
            UpdateImage(app);
            % disp([contrastMin,contrastMax])
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            
            % default parameters
            defaults = cell(0,3);
            defaults(end+1,:) = {'daxFile','string',''};
            defaults(end+1,:) = {'figHandle','handle',100};
            defaults(end+1,:) = {'childHandle','handle',200};
            defaults(end+1,:) = {'autoContrastMin','fraction',.3};
            defaults(end+1,:) = {'autoContrastMax','fraction',.9999};
            defaults(end+1,:) = {'currFrame','integer',1};
            defaults(end+1,:) = {'numChannels','integer',1};
            defaults(end+1,:) = {'colormap','colormap','gray'};
            defaults(end+1,:) = {'tether','boolean','true'};
            defaults(end+1,:) = {'colorbar','boolean','true'};
            defaults(end+1,:) = {'channelOrder',{'alternate','sequential'},'alternate'};
            app.pars = ParseVariableArguments(varargin,defaults,mfilename); 
            
            % handle multiple instances
            app.UIFigure.Tag = 'DaxViewer';
            handles = findall(0,'Tag','DaxViewer');
            app.pars.instanceID = length(handles);
            app.pars.title = ['DaxViewer',num2str(app.pars.instanceID)];
            app.UIFigure.Name  = app.pars.title;
            app.pars.figHandle = app.pars.figHandle + app.pars.instanceID; 
            assignin('base',app.pars.title,app);
            
            % toggle option defaults
            app.pars.lockZoom = false;
            app.pars.overlayOn = false;
            
            % Load dax file
            if isempty(app.pars.daxFile)
                [daxName,folder] = uigetfile('*.dax');
                app.pars.daxFile = [folder,daxName];
            end
            try
                LoadData(app); 
            catch er
                warning(['failed to load daxfile: ',app.pars.daxFile]);
                disp(er.getReport)
            end
        end

        % Menu selected function: LoadMulticolor3DMenu
        function LoadMulticolor3DMenuSelected(app, event)
            [daxName,folder] = uigetfile('*.dax');
            app.pars.daxFile = [folder,daxName];
            try
               LoadDax(app.pars.daxFile); 
            catch er
               warning(['failed to load daxfile: ',app.pars.daxFile]);
               disp(er.getReport)
            end
        end

        % Value changed function: FrameSpinner
        function FrameSpinnerValueChanged(app, event)
            app.pars.currFrame = app.FrameSpinner.Value;
            UpdateImage(app);
        end

        % Callback function: ManualContrastMenu, ToolManualContrast
        function ToolManualContrastClicked(app, event)
            currValues.contrastMin = app.pars.contrastMin;
            currValues.contrastMax = app.pars.contrastMax;
            newValues = SimpleParameterGUI(currValues);
            app.pars.contrastMin = newValues.contrastMin;
            app.pars.contrastMax = newValues.contrastMax;          
        end
        
        function ChangeAutoContrastLevels(app)
            currValues.contrastMin = app.pars.autoContrastMin;
            currValues.contrastMax = app.pars.autoContrastMax;
            newValues = SimpleParameterGUI(currValues);
            app.pars.autoContrastMin = newValues.contrastMin;
            app.pars.autoContrastMax = newValues.contrastMax;
        end

        % Callback function: AutoContrastMenu, ToolAutoContrast
        function ToolAutoContrastClicked(app, event)
            AutoContrast(app);
             
        end

        % Callback function: SplitChannelsMenu, ToolSplitChannels
        function ToolSplitChannelsClicked(app, event)
            currValues.numChannels = app.pars.numChannels;
            newValues = SimpleParameterGUI(currValues);
            app.pars.numChannels = newValues.numChannels;
            SplitChannels(app); % creates app.data.chns cell array
            AutoContrast(app); % creates app.pars.contrastMin and Max
        end

        % Callback function: MaxProjectMenu, ToolMaxProject
        function ToolMaxProjectClicked(app, event)
            nChns = app.pars.numChannels;
            maxProj = cell(1,nChns);
            figure(app.pars.figHandle);
            for c=1:nChns
                maxProj{c} = max(app.data.chns{c},[],3);
                % display
                subplot(1,nChns,c);
                dispImage = IncreaseContrast(maxProj{c}, ...
                    'low',app.pars.autoContrastMin,...
                    'high',app.pars.autoContrastMax);
                imagesc(dispImage);
                if app.pars.lockZoom
                    xlim(app.pars.xs);
                    ylim(app.pars.ys);
                end
            end
            app.data.maxProj = maxProj;
        end

        % Callback function: LockZoomMenu, ToolLockZoom
        function ToolLockZoomClicked(app, event)
            xs = xlim;
            ys = ylim;
            app.pars.xs = xs;
            app.pars.ys = ys;
            app.pars.lockZoom = ~app.pars.lockZoom;
            UpdateImage(app);
        end

        % Callback function: Rotate3DMenu, Tool3DView
        function Tool3DViewClicked(app, event)
            disp('Use mouse to select a region for 3D view...')
            figure(app.pars.figHandle);
            roi = drawrectangle();
            rect = round(roi.Position);
            x1 = rect(1); x2 = rect(1)+rect(3);
            y1 = rect(2); y2 = rect(2)+rect(4);
            nChns = app.pars.numChannels;
            for n=1:nChns
                im3D = app.data.chns{n}(y1:y2,x1:x2,:);
                Rotate3D_GUI(im3D,'figHandle',app.pars.childHandle+n-1);
                % disp(fh.UserData.rot)
                % in future, should output the rotation from one image
                % and apply this automatically to other channel images
            end
        end

        % Callback function: ExitMenu, UIFigure
        function UIFigureCloseRequest(app, event)
            try
                fh = figure(app.pars.figHandle);
                close(fh);
            catch
            end
            delete(app)
            
        end

        % Callback function: OverlayChannelsMenu, ToolOverlayChannels
        function ToolOverlayChannelsClicked(app, event)
            app.pars.overlayOn = ~app.pars.overlayOn;
            UpdateImage(app);
        end

        % Callback function: ToolFindSpots
        function ToolFindSpotsClicked(app, event)
            disp('Find spots integration, still in development')
            % parameter menu
            %   3D or 2D
            %   use multiFit
            %   full window or select region? 
            %   
            
        end

        % Value changed function: ToggleLockZoom
        function ToggleLockZoomValueChanged(app, event)
            xs = xlim;
            ys = ylim;
            app.pars.xs = xs;
            app.pars.ys = ys;
            app.pars.lockZoom = app.ToggleLockZoom.Value;
            UpdateImage(app);
        end

        % Value changed function: ToggleMaxProjection
        function ToggleMaxProjectionValueChanged(app, event)
            value = app.ToggleMaxProjection.Value;
            disp('function coming soon, max projection will be a toggle')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.LoadMulticolor3DMenu = uimenu(app.FileMenu);
            app.LoadMulticolor3DMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadMulticolor3DMenuSelected, true);
            app.LoadMulticolor3DMenu.Text = 'Load Multicolor 3D';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ExitMenu.Text = 'Exit';
            app.ViewMenu = uimenu(app.UIFigure);
            app.ViewMenu.Text = 'View';
            app.AutoContrastMenu = uimenu(app.ViewMenu);
            app.AutoContrastMenu.MenuSelectedFcn = createCallbackFcn(app, @ToolAutoContrastClicked, true);
            app.AutoContrastMenu.Text = 'Auto Contrast';
            app.ManualContrastMenu = uimenu(app.ViewMenu);
            app.ManualContrastMenu.MenuSelectedFcn = createCallbackFcn(app, @ToolManualContrastClicked, true);
            app.ManualContrastMenu.Text = 'Manual Contrast';
            app.MaxProjectMenu = uimenu(app.ViewMenu);
            app.MaxProjectMenu.MenuSelectedFcn = createCallbackFcn(app, @ToolMaxProjectClicked, true);
            app.MaxProjectMenu.Text = 'Max Project';
            app.FindSpotsMenu = uimenu(app.ViewMenu);
            app.FindSpotsMenu.Text = 'Find Spots';
            app.SplitChannelsMenu = uimenu(app.ViewMenu);
            app.SplitChannelsMenu.MenuSelectedFcn = createCallbackFcn(app, @ToolSplitChannelsClicked, true);
            app.SplitChannelsMenu.Text = 'Split Channels';
            app.LockZoomMenu = uimenu(app.ViewMenu);
            app.LockZoomMenu.MenuSelectedFcn = createCallbackFcn(app, @ToolLockZoomClicked, true);
            app.LockZoomMenu.Text = 'Lock Zoom';
            app.Rotate3DMenu = uimenu(app.ViewMenu);
            app.Rotate3DMenu.MenuSelectedFcn = createCallbackFcn(app, @Tool3DViewClicked, true);
            app.Rotate3DMenu.Text = 'Rotate 3D';
            app.OverlayChannelsMenu = uimenu(app.ViewMenu);
            app.OverlayChannelsMenu.MenuSelectedFcn = createCallbackFcn(app, @ToolOverlayChannelsClicked, true);
            app.OverlayChannelsMenu.Text = 'Overlay Channels';
            app.AnalyzeMenu = uimenu(app.UIFigure);
            app.AnalyzeMenu.Text = 'Analyze';
            app.Menu = uimenu(app.AnalyzeMenu);
            app.Menu.Text = 'Menu';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.ToolAutoContrast = uipushtool(app.Toolbar);
            app.ToolAutoContrast.Tooltip = {'Auto-contrast current image(s)'};
            app.ToolAutoContrast.ClickedCallback = createCallbackFcn(app, @ToolAutoContrastClicked, true);
            app.ToolAutoContrast.Icon = 'AutoSetContrast.png';
            app.ToolManualContrast = uipushtool(app.Toolbar);
            app.ToolManualContrast.Tooltip = {'Manually set contrast of current image(s)'};
            app.ToolManualContrast.ClickedCallback = createCallbackFcn(app, @ToolManualContrastClicked, true);
            app.ToolManualContrast.Icon = 'SetContrast.png';
            app.ToolMaxProject = uipushtool(app.Toolbar);
            app.ToolMaxProject.ClickedCallback = createCallbackFcn(app, @ToolMaxProjectClicked, true);
            app.ToolMaxProject.Icon = 'FlattenIcon.PNG';
            app.ToolSplitChannels = uipushtool(app.Toolbar);
            app.ToolSplitChannels.Tooltip = {'Separate alternating color channels into different panels.'};
            app.ToolSplitChannels.ClickedCallback = createCallbackFcn(app, @ToolSplitChannelsClicked, true);
            app.ToolSplitChannels.Icon = 'SplitChns.png';
            app.ToolLockZoom = uipushtool(app.Toolbar);
            app.ToolLockZoom.ClickedCallback = createCallbackFcn(app, @ToolLockZoomClicked, true);
            app.ToolLockZoom.Icon = 'LockZoom.png';
            app.Tool3DView = uipushtool(app.Toolbar);
            app.Tool3DView.Tooltip = {'Select a region of the image to render in 3D.'};
            app.Tool3DView.ClickedCallback = createCallbackFcn(app, @Tool3DViewClicked, true);
            app.Tool3DView.Icon = 'View3D.PNG';
            app.ToolOverlayChannels = uipushtool(app.Toolbar);
            app.ToolOverlayChannels.Tooltip = {'Overlay different panels as a multicolor image.'};
            app.ToolOverlayChannels.ClickedCallback = createCallbackFcn(app, @ToolOverlayChannelsClicked, true);
            app.ToolOverlayChannels.Icon = 'OverlayChannels.PNG';
            app.ToolFindSpots = uipushtool(app.Toolbar);
            app.ToolFindSpots.ClickedCallback = createCallbackFcn(app, @ToolFindSpotsClicked, true);
            app.ToolFindSpots.Icon = 'FindDots.png';
            app.FrameSpinnerLabel = uilabel(app.UIFigure);
            app.FrameSpinnerLabel.Text = 'Frame';
            app.FrameSpinner = uispinner(app.UIFigure);
            app.FrameSpinner.Limits = [1 Inf];
            app.FrameSpinner.RoundFractionalValues = 'on';
            app.FrameSpinner.ValueChangedFcn = createCallbackFcn(app, @FrameSpinnerValueChanged, true);
            app.FrameSpinner.Value = 1;
            app.ToggleLockZoom = uibutton(app.UIFigure, 'state');
            app.ToggleLockZoom.ValueChangedFcn = createCallbackFcn(app, @ToggleLockZoomValueChanged, true);
            app.ToggleLockZoom.Tooltip = {'Toggle lock on zoom on or off.'};
            app.ToggleLockZoom.Icon = 'LockZoom.png';
            app.ToggleLockZoom.Text = '';
            app.ToggleMaxProjection = uibutton(app.UIFigure, 'state');
            app.ToggleMaxProjection.ValueChangedFcn = createCallbackFcn(app, @ToggleMaxProjectionValueChanged, true);
            app.ToggleMaxProjection.Tooltip = {'Toggle max-projection on or off.'};
            app.ToggleMaxProjection.Icon = 'MaxProject.png';
            app.ToggleMaxProjection.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DaxViewer3(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end