classdef FindCellBordersApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ShowCellNumCheckBox            matlab.ui.control.CheckBox
        ConvexCheckBox                 matlab.ui.control.CheckBox
        ShowEdgeFigEditField           matlab.ui.control.NumericEditField
        ShowEdgeFigEditFieldLabel      matlab.ui.control.Label
        SmoothFinalBorderSpinner       matlab.ui.control.Spinner
        SmoothFinalBorderSpinnerLabel  matlab.ui.control.Label
        SmoothFindEdgeSpinner          matlab.ui.control.Spinner
        SmoothFindEdgeSpinnerLabel     matlab.ui.control.Label
        nAnglesSpinner                 matlab.ui.control.Spinner
        nAnglesSpinnerLabel            matlab.ui.control.Label
        SegmentLengthSpinner           matlab.ui.control.Spinner
        SegmentLengthSpinnerLabel      matlab.ui.control.Label
    end

    
    properties (Access = public)
        data % data
        pars % parameters
    end
    
    methods (Access = public)
        function ProcessImage(app)
            cellBorders = FindNucBorders(app.data.im,...
                app.data.cntrs,...
                app.data.radii,...
                'showOverlayFig',app.pars.figHandle,...
                'parameters',app.pars);
            app.data.cellBorders = cellBorders;
            assignin('caller',"cellBorders",cellBorders);
            assignin('base',"cellBorders",cellBorders);
            % cellBorders = evalin('base','cellBorders');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, im, cntrs, radii, varargin)
            app.data.im = im;
            % default parameters
            defaults = cell(0,3);
            defaults(end+1,:) = {'figHandle','handle',200};
            defaults(end+1,:) = {'colormap','colormap','gray'};
            defaults(end+1,:) = {'nAngles','integer',21};
            defaults(end+1,:) = {'segLength','integer',20};
            defaults(end+1,:) = {'smoothFindEdge','integer',3};
            defaults(end+1,:) = {'smoothFinalBorder','integer',3};
            defaults(end+1,:) = {'imageFilter','array',fspecial('gaussian',10,20)};
            defaults(end+1,:) = {'showOverlayFig','integer',3};
            defaults(end+1,:) = {'showExtraFig','integer',0};
            defaults(end+1,:) = {'dilate','integer',1};
            defaults(end+1,:) = {'xyNucWindow','positive',2.25}; % search window is the nuc radius x this muliplier
            defaults(end+1,:) = {'convex','boolean',false};
            defaults(end+1,:) = {'showNum','boolean',true};
            defaults(end+1,:) = {'contrastMin','fraction',.3};
            defaults(end+1,:) = {'contrastMax','fraction',.9999};
            app.pars = ParseVariableArguments(varargin,defaults,mfilename);
            % update 
            app.data.im = im;
            app.data.cntrs = cntrs;
            app.data.radii = radii;
            % update current display values with passed defaults
            app.nAnglesSpinner.Value = app.pars.nAngles;
            app.SegmentLengthSpinner.Value = app.pars.segLength;
            app.ConvexCheckBox.Value = app.pars.convex;
            app.ShowCellNumCheckBox.Value = app.pars.showNum;
            app.SmoothFinalBorderSpinner.Value = app.pars.smoothFinalBorder;
            app.SmoothFindEdgeSpinner.Value = app.pars.smoothFindEdge;
            app.ShowEdgeFigEditField.Value = app.pars.showExtraFig;
            % show initial spots
            ProcessImage(app);
            
        end

        % Value changed function: SegmentLengthSpinner
        function SegmentLengthSpinnerValueChanged(app, event)
            app.pars.segLength = app.SegmentLengthSpinner.Value;
            ProcessImage(app)
        end

        % Value changed function: nAnglesSpinner
        function nAnglesSpinnerValueChanged(app, event)
            app.pars.nAngles = app.nAnglesSpinner.Value;
            ProcessImage(app)
        end

        % Value changed function: SmoothFindEdgeSpinner
        function SmoothFindEdgeSpinnerValueChanged(app, event)
            app.pars.smoothFindEdge = app.SmoothFindEdgeSpinner.Value;
            ProcessImage(app)
        end

        % Value changed function: SmoothFinalBorderSpinner
        function SmoothFinalBorderSpinnerValueChanged(app, event)
            app.pars.smoothFinalBorder = app.SmoothFinalBorderSpinner.Value;
            ProcessImage(app)
        end

        % Value changed function: ShowEdgeFigEditField
        function ShowEdgeFigEditFieldValueChanged(app, event)
            app.pars.showExtraFig = app.ShowEdgeFigEditField.Value;
            
        end

        % Value changed function: ShowCellNumCheckBox
        function ShowCellNumCheckBoxValueChanged(app, event)
            app.pars.showNum = app.ShowCellNumCheckBox.Value;
            
        end

        % Value changed function: ConvexCheckBox
        function ConvexCheckBoxValueChanged(app, event)
            app.pars.convex = app.ConvexCheckBox.Value;
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            close(app.pars.figHandle);
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.SegmentLengthSpinnerLabel = uilabel(app.UIFigure);
            app.SegmentLengthSpinnerLabel.Text = 'Segment Length';
            app.SegmentLengthSpinner = uispinner(app.UIFigure);
            app.SegmentLengthSpinner.Limits = [0 1000];
            app.SegmentLengthSpinner.ValueChangedFcn = createCallbackFcn(app, @SegmentLengthSpinnerValueChanged, true);
            app.SegmentLengthSpinner.Value = 20;
            app.nAnglesSpinnerLabel = uilabel(app.UIFigure);
            app.nAnglesSpinnerLabel.Text = 'nAngles';
            app.nAnglesSpinner = uispinner(app.UIFigure);
            app.nAnglesSpinner.Limits = [1 100];
            app.nAnglesSpinner.ValueChangedFcn = createCallbackFcn(app, @nAnglesSpinnerValueChanged, true);
            app.nAnglesSpinner.Value = 21;
            app.SmoothFindEdgeSpinnerLabel = uilabel(app.UIFigure);
            app.SmoothFindEdgeSpinnerLabel.Text = 'Smooth Find Edge';
            app.SmoothFindEdgeSpinner = uispinner(app.UIFigure);
            app.SmoothFindEdgeSpinner.Limits = [0 100];
            app.SmoothFindEdgeSpinner.ValueChangedFcn = createCallbackFcn(app, @SmoothFindEdgeSpinnerValueChanged, true);
            app.SmoothFindEdgeSpinner.Value = 3;
            app.SmoothFinalBorderSpinnerLabel = uilabel(app.UIFigure);
            app.SmoothFinalBorderSpinnerLabel.Text = 'Smooth Final Border';
            app.SmoothFinalBorderSpinner = uispinner(app.UIFigure);
            app.SmoothFinalBorderSpinner.Limits = [0 100];
            app.SmoothFinalBorderSpinner.ValueChangedFcn = createCallbackFcn(app, @SmoothFinalBorderSpinnerValueChanged, true);
            app.SmoothFinalBorderSpinner.Value = 3;
            app.ShowEdgeFigEditFieldLabel = uilabel(app.UIFigure);
            app.ShowEdgeFigEditFieldLabel.Text = 'Show Edge Fig';
            app.ShowEdgeFigEditField = uieditfield(app.UIFigure, 'numeric');
            app.ShowEdgeFigEditField.ValueChangedFcn = createCallbackFcn(app, @ShowEdgeFigEditFieldValueChanged, true);
            app.ConvexCheckBox = uicheckbox(app.UIFigure);
            app.ConvexCheckBox.ValueChangedFcn = createCallbackFcn(app, @ConvexCheckBoxValueChanged, true);
            app.ConvexCheckBox.Text = 'Convex?';
            app.ShowCellNumCheckBox = uicheckbox(app.UIFigure);
            app.ShowCellNumCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowCellNumCheckBoxValueChanged, true);
            app.ShowCellNumCheckBox.Text = 'Show Cell Num.?';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FindCellBordersApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end