classdef FindCirclesApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        RemoveManuallyButton      matlab.ui.control.Button
        AddManuallyButton         matlab.ui.control.Button
        MaxRadiusSpinner          matlab.ui.control.Spinner
        MaxRadiusSpinnerLabel     matlab.ui.control.Label
        MinRadiusSpinner          matlab.ui.control.Spinner
        MinRadiusSpinnerLabel     matlab.ui.control.Label
        ContrastHighSpinner       matlab.ui.control.Spinner
        ContrastHighSpinnerLabel  matlab.ui.control.Label
        ContrastLowSpinner        matlab.ui.control.Spinner
        ContrastLowSpinnerLabel   matlab.ui.control.Label
        DownsampleSpinner         matlab.ui.control.Spinner
        DownsampleSpinnerLabel    matlab.ui.control.Label
        SensitivitySpinner        matlab.ui.control.Spinner
        SensitivitySpinnerLabel   matlab.ui.control.Label
    end

    
    properties (Access = public)
        data % data
        pars % parameters
    end
    
    methods (Access = public)
        function ProcessImage(app)
            % image transforms
            im1 = app.data.im;
            im1 =  imresize(im1,1/app.pars.downsample);
            im1 = IncreaseContrast(im1,...
                'high',app.pars.contrastMax,...
                'low',app.pars.contrastMin);
            app.data.imF = im1;
            FindCircs(app);
        end
        
        function [cntrs,radii] = CollectManualCircles(app)
            figure(app.pars.figHandle);
            circs = findobj(gca,'Type','images.roi.circle');
            nC = length(circs);
            cntrs = zeros(nC,2);
            radii = zeros(nC,1);
            for i=1:nC
                cntrs(i,:) = circs(i).Center;
                radii(i) = circs(i).Radius;
            end          
        end
        
        function FindCircs(app) 
            % find circles
            [app.data.cntrs,app.data.radii] = imfindcircles(app.data.imF,...
                [app.pars.minRadius,app.pars.maxRadius],...
                'Sensitivity',app.pars.sensitivity);
            % add any manual annotations (data.cntrs has just been cleared, so this won't over-accumulate)
            [cntrs,radii] = CollectManualCircles(app);
            app.data.cntrs = cat(1,app.data.cntrs,cntrs);
            app.data.radii = cat(1,app.data.radii,radii);
            PlotCircs(app);
        end
        
        function PlotCircs(app)
            % plot results;
            figure(app.pars.figHandle); clf;
            imagesc(app.data.imF);
            GetColorMap(app.pars.colormap);
            hold on; 
            viscircles(app.data.cntrs,app.data.radii,'EdgeColor','y');
            
            % save results in caller function (this doesn't seem to work)
            cntrs = app.data.cntrs*app.pars.downsample;
            radii = app.data.radii*app.pars.downsample;
            assignin('caller',"cntrs",cntrs);
            assignin('caller',"radii",radii);
            assignin('caller',"FindCirclePars",app.pars);
            % and in base (since the caller part doesn't work) 
            assignin('base',"cntrs",cntrs);
            assignin('base',"radii",radii);
            assignin('base',"FindCirclePars",app.pars);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, im, varargin)
            app.data.im = im;
            % default parameters
            defaults = cell(0,3);
            defaults(end+1,:) = {'figHandle','handle',200};
            defaults(end+1,:) = {'stayOpen','handle',1000};
            defaults(end+1,:) = {'contrastMin','fraction',.3};
            defaults(end+1,:) = {'contrastMax','fraction',.999};
            defaults(end+1,:) = {'sensitivity','fraction',.9};
            defaults(end+1,:) = {'minRadius','integer',12};
            defaults(end+1,:) = {'maxRadius','integer',30};
            defaults(end+1,:) = {'downsample','integer',2};
            defaults(end+1,:) = {'colormap','colormap','gray'};
            app.pars = ParseVariableArguments(varargin,defaults,mfilename);
            % update current display values with passed defaults
            app.SensitivitySpinner.Value = app.pars.sensitivity;
            app.DownsampleSpinner.Value = app.pars.downsample;
            app.MinRadiusSpinner.Value = app.pars.minRadius;
            app.MaxRadiusSpinner.Value = app.pars.maxRadius;
            app.ContrastLowSpinner.Value = app.pars.contrastMin;
            app.ContrastHighSpinner.Value = app.pars.contrastMax;
            % 
            % drawcircles triggers the uiwait even though the figure
            % doesn't get deleted. I've hacked this bug by adding a dummy
            % figure.
            app.pars.stayOpen = figure(app.pars.stayOpen);
            app.pars.stayOpen.Position = [2500 0 0 0]; % this works to keep an invisible figure
            % app.pars.stayOpen.Visible = 'off';% 
            %
            app.pars.removeManual = 0; % state switch
            
             %%----- General App handling
            % handle multiple instances,
            guiName = 'FindCirclesApp';
            app.UIFigure.Tag = guiName;
            handles = findall(0,'Tag',app.UIFigure.Tag);
            app.pars.instanceID = length(handles);
            app.pars.title = [guiName,num2str(app.pars.instanceID)];
            app.UIFigure.Name  = app.pars.title;
            % link a figure
            app.pars.figHandle = app.pars.figHandle + app.pars.instanceID-1; 
            % export handle to the base 
            assignin('base',app.pars.title,app);
            
            
            % show initial spots
            ProcessImage(app);
            
        end

        % Value changed function: SensitivitySpinner
        function SensitivitySpinnerValueChanged(app, event)
            app.pars.sensitivity = app.SensitivitySpinner.Value;
            FindCircs(app)
        end

        % Value changed function: DownsampleSpinner
        function DownsampleSpinnerValueChanged(app, event)
            app.pars.downsample = app.DownsampleSpinner.Value;
            ProcessImage(app)
        end

        % Value changed function: ContrastLowSpinner
        function ContrastLowSpinnerValueChanged(app, event)
            app.pars.contrastMin = app.ContrastLowSpinner.Value;
            ProcessImage(app)
        end

        % Value changed function: ContrastHighSpinner
        function ContrastHighSpinnerValueChanged(app, event)
            app.pars.contrastMax = app.ContrastHighSpinner.Value;
            ProcessImage(app)
        end

        % Value changed function: MinRadiusSpinner
        function MinRadiusSpinnerValueChanged(app, event)
            app.pars.minRadius = app.MinRadiusSpinner.Value;
            FindCircs(app)
        end

        % Value changed function: MaxRadiusSpinner
        function MaxRadiusSpinnerValueChanged(app, event)
            app.pars.maxRadius = app.MaxRadiusSpinner.Value;
            FindCircs(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try % don't error if the figure is already closed
                close(app.pars.figHandle);
            catch
            end
            try  % this allows uiwait to continue
                close(app.pars.stayOpen)
            catch
            end
            delete(app)
            
        end

        % Button pushed function: AddManuallyButton
        function AddManuallyButtonPushed(app, event)
              figure(app.pars.figHandle);
              disp('draw circle')
              drawcircle("Parent",gca);
              % [xi,yi] = getpts(gca);  select points in current
%             app.data.manCircs{end+1} = manCirc;
%             app.data.manCntrs = cat(1,app.data.manCntrs,newCntrs);
        end

        % Button pushed function: RemoveManuallyButton
        function RemoveManuallyButtonPushed(app, event)
             % convert all centers into selectable circle objects
            if app.pars.removeManual == 0
                disp('right click on circles and select delete. Press button a second time to update')
                figure(app.pars.figHandle);
                nC = length(app.data.radii);
                app.data.temp = cell(nC,1);
                for i=1:nC
                    app.data.temp{i} = images.roi.Circle("Center",app.data.cntrs(i,:),"Radius",app.data.radii(i),"Parent",gca,"color",'c');
                end
                app.pars.removeManual = 1;
            elseif app.pars.removeManual == 1
                [app.data.cntrs,app.data.radii] = CollectManualCircles(app);
                nC = length(app.data.temp);
                for c=1:nC
                    delete(app.data.temp{c})
                end
                PlotCircs(app);    
                app.pars.removeManual = 0;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.SensitivitySpinnerLabel = uilabel(app.UIFigure);
            app.SensitivitySpinnerLabel.Text = 'Sensitivity';
            app.SensitivitySpinner = uispinner(app.UIFigure);
            app.SensitivitySpinner.Step = 0.001;
            app.SensitivitySpinner.Limits = [0 1];
            app.SensitivitySpinner.ValueChangedFcn = createCallbackFcn(app, @SensitivitySpinnerValueChanged, true);
            app.SensitivitySpinner.Value = 0.99;
            app.DownsampleSpinnerLabel = uilabel(app.UIFigure);
            app.DownsampleSpinnerLabel.Text = 'Downsample';
            app.DownsampleSpinner = uispinner(app.UIFigure);
            app.DownsampleSpinner.Limits = [1 100];
            app.DownsampleSpinner.ValueChangedFcn = createCallbackFcn(app, @DownsampleSpinnerValueChanged, true);
            app.DownsampleSpinner.Value = 2;
            app.ContrastLowSpinnerLabel = uilabel(app.UIFigure);
            app.ContrastLowSpinnerLabel.Text = 'Contrast Low';
            app.ContrastLowSpinner = uispinner(app.UIFigure);
            app.ContrastLowSpinner.Step = 0.01;
            app.ContrastLowSpinner.Limits = [0 1];
            app.ContrastLowSpinner.ValueChangedFcn = createCallbackFcn(app, @ContrastLowSpinnerValueChanged, true);
            app.ContrastLowSpinner.Value = 0.3;
            app.ContrastHighSpinnerLabel = uilabel(app.UIFigure);
            app.ContrastHighSpinnerLabel.Text = 'Contrast High';
            app.ContrastHighSpinner = uispinner(app.UIFigure);
            app.ContrastHighSpinner.Step = 0.0001;
            app.ContrastHighSpinner.Limits = [0 1];
            app.ContrastHighSpinner.ValueChangedFcn = createCallbackFcn(app, @ContrastHighSpinnerValueChanged, true);
            app.ContrastHighSpinner.Value = 0.999;
            app.MinRadiusSpinnerLabel = uilabel(app.UIFigure);
            app.MinRadiusSpinnerLabel.Text = 'Min Radius';
            app.MinRadiusSpinner = uispinner(app.UIFigure);
            app.MinRadiusSpinner.Limits = [0 Inf];
            app.MinRadiusSpinner.RoundFractionalValues = 'on';
            app.MinRadiusSpinner.ValueChangedFcn = createCallbackFcn(app, @MinRadiusSpinnerValueChanged, true);
            app.MinRadiusSpinner.Value = 12;
            app.MaxRadiusSpinnerLabel = uilabel(app.UIFigure);
            app.MaxRadiusSpinnerLabel.Text = 'Max Radius';
            app.MaxRadiusSpinner = uispinner(app.UIFigure);
            app.MaxRadiusSpinner.Limits = [0 Inf];
            app.MaxRadiusSpinner.RoundFractionalValues = 'on';
            app.MaxRadiusSpinner.ValueChangedFcn = createCallbackFcn(app, @MaxRadiusSpinnerValueChanged, true);
            app.MaxRadiusSpinner.Value = 30;
            app.AddManuallyButton = uibutton(app.UIFigure, 'push');
            app.AddManuallyButton.ButtonPushedFcn = createCallbackFcn(app, @AddManuallyButtonPushed, true);
            app.AddManuallyButton.Text = 'Add Manually';
            app.RemoveManuallyButton = uibutton(app.UIFigure, 'push');
            app.RemoveManuallyButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveManuallyButtonPushed, true);
            app.RemoveManuallyButton.Text = 'Remove Manually';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FindCirclesApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end