classdef MosaicAnalyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        FileMenu                    matlab.ui.container.Menu
        LoadMosaicMenu              matlab.ui.container.Menu
        LoadCTDataMenu              matlab.ui.container.Menu
        LoadAnnotationsMenu         matlab.ui.container.Menu
        SaveAnnotationsMenu         matlab.ui.container.Menu
        EditMenu                    matlab.ui.container.Menu
        DisplayparametersMenu       matlab.ui.container.Menu
        DistancemapparametersMenu   matlab.ui.container.Menu
        EditdisplaytitleMenu        matlab.ui.container.Menu
        AnalyzeMenu                 matlab.ui.container.Menu
        DistanceMapMenu             matlab.ui.container.Menu
        ContactMapMenu              matlab.ui.container.Menu
        RNAspotsinROIMenu           matlab.ui.container.Menu
        RNAspotsinCellMenu          matlab.ui.container.Menu
        SegmentCellsMenu            matlab.ui.container.Menu
        CountSpotsMenu              matlab.ui.container.Menu
        RecordROIMenu               matlab.ui.container.Menu
        CoverageMapMenu             matlab.ui.container.Menu
        DisplayMenu                 matlab.ui.container.Menu
        ClearImageMenu              matlab.ui.container.Menu
        RecordROIButton             matlab.ui.control.Button
        SegmentCellsButton          matlab.ui.control.Button
        BoxFOVsButton               matlab.ui.control.Button
        CountSpotsButton            matlab.ui.control.Button
        AddAnnotationtoTableButton  matlab.ui.control.Button
        LoadMosaicfirstthenLoadCTdatathenexploreLabel  matlab.ui.control.Label
        ShowCTspotsButton           matlab.ui.control.Button
        SliderLabel                 matlab.ui.control.Label
        Slider                      matlab.ui.control.Slider
        EditField                   matlab.ui.control.EditField
        ClearImageButton            matlab.ui.control.Button
        ShowAnnotationsButton       matlab.ui.control.Button
        Tree                        matlab.ui.container.Tree
        TestFeatureButton           matlab.ui.control.Button
        AvailableAnnotationsLabel   matlab.ui.control.Label
    end

    
    properties (Access = public)
        pars % list of editable parameters, as a structure, indexed by button name
        status % list of toggle box status
        nca % handle to the NcolorGUI
        data % structure to hold data (the below could move into this?)
        fovBoxes % array of the corner points for all FOV referenced in the mosaic
        mosaicTable % records some data from mosaic, including FOV ULs and trim size
        mosaicSpotXYf = [] % fiducial spot XY positions in from CT in mosaic coordinates
        annotations % 
    end
    
    methods (Access = public)
        function LoadMosaicData(app)
            verbose = app.pars.LoadMosaic.verbose;
            mosaicFolder = app.pars.LoadMosaic.saveFolder;
            % a file browser is a more familiar way to load the mosaic.
            [saveRoot,mosaicFolder] = uigetfile([mosaicFolder,'*.tif']);
            saveRoot = regexprep(saveRoot,'.tif','');
            if strcmp(saveRoot(end-4:end),'_c001')
                fid = imread([mosaicFolder,saveRoot,'.tif']); % load chn1 to get size;
                saveRoot = regexprep(saveRoot,'_c001',''); % remove c1
            else
                fid = imread([mosaicFolder,saveRoot,'.tif'],1);  % load chn1 to get size;
            end
            % load channel names 
            chnNames = readtable([mosaicFolder,saveRoot,'Labels.txt'],'delimiter','\t');
            if isempty(chnNames)
                chnNames = {};
            else
                try
                    chnNames = chnNames.chnName;
                catch
                    chnNames = chnNames{:,1};
                end
            end
            nChns = length(chnNames);           
            bigMosaic = zeros([size(fid),nChns],'uint16'); % note this is very large. 
            if verbose
                disp('loading mosaic image, please wait...');
            end
            for c=1:nChns
                if verbose
                    disp(['loading channel ',num2str(c),'...']);
                end
                filesFound = cellstr(ls([mosaicFolder,saveRoot,'*.tif']));
                if contains(filesFound{1},'c001')
                    bigMosaic(:,:,c) = imread([mosaicFolder,filesFound{c}]);
                else % single tif file
                    bigMosaic(:,:,c) = imread([mosaicFolder,filesFound{1}],c);
                end
            end
            if verbose
                disp('mosaic loaded'); 
            end
            % load tile info (for overlays)
            mosTable = readtable([mosaicFolder,saveRoot,'tileULs.csv']);
            % update mosTable with FOV numbers if needed
            if ~any(contains(mosTable.Properties.VariableNames,'fov'))
                mosTable.fov = Column( 1:height(mosTable) );
            end
            
            app.fovBoxes = [mosTable.x,mosTable.y,mosTable.w,mosTable.h];
            app.mosaicTable = mosTable; % save for other buttons
            app.annotations.chnNames = chnNames;
            app.data.bigMosaic = bigMosaic;
            app.pars.LoadMosaic.saveFolder = mosaicFolder;
            % update scope pars;
            UpdateScopePars(app);
            % Load (maybe make this a second click?)
            CallNcolor(app);
        end
        % load
        function CallNcolor(app)
            tic
            app.nca = NcolorApp(app.data.bigMosaic,'names',app.annotations.chnNames,'figHandle',app.pars.LoadMosaic.figHandle); % allow access to NcolorApp props
            toc
        end
       
        function UpdateScopePars(app)
            % load scope
            if any(contains(app.mosaicTable.Properties.VariableNames,'scope'))
                scopePars = GetScopeSettings([],'scope',app.mosaicTable.scope{1});
                app.pars.scopePars = scopePars;
            % if scope not recorded, load the recorded oreintations instead
            elseif any(contains(app.mosaicTable.Properties.VariableNames,'transpose'))
                 app.pars.scopePars.transpose = app.mosaicTable.transpose(1);
                 app.pars.scopePars.fliplr = app.mosaicTable.fliplr(1);
                 app.pars.scopePars.flipud = app.mosaicTable.flipud(1);
                 app.pars.scopePars.nmPixXY = 154;
                 app.pars.scopePars.pix_to_mm = 6.55;
            % if still lost, asked
            else
                warning('scope not recorded')
                scopeName = input('What microscope was this data collected on? type: "scope1","scope2","scope3" or "other" ');
                scopePars = GetScopeSettings([],'scope',string(scopeName));
                app.pars.scopePars = scopePars;
            end
        end
        
        function LoadCTData(app)
            disp('Select all CT files to load (hold shift or ctrl to select multiple)');         
             [fitFiles,fitFolder] = uigetfile([app.pars.LoadMosaic.saveFolder,'fov*_AllFits.csv'],'MultiSelect','on');           
             if ~iscell(fitFiles)
                 fitFiles = {fitFiles};
             end
             if ~isempty(fitFolder)
                mosTable = app.mosaicTable; % from load mosaic  
                pixnm = app.pars.scopePars.nmPixXY;  
                % numFOV = size(app.fovBoxes,1); % loops over entries
                numFOV = length(fitFiles); % loops over entries
                XYf = cell(numFOV,1);
                ctTables = cell(numFOV,1);
                for f=1:numFOV
                    % get fov number from file name
                    fend = strfind(fitFiles{f},'_AllFits');
                    fNum = str2double(fitFiles{f}(4:fend-1));
                    % load table;
                    fovFits = readtable([fitFolder,fitFiles{f}]);
                    ctTables{f} = fovFits;
                    % -- assign unique IDs
                    [spotNums,spotStarts] = unique(fovFits.s,'stable');
                    numSpots = length(spotNums);
                    if app.pars.LoadMosaic.verbose
                        disp(['loaded data for ',num2str(numSpots), ' chromosomes.'])
                    end
                    % get position from mosTable
                    try 
                        ff = mosTable.fov(fNum);
                    catch
                        warning('failed to match fov number, CT data may be mispositioned')
                        ff = mosTable.fov(f);
                    end
                    % -- detect of spots are in pixels or nm
                    spotXY = [fovFits.locusX(spotStarts),fovFits.locusY(spotStarts)]/pixnm;
                    % ------- fix spot transpose/flip
                    if app.pars.scopePars.transpose
                       spotXY = fliplr(spotXY); % exchange X and Y 
                    end
                    if app.pars.scopePars.fliplr
                        spotXY(:,1) = mosTable.w(ff) + 2*mosTable.trim(ff) - spotXY(:,1);
                    end
                    if app.pars.scopePars.flipud
                        spotXY(:,2) = mosTable.h(ff) + 2*mosTable.trim(ff) - spotXY(:,2);
                    end
                    XYf{f} = [app.fovBoxes(ff,1)+spotXY(:,1) - mosTable.trim(ff),...
                              app.fovBoxes(ff,2)+spotXY(:,2) - mosTable.trim(ff),...
                              repmat(ff,numSpots,1),...
                              spotNums,...  % spot numbers (s)
                              spotStarts];  % idx spot starts indices of where these spots start in the table
                end
                XYf = cat(1,XYf{:}); % one big table. 
                app.mosaicSpotXYf = XYf; % not dealing with double counting yet. 
                app.data.ctTables = ctTables; % store the ctTables for plotting data
                if app.pars.LoadMosaic.verbose
                   disp('finished loading CT data') 
                end
            end    
        end
        
        function ComputeAllDistMap(app)
            disp('pre-computing distance map for all traces in CT data, this only needs to be done once. Please wait.')
            allTables = app.data.ctTables;
            numFOVs = length(allTables);
            distMap = cell(numFOVs,1);
            chrPolys = cell(numFOVs,1);
            nSteps = max(cellfun(@(x) max(x.hybe),app.data.ctTables)); % avoid concatination errors from small tables 
            for f = 1:numFOVs
                disp(['processing FOV ',num2str(f),' of ',num2str(numFOVs)]);
                [chrPolys{f},distMap{f}] = TableToPolymer(allTables{f},'nSteps',nSteps); % accepts nSteps if we have issues with 
            end
            app.data.allDistMap = cat(3,distMap{:}); 
            app.data.allChrPolys = cat(3,chrPolys{:}); 
        end
        
        
        function ComputeLocalDistMap(app,selectData)
            % Compute a distance map for all cells in mosaic and index out
            % of it.
            
            % Compute a distance map only for select spots: select spots
            selectData = [1:100,3000:3400]; % for troubleshooting
            spotTable = app.annotations.tableData(selectData,:); % the subset of the spot-table to plot
            selFOVs = unique(spotTable.fov);
            numFOVs = length(selFOVs);
            distMap = cell(numFOVs,1);
            chrPolys = cell(numFOVs,1);
            nSteps = cellfun(@(x) max(x.hybe),app.data.ctTables); % avoid concatination errors from small tables 
            for fov = 1:numFOVs
                f = selFOVs(fov);
                table_s = spotTable(spotTable.f==f,:); % table of chromsomes
                table_f = app.data.ctTables{f}; % table of trace data 
                idx = ismember(table_f.s,table_s.s);
                table_f = table_f(idx,:); 
                [chrPolys{f},distMap{f}] = TableToPolymer(table_f,'nSteps',nSteps); % accepts nSteps if we have issues with 
            end
            app.data.distMap = cat(3,distMap{:}); 
            app.data.chrPolys = cat(3,distMap{:}); 
        end
        
        function SaveAllAnnotations(app) 
            [saveRoot,saveFolder] = uiputfile('*.csv');
            saveRoot = regexprep(saveRoot,'.csv','');
            disp('saving data')
            % write spot table
            tableData = app.annotations.tableData;
            tableName = [saveFolder,filesep,saveRoot,'_tableData.csv'];
            writetable(tableData,tableName); disp(['wrote ',tableName]);
            % write polygon table
            tableData = app.annotations.polygonTable;
            tableName = [saveFolder,filesep,saveRoot,'_polygonTable.csv'];
            writetable(tableData,tableName); disp(['wrote ',tableName]);
            % write RNA table
            tableData = app.annotations.spotCountTable;
            tableName = [saveFolder,filesep,saveRoot,'_spotCountTable.csv'];
            writetable(tableData,tableName); disp(['wrote ',tableName]);
            % write cell borders 
            cellBorders = (app.annotations.cellBordersXY); % convert nan to zeros
            % save([saveFolder,'cellBorders.mat'],'cellBorders','-mat'); % this is much faster
            if ~isempty(cellBorders)
                fileName = [saveFolder,filesep,saveRoot,'_cellBordersXY.csv'];
                fid = fopen(fileName,'w+');
                fprintf(fid,'%5.1f %5.1f \n',cellBorders');
                fclose(fid);
                disp(['wrote ',fileName]);
            end
        end
        
        function LoadAnnotations(app)
             [dataFiles,dataFolder] = uigetfile({'*_tableData.csv;*_polygonTable.csv;*_spotCountTable.csv;*_cellBordersXY.csv;','MosaicAnalyzer Files'},...
                 'Pick One or More Files',app.pars.LoadMosaic.saveFolder,'MultiSelect','on');
             disp('loading data');
             if iscell(dataFiles)
                 nFiles = length(dataFiles);
             else
                 nFiles = 1;
             end
             for f=1:nFiles
                 if iscell(dataFiles)
                    dataTableName = dataFiles{f};
                 else
                     dataTableName = dataFiles;
                 end
                 if contains(dataTableName,'_tableData')
                     answer = 'Yes';
                     if ~isempty(app.annotations.tableData)
                          answer = questdlg('Mosaic Analyzer already has tableData, overwrite?', ... % question
                            'Prompt ', ...  % pop-up label
                            'Yes','No','No'); % op1 op2 defaul
                     end
                     if strcmp(answer,'Yes')
                         tableData = readtable([dataFolder,dataTableName]);
                         disp(tableData(1:3,:));
                         app.annotations.tableData = tableData;
                     end
                 end
                 if contains(dataTableName,'_polygonTable')
                     answer = 'Yes';
                     if ~isempty(app.annotations.polygonTable)
                          answer = questdlg('Mosaic Analyzer already has polygonTable, overwrite?', ... % question
                            'Prompt ', ...  % pop-up label
                            'Yes','No','No'); % op1 op2 defaul
                     end
                     if strcmp(answer,'Yes')
                         tableData = readtable([dataFolder,dataTableName]);
                         app.annotations.polygonTable = tableData;
                     end
                 end
                 if contains(dataTableName,'_spotCountTable')
                     answer = 'Yes';
                     if ~isempty(app.annotations.spotCountTable)
                          answer = questdlg('Mosaic Analyzer already has spotCountTable, overwrite?', ... % question
                            'Prompt ', ...  % pop-up label
                            'Yes','No','No'); % op1 op2 defaul
                     end
                     if strcmp(answer,'Yes')
                         tableData = readtable([dataFolder,dataTableName]);
                         app.annotations.spotCountTable = tableData;
                     end
                 end
                 if contains(dataTableName,'_cellBordersXY')
                     answer = 'Yes';
                     if ~isempty(app.annotations.cellBordersXY)
                          answer = questdlg('Mosaic Analyzer already has cellBordersXY, overwrite?', ... % question
                            'Prompt ', ...  % pop-up label
                            'Yes','No','No'); % op1 op2 defaul
                     end
                     if strcmp(answer,'Yes')
                         tableData = dlmread([dataFolder,dataTableName]);
                         app.annotations.cellBordersXY = tableData;
                     end
                 end
             end
             UpdateTree(app);
        end
        
        % Helper function for display
        function  spotXY = XYabsToXYview(app,absXY)
           currOrigin = [app.nca.pixelBox.x(1)-1,app.nca.pixelBox.y(1)-1];
           spotXY = absXY*(app.nca.scale)^app.nca.currScale;
           spotXY = spotXY - repmat(currOrigin,size(spotXY,1),1);
        end
        
        function UpdateTree(app)
            t= app.Tree;
            % remove all annotations and re-add them?
            
            if isempty(t.Children)
                currAnnotations ={}; 
            else
                currAnnotations = {t.Children.Text};
                for c=1:length(currAnnotations)
                    t.Children(1).delete;
                end
                currAnnotations ={};
            end
            if ~isempty(app.annotations.cellBordersXY) && ~any(contains(currAnnotations,'cell edges'))
                uitreenode(t,'Text','cell edges');
            end
            if ~isempty(app.annotations.spotCountTable) && ~any(contains(currAnnotations,'spot counts'))
                uitreenode(t,'Text','spot counts');
            end
            if ~isempty(app.annotations.polygonTable) && ~any(contains(currAnnotations,'ROIs'))
                 uitreenode(t,'Text','ROIs');
            end
            currAnnotations = {t.Children.Text};
            % ROIs have their own names
            isROI = contains(currAnnotations,'ROIs');
            if any(isROI)
                roiNames = unique(app.annotations.polygonTable.Tag,'stable');
                for r = 1:length(roiNames)
                    uitreenode(t.Children(isROI),'Text',roiNames{r});
                end
            end
           % spot counts also have their own names 
           isCount = contains(currAnnotations,'spot counts');
            if any(isCount)
                cntNames = unique(app.annotations.spotCountTable.spotName,'stable');
                for r = 1:length(cntNames)
                    uitreenode(t.Children(isCount),'Text',cntNames{r});
                end
            end
           t.SelectedNodes;
        end
        
        function ShowAnnotations(app)
            allROI = false;
            allCounts = false;
            namesSelected = {app.Tree.SelectedNodes.Text}; 
            % display all properties in a class if the main class is selected    
            if any(strcmp(namesSelected,'cell edges'))
                ShowEdges(app);
            end
            if any(strcmp(namesSelected,'ROIs'))
                ShowPolygons(app);
                allROI = true;
            end
            if any(strcmp(namesSelected,'spot counts'))
                ShowCountedSpots(app);
                allCounts = true;
            end
            % show specific ROIs and speicfic names if they are selected
            if ~allROI  && ~isempty(app.annotations.polygonTable)    
                roiNames = unique(app.annotations.polygonTable.Tag,'stable');
                hasROInames = StringFind(namesSelected,roiNames,'exactly',true);
                if iscell(hasROInames)
                    selROIs = ~cellfun(@isempty,hasROInames);
                    selROInames = roiNames(selROIs);
                else
                    selROInames = namesSelected(hasROInames);
                    selROIs = ismember(roiNames,selROInames); % was contains
                end
                if ~isempty(selROInames)
                    ShowPolygons(app,find(selROIs));
                end
            end
            % show specific counts if selected
            if ~allCounts  && ~isempty(app.annotations.spotCountTable)
                cntNames = unique(app.annotations.spotCountTable.spotName,'stable');
                hasCntNames = StringFind(namesSelected,cntNames,'exactly',true);
                if iscell(hasCntNames)
                    sel = ~cellfun(@isempty,hasCntNames);
                    selNames = roiNames(sel);
                else
                    selNames = namesSelected(hasCntNames);
                    sel = ismember(cntNames,selNames);
                end
                if ~isempty(selNames)
                    ShowCountedSpots(app,find(sel));
                end
            end
        end
        
        function ShowEdges(app)
            NcolorViewerFigure('figHandle',app.pars.LoadMosaic.figHandle,'display','none');
            % figure(1); clf;
            spotXY = XYabsToXYview(app,app.annotations.cellBordersXY);  % 
            hold on;
            plot(spotXY(:,1),spotXY(:,2),'-'); 
            hold off; 
        end
            
        function ShowPolygons(app,varargin)
            if ~isempty(varargin)
                selectPolys = varargin{1};
            else 
                selectPolys = [];
            end
            disp('annotations in polygon table:')
            % disp(app.annotations.polygonTable); % needs testing
            inPoly = app.annotations.tableData{:,6:end}; % boolean of in polygon
            inPolyName = app.annotations.tableData.Properties.VariableNames(6:end);
            roiNames = unique(app.annotations.polygonTable.Tag,'stable');
            disp('annotations in spot table:');
            disp(inPolyName')
            if isempty(selectPolys)
                nPolys = length(unique(app.annotations.polygonTable.Tag)); % height(app.annotations.polygonTable);
                selectPolys = 1:nPolys;
            end
            hold on;
            for n=Column(selectPolys)'  % 1:nPolys % draw all the polygons
                try
                    idx = strcmp(app.annotations.polygonTable.Tag,roiNames{n});
                catch er
                    warning(er.getReport)
                    disp('debug here')
                end
                starts = find(diff([0;idx])==1); % find the starts of discontiguous blocks
                stops = find(diff([idx;0])==-1); % find the starts of discontiguous blocks
                kblocks = length(starts);
                for k=1:kblocks
                    xy = app.annotations.polygonTable{starts(k):stops(k),1:2};
                    xy = cat(1,xy,xy(1,:)); % close the polygon trace;
                    xy = XYabsToXYview(app,xy);
                    plot(xy(:,1),xy(:,2),'.-');
                    text(xy(1,1),xy(1,2),roiNames{n},'color','w');
                end
            end
%             nAnnot = size(inPoly,2);
%             for n=1:nAnnot % mark all the spots (should be inside the polygons)
%                xy = app.annotations.tableData{inPoly(:,n),{'x','y'}};
%                xy = XYabsToXYview(app,xy);
%                plot(xy(:,1),xy(:,2),'+');
%                text(xy(end,1),xy(end,2),inPolyName{n},'color','w');
%             end
            hold off;
        end
        
        function ShowCountedSpots(app,varargin)
            if ~isempty(varargin)
                selected = varargin{1};
            else
                selected =[];
            end
            if ~isempty(app.annotations.spotCountTable)
                spotTable = app.annotations.spotCountTable ;
                disp('showing the beginning and end of the spot count table:')
                disp(spotTable([1:3,end-2:end],:)); 
                [flavors,idxStart] = unique(spotTable.spotName,'stable');
                idxStart = cat(1,idxStart,height(spotTable));
                if isempty(selected)
                    disp('found the following spot types:'); disp(flavors);
                    % app.pars.ShowAnnotation.showOnly = '';
                    if ~isempty(app.pars.ShowAnnotation.showOnly)
                        selected = strsplit(app.pars.ShowAnnotation.showOnly,',');    
                        selected = ismember(flavors,selected); % ismember
                    else
                        selected = 1:length(flavors);
                    end
                end
                % sc = (app.nca.scale)^app.nca.currScale;
                NcolorViewerFigure('figHandle',app.pars.LoadMosaic.figHandle,'display','none'); % figure(1); clf; 
                hold on;
                % chose colors to match colorbar
                 [~,~,idx] = intersect(flavors,app.nca.inputPars.names,'stable');
                cmap = app.nca.inputPars.colormap(idx,:);
                % cmap = hsv(length(flavors)+1);
                spotXY = XYabsToXYview(app,spotTable{:,1:2}); 
                for c=Row(selected)
                    idxStart(c)
                    i = idxStart(c):idxStart(c+1)-1;
                    plot(spotXY(i,1),spotXY(i,2),'Marker','o','color',cmap(c,:),'LineStyle','none');
                    disp(['showing ',flavors{c}]); pause(.1);
                end
                % legend(flavors(selected));
                hold off;
            else
                disp('spotCount table is empty!')
            end
        end
        
        function ChangeTitle(app)
             currTitle = regexprep(app.UIFigure.Name,'Mosaic Analyzer','');
             newTitle = inputdlg({'new title: '},'change title',[1 40],{currTitle});
             newTitle = newTitle{1}; % unpack cell
             set(app.pars.LoadMosaic.figHandle,'Name',['Display ',newTitle]);
             app.UIFigure.Name = ['Mosaic Analyzer',' ',newTitle];
             app.nca.NcolorAppUIFigure.Name = ['Ncolor ',newTitle];
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            % Parameter default values (may be changed using varargin)
            % GUI defaults on startup
            defaults = cell(0,3);
            defaults(end+1,:) = {'title','string','Mosaic Analyzer'};
            startup_pars = ParseVariableArguments(varargin,defaults,'MosaicAnalyzer');
            app.UIFigure.Name = startup_pars.title;
            % -- defaults for buttons
            % ----- LoadMosaic parameters (these are really global parameters)    
            defaults = cell(0,3);
            defaults(end+1,:) = {'saveFolder','string',''};
            % defaults(end+1,:) = {'rootName','string',''};
            defaults(end+1,:) = {'verbose','boolean',true};
            defaults(end+1,:) = {'figHandle','freeType',100}; %  
            app.pars.LoadMosaic = ParseVariableArguments(varargin,defaults,'MosaicAnalyzer');
%             % ----- LoadCTData parameters
%             defaults = cell(0,3);
%             defaults(end+1,:) = {'folder','string',''};
%             defaults(end+1,:) = {'verbose','boolean',true};
%             app.pars.LoadCTData = ParseVariableArguments(varargin,defaults,'MosaicAnalyzer');
            % ----- SegmentCells parameters
            defaults = cell(0,3);
            defaults(end+1,:) = {'method',{'Watershed CT Data','use Channel'},'watershed CT Data'};
            defaults(end+1,:) = {'minSep','integer',10};
            defaults(end+1,:) = {'cellSize','integer',25};
            app.pars.SegmentCells = ParseVariableArguments(varargin,defaults,'MosaicAnalyzer');
            % ----- Record ROI parameters
            defaults = cell(0,3);
            defaults(end+1,:) = {'Points','array',[]};
            defaults(end+1,:) = {'PolygonData','freeType',[]};  % No option for type 'table'
            defaults(end+1,:) = {'MarkerTags','cell',{'E1','E2','E3','E4','E5','E6','E7','E8','E9','E10','E11','E12','E13','E14'}};
            defaults(end+1,:) = {'MarkerShape','integer','x'};
            defaults(end+1,:) = {'MarkerDefaultColor','array',[1,1,1]};
            app.pars.RecordROI = ParseVariableArguments(varargin,defaults,'MosaicAnalyzer');
            % ----- CountSpots parameters
            defaults = cell(0,3);
            defaults(end+1,:) = {'threshold','fraction',.1};
            app.pars.CountSpots = ParseVariableArguments(varargin,defaults,'MosaicAnalyzer');
            % ----- Add Annotation parameters
            defaults = cell(0,3);
            defaults(end+1,:) = {'annotation','freeType',[]};
            app.pars.AddAnnotation = ParseVariableArguments(varargin,defaults,'MosaicAnalyzer');
            % ----- Show Annotations parameters
            defaults = cell(0,3);
            defaults(end+1,:) = {'annotation',{'counted spots','ROI polygons','cell edges','box FOVs','CT spots'},'counted spots'};
            defaults(end+1,:) = {'showOnly','string',''};
            app.pars.ShowAnnotation = ParseVariableArguments(varargin,defaults,'MosaicAnalyzer');
            % ----- Dist Map parameters
            defaults = cell(0,3);
            defaults(end+1,:) = {'ROIs','string','all'}; % 'A1,A2,A3,A4,A5';
            defaults(end+1,:) = {'figHandle','freeType',200};
            defaults(end+1,:) = {'badHybes','freeType',[]};
            app.pars.DistMapPars = ParseVariableArguments([],defaults,'MosaicAnalyzer');
            % ----- Cont Map parameters
            defaults = cell(0,3);
            defaults(end+1,:) = {'ROIs','string','all'}; % 'A1,A2,A3,A4,A5';
            defaults(end+1,:) = {'threshold','positive',150};
            defaults(end+1,:) = {'figHandle','freeType',200};
            defaults(end+1,:) = {'badHybes','freeType',[]};
            app.pars.ContMapPars = ParseVariableArguments([],defaults,'MosaicAnalyzer');
            % -- defaults for toggle buttons
            app.status.BoxButtonON = false; % button not pressed 
            app.status.ShowCTButtonON = false;
            % -- some storage arrays for data export
            app.annotations.polyData = {};
            app.annotations.lastUpdate = '';
            app.annotations.cellBordersXY = [];
            app.annotations.spotCountTable = table(); % all saved RNA spot counts, in mosaic xy coords
            app.annotations.polygonTable = table(); % all saved ROI polygons, in mosaic xy coords
            app.annotations.tableData =table(); 
            app.data.allDistMap = [];
        end

        % Button pushed function: BoxFOVsButton
        function BoxFOVsButtonPushed(app, event)
            % disp(app.status)
            if ~app.status.BoxButtonON
                disp('showing FOV boxes')
                boxPostions = app.fovBoxes*(app.nca.scale)^app.nca.currScale;
                NcolorViewerFigure('figHandle',app.pars.LoadMosaic.figHandle,'display','none'); % call figure to the foreground
                hold on;
                for f=1:size(app.fovBoxes,1) % plot all the boxes 
                    rectangle('Position',boxPostions(f,:),'EdgeColor','y');
                    text(boxPostions(f,1)-15,boxPostions(f,2)-15,num2str(app.mosaicTable.fov(f)),'color','y');
                end
                app.status.BoxButtonON = true;
                hold off;
            else
                allRectangles = findobj(gca,'Type','rectangle');
                delete(allRectangles);
                allText = findobj(gca,'Type','text');
                delete(allText);
                app.status.BoxButtonON = false;
            end
        end

        % Button pushed function: ShowCTspotsButton
        function ShowCTspotsButtonPushed(app, event)
            if isempty(app.mosaicSpotXYf)
               error('No CT data found to plot. Load CT data first and try again.')
            end
            if ~app.status.ShowCTButtonON
                disp('plotting spots')
                absXY = app.mosaicSpotXYf(:,1:2);
                spotXY = XYabsToXYview(app,absXY);
                % display image
                NcolorViewerFigure('figHandle',app.pars.LoadMosaic.figHandle,'display','none');
                hold on;
                plot(spotXY(:,1),spotXY(:,2),'yo');
                app.status.ShowCTButtonON = true;
                hold off;
            else
                disp('clearing spots')
                NcolorViewerFigure('figHandle',app.pars.LoadMosaic.figHandle,'display','none');
                spots = findobj(gca,'Type','Line');
                delete(spots);
                app.status.ShowCTButtonON = false;
            end
        end

        % Button pushed function: SegmentCellsButton
        function SegmentCellsButtonPushed(app, event)
            disp('segmenting all cells in mosaic, using CT spots as reference.')
            % ----- parameters
            [app.pars.SegmentCells,canceled] = SimpleParameterGUI(app.pars.SegmentCells);
            if ~canceled
                disp('this may be slow, please wait...')
                sc = 1; % downsampling parameter for segmentation.  this is MUCH faster if we downsample by 2 or 4 (6 vs 130s), but I haven't written the code to upsample again accurately yet
                % if we converted these to cell polygons (x,y lines, not pixels) then we could easily rescale up to full sized images? Polygons still have gaps of course after expanding...    
                minSep = app.pars.SegmentCells.minSep; % minSep = 10         
                cellSize = round(app.pars.SegmentCells.cellSize/sc); % 25
                % -- Main function
                % ------ Find and remove overlapping points 
                %       Overlap happens because of the 3D to 2D cell stacking, and
                %       because of imperfect matches edges. We need to increase
                %       the sparsity to get reasonable 2D cell segmentation. 
                xSpots = app.mosaicSpotXYf(:,1);
                ySpots = app.mosaicSpotXYf(:,2);
                maxX = app.nca.maxBox.x(2);
                maxY = app.nca.maxBox.y(2);
                h = ceil(maxY/minSep);
                w = ceil(maxX/minSep);
                cellID = sub2ind([h,w],round(ySpots/minSep),round(xSpots/minSep));
                [~,id] = unique(cellID);
                cellXY = [xSpots(id),ySpots(id)];
                            %-- troubleshooting plot
                % figure(1); clf; plot(xSpots,ySpots,'c.');
                % hold on; plot(cellXY(:,1),cellXY(:,2),'bo');
                % app.annotations.cellXY = cellXY; % record result
                % -- Actual segmentation based on cellXY
                tic;
                h = ceil(maxY/sc);
                w = ceil(maxX/sc);
                cellMap = false(h,w); % downsampled map
                idx = sub2ind([h,w],round(cellXY(:,2)/sc),round(cellXY(:,1)/sc));
                cellMap(idx) = true;
                % ------ watersheding the cell map
                im1 = bwdist(cellMap);
                im1 = watershed(im1);
                bw0 = im1==0; % cuts between cells
                bw1 = imdilate(cellMap,strel('disk',cellSize)); 
                bw1 = bw1 & ~bw0; 
                toc
                % test
                % bw2 = imdilate(cellMap,strel('disk',cellSize+1)); % cell-areas
                % bw = (bw2 & ~bw1); % borders of cells 
                % figure(1); clf; imagesc(bw);
                
                % ------- record results as a plotable array
                % app.annotations.cellEdges = find(bw); % save cell edges
                cellBorderXY = regionprops(bw1,'ConvexHull','Area','Centroid'); % a bit slow (20s), scales with number of 'on' objects? on pixels?.  (fast for bw)
                toc
                % -- convert cell array to a Nx2 XY Matrix, cells separated by NaNs   
                nCells = length(cellBorderXY);
                borderXY = cell(nCells,1);
                for n=1:length(cellBorderXY)
                    borderXY{n} = [cellBorderXY(n).ConvexHull*sc; NaN,NaN];
                end
                borderXY = cat(1,borderXY{:});
                app.annotations.cellBordersXY = borderXY;             
                % -- plot
                NcolorViewerFigure('figHandle',app.pars.LoadMosaic.figHandle,'display','none');
                % figure(1); clf;
                spotXY = XYabsToXYview(app,borderXY);  % app.annotations.cellBordersXY
                hold on;
                plot(spotXY(:,1),spotXY(:,2),'-'); 
                hold off;  
                app.annotations.lastUpdate = 'SegmentCells';
                disp('Click Add Annotation to Table to save this segmentation')
            end
        end

        % Button pushed function: RecordROIButton
        function RecordROIButtonPushed(app, event)
            disp('Enter the marker names you wish to record as a comma separated list without spaces, (e.g. "Embryo1,Embryo2,Embryo",... or "T1,T2,T3",...).')
            disp('All spots in the region displayed will be classified as one of these names.')
            disp('New marker names will be recorded as new columns in the annotations table.')
            disp('Reused marker names will be added as members of existing data column using logical OR.')
            % prompt update parameters
            [app.pars.RecordROI,canceled] = SimpleParameterGUI(app.pars.RecordROI);
            if ~canceled
                % get spotXY in current view
                currOrigin = [app.nca.pixelBox.x(1)-1,app.nca.pixelBox.y(1)-1];
                if ~isempty(app.mosaicSpotXYf)
                    spotXY = app.mosaicSpotXYf(:,1:2)*(app.nca.scale)^app.nca.currScale;
                    spotXY = spotXY - repmat(currOrigin,size(spotXY,1),1);
                else
                    warning('no CT data loaded. Load CT data first if you want it assigned within an ROI.')
                    spotXY = [];
                end
                % call Image_Segmentation GUI
                [segmentIDs, polygonData] = SegmentationGUI(app.pars.LoadMosaic.figHandle,... % just pass the current figure handle
                    'parameters',app.pars.RecordROI,'Points',spotXY);
                % convert polygonData.xy back to mosaic coordinates
                nROI = height(polygonData);
                for n=1:nROI
                    roiXY = polygonData.Positions{n};
                    roiXY = (roiXY + repmat(currOrigin,size(roiXY,1),1)) /(app.nca.scale)^app.nca.currScale;
                    polygonData.Positions{n} = roiXY;
                end
                app.annotations.polyDataTemp = polygonData;
                app.annotations.segIDs = segmentIDs; % strings, length spotXY. empty for spots not assigned annotation in this round.
                app.annotations.lastUpdate = 'RecordROI';
                % so the trick is just to not update IDs for unlabeled spots).
                disp('select "Add Annotation to Table" Button to save these annotations. If not added, they will be overwritten when Record ROI is pressed again.')
            end
        end

        % Button pushed function: CountSpotsButton
        function CountSpotsButtonPushed(app, event)
            % prompt update parameters
            % app.pars.CountSpots = SimpleParameterGUI(app.pars.CountSpots);
            %    - only parameter is in the slider.
            % ---- read in current view and threshold 
            disp('use slider to select threshold for spot counting.')
            disp('it is recommended that you first zoom in on a region of interest in the Ncolor GUI to calibrate the threshold.')
            disp('Then zoom out to the full mosaic to count all spots in the complete mosaic')
            disp('select Add Annotation to Table button to save your results.')
            xl = floor(app.nca.viewBox.x);
            yl = floor(app.nca.viewBox.y);
            sc = (app.nca.scale)^app.nca.currScale;
            c = app.nca.selectedChannels; % 27 = snail
            rnaIm = app.nca.im_orig(yl(1):yl(2),xl(1):xl(2),c); % apply to raw, non-contrasted image
            % imMax = max(rnaIm(:));
            imMax = max(app.nca.im_orig(:));
            theta = app.Slider.Value/100*imMax;
            % ----- apply simple absolute threshold
            bw = imregionalmax(rnaIm); 
            bw2 = bw;
            bw2(rnaIm<theta)  = 0;
            [h,w] = size(rnaIm(:,:,1));
            [y,x] = ind2sub([h,w],find(bw2));
            brightness = rnaIm(bw2); 
            spotName = cellstr(repmat(app.annotations.chnNames{c},length(x),1)); 
            if length(x) <= 1
                disp('no spots found!');
            else
                 spotCounts = table(x,y,brightness,spotName);
                % ----- record results
                app.annotations.spotCountTemp = spotCounts;
                % ----- plot results
                % figure(2); clf; imagesc(rnaIm); hold on;
                NcolorViewerFigure('figHandle',app.pars.LoadMosaic.figHandle,'display','none');
                delete(findobj(gca,'Type','line')); % clear existing lines; 
                hold on;
                plot(x*sc,y*sc,'yo');
                hold off;
                app.annotations.lastUpdate = 'CountSpots';
            end
           
        end

        % Button pushed function: AddAnnotationtoTableButton
        function AddAnnotationtoTableButtonPushed(app, event)
            % app.pars.AddAnnotation = SimpleParameterGUI(app.pars.AddAnnotation);
            if isempty(app.annotations.tableData)
                x =  app.mosaicSpotXYf(:,1);
                y =  app.mosaicSpotXYf(:,2);
                fov = app.mosaicSpotXYf(:,3);
                s = app.mosaicSpotXYf(:,4);
                sidx = app.mosaicSpotXYf(:,5);
                app.annotations.tableData = table(x,y,fov,s,sidx);
            end      
            % nSpots = height(app.annotations.tableData); % all fiducial spots. 
            switch app.annotations.lastUpdate
                case 'RecordROI'
                    % segIDs is a list of length nSpots with strings = new
                    % ID values (probably should change to a table). 
                    addedFields = unique(app.annotations.segIDs);
                    addedFields(cellfun(@isempty,addedFields)) = []; % remove empty fields
                    if isempty(app.annotations.tableData)
                        app.annotations.tableData = table();
                    end
                    currentFields = app.annotations.tableData.Properties.VariableNames;
                    newFields = setdiff(addedFields,currentFields);
                    for f=1:length(newFields)
                        app.annotations.tableData.(newFields{f}) = app.annotations.segIDs == newFields{f};
                    end
                    fieldsToUpdate = intersect(currentFields,addedFields);
                    for f=1:length(fieldsToUpdate)
                        newValues = app.annotations.segIDs == fieldsToUpdate{f};
                        oldValues = app.annotations.tableData.(fieldsToUpdate{f});
                        app.annotations.tableData.(fieldsToUpdate{f}) = newValues | oldValues;
                    end
                    nPolys = height(app.annotations.polyDataTemp);
                    x = []; y = []; Tag = {};
                    for n=1:nPolys
                        xy = app.annotations.polyDataTemp.Positions{n};
                        x = cat(1,x,xy(:,1));
                        y = cat(1,y,xy(:,2));
                        Tag = cat(1,Tag,repmat(app.annotations.polyDataTemp.Tag(n),size(xy,1),1));
                    end
                    app.annotations.polyDataTemp = table();
                    newPolyTable = table(x,y,Tag);
                    app.annotations.polygonTable = cat(1,...
                        app.annotations.polygonTable,...
                        newPolyTable);
                    disp('ROI added to tableData');
                case 'CountSpots'
                    app.annotations.spotCountTable = cat(1,...
                            app.annotations.spotCountTable,...
                            app.annotations.spotCountTemp);
                        % x,y,brightness,chnName
                    disp('spot counts added to table')                  
                case 'SegmentCells'
                    NcolorViewerFigure('figHandle',app.pars.LoadMosaic.figHandle,'display','none');
                    % figure(1); clf;
                    spotXY = XYabsToXYview(app,app.annotations.cellBordersXY);  % 
                    hold on;
                    plot(spotXY(:,1),spotXY(:,2),'-'); 
                    hold off;   
                    disp('added cell segmentation to table');
                otherwise
                    disp('no annotations to add')
            end
            UpdateTree(app);
        end

        % Callback function
        function SaveAnnotationTableButtonPushed(app, event)
            [app.pars.SaveAnnotation,canceled] = SimpleParameterGUI(app.pars.SaveAnnotation);
            if ~canceled
                SaveAllAnnotations(app);
                
            end
            
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            app.EditField.Value = num2str(value);
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            app.Slider.Value = str2double(value);
            
        end

        % Button pushed function: ClearImageButton
        function ClearImageButtonPushed(app, event)
            % clears all annotations from image
            NcolorViewerFigure('figHandle',app.pars.LoadMosaic.figHandle,'display','none');
            delete(findobj(gca,'Type','Line'));
            delete(findobj(gca,'Type','Scatter'));
            delete(findobj(gca,'Type','Text'));
            delete(findobj(gca,'Type','Group'));
            delete(findobj(gca,'Type','Patch'));
            
            % CallNcolor(app);
        end

        % Button pushed function: ShowAnnotationsButton
        function ShowAnnotationsButtonPushed(app, event)
            ShowAnnotations(app); % updated function. Old behavior below
%             % Displays selected annotations
%             disp(['Select annotation to show: ','counted spots','; ','ROI polygons','; ','cell edges','; ','box FOVs','; ','CT spots']);
%             [app.pars.ShowAnnotation,canceled] = SimpleParameterGUI(app.pars.ShowAnnotation);
%             if ~canceled
%                 switch app.pars.ShowAnnotation.annotation
%                     case 'counted spots'
%                         ShowCountedSpots(app);
%                         % now we need to plot these on the figure
%                     case 'ROI polygons'
%                         ShowPolygons(app);
%                         % plot these on the figure
%                     case 'cell edges'
%                         ShowEdges(app);
%                     case 'box FOVs'
%                         BoxFOVsButtonPushed(app, event);
%                         % already written as a buttong (could consolidate)
%                     case 'CT spots'
%                         ShowCTspotsButtonPushed(app, event);
%                         % already written as a buttong (could consolidate)
%                     otherwise 
%             
%                 end
%             end
        end

        % Menu selected function: LoadMosaicMenu
        function LoadMosaicMenuSelected(app, event)
            LoadMosaicData(app);
        end

        % Menu selected function: LoadCTDataMenu
        function LoadCTDataMenuSelected(app, event)
            LoadCTData(app);
        end

        % Button pushed function: TestFeatureButton
        function TestFeatureButtonPushed(app, event)
            disp('this button is for debugging')
            ChangeTitle(app);
           %  app.data.allDistMap = [];
           % UpdateScopePars(app); disp('called UpdateScopePars')
        end

        % Menu selected function: LoadAnnotationsMenu
        function LoadAnnotationsMenuSelected(app, event)
            LoadAnnotations(app);
        end

        % Menu selected function: SaveAnnotationsMenu
        function SaveAnnotationsMenuSelected(app, event)
            SaveAllAnnotations(app);
        end

        % Menu selected function: DisplayparametersMenu
        function DisplayparametersMenuSelected(app, event)
            
        end

        % Menu selected function: DistancemapparametersMenu
        function DistancemapparametersMenuSelected(app, event)
            app.pars.DistMapPars = SimpleParameterGUI(app.pars.DistMapPars);
        end

        % Menu selected function: DistanceMapMenu
        function DistanceMapMenuSelected(app, event)
            app.pars.DistMapPars = SimpleParameterGUI(app.pars.DistMapPars);
            ROIs = strsplit(app.pars.DistMapPars.ROIs,',');
            if isempty(app.data.allDistMap)
                ComputeAllDistMap(app);
            end
            tableData = app.annotations.tableData; % short hand
            % loop through cell list of strings of ROIs (e.g. ROIs = {'A1','A2','A3'})  
            if strcmp(ROIs{1},'all')
                selectSpots = true(size(app.data.allDistMap,3),1);
            else
                selectSpots = false(size(app.data.allDistMap,3),1);
                for f=1:length(ROIs)
                    selectSpots = selectSpots | app.annotations.tableData.(ROIs{f});
                end
            end
            distMap = app.data.allDistMap(:,:,selectSpots);
            medMap = nanmedian(distMap,3);
            medMap = InterpMapNans(medMap,'badHybes',app.pars.DistMapPars.badHybes);
            app.data.currDistanceMap = medMap;
            ImageWithContrastSlider(medMap,'figHandle',app.pars.DistMapPars.figHandle);
        end

        % Menu selected function: ContactMapMenu
        function ContactMapMenuSelected(app, event)
            app.pars.ContMapPars = SimpleParameterGUI(app.pars.ContMapPars);
            ROIs = strsplit(app.pars.ContMapPars.ROIs,',');
            if isempty(app.data.allDistMap)
                ComputeAllDistMap(app);
            end
            tableData = app.annotations.tableData; % short hand
            % loop through cell list of strings of ROIs (e.g. ROIs = {'A1','A2','A3'})  
            if strcmp(ROIs{1},'all')
                selectSpots = true(size(app.data.allDistMap,3),1);
            else
                selectSpots = false(size(app.data.allDistMap,3),1);
                for f=1:length(ROIs)
                    selectSpots = selectSpots | app.annotations.tableData.(ROIs{f});
                end
            end
            distMap = app.data.allDistMap(:,:,selectSpots);
            [medMap,cntMap] = ContactFrac(distMap,'threshold',app.pars.ContMapPars.threshold);
            medMap = InterpMapNans(medMap,'badHybes',app.pars.ContMapPars.badHybes);
            app.data.currContactMap = medMap;
            ImageWithContrastSlider(medMap,'figHandle',app.pars.ContMapPars.figHandle);
            title(app.pars.ContMapPars.ROIs);
            figure(201); clf; imagesc(cntMap); title(app.pars.ContMapPars.ROIs);
        end

        % Menu selected function: CoverageMapMenu
        function CoverageMapMenuSelected(app, event)
            app.pars.ContMapPars = SimpleParameterGUI(app.pars.ContMapPars);
            ROIs = strsplit(app.pars.ContMapPars.ROIs,',');
            if isempty(app.data.allDistMap)
                ComputeAllDistMap(app);
            end
            tableData = app.annotations.tableData; % short hand
            % loop through cell list of strings of ROIs (e.g. ROIs = {'A1','A2','A3'})  
            if strcmp(ROIs{1},'all')
                selectSpots = true(height(tableData),1);
            else
                selectSpots = false(height(tableData),1);
                for f=1:length(ROIs)
                    selectSpots = selectSpots | app.annotations.tableData.(ROIs{f});
                end
            end
            distMap = app.data.allDistMap(:,:,selectSpots);
            [~,cntMap] = ContactFrac(distMap,'threshold',app.pars.ContMapPars.threshold);
            ImageWithContrastSlider(cntMap,'figHandle',app.pars.ContMapPars.figHandle);
            title(app.pars.ContMapPars.ROIs);
        end

        % Menu selected function: EditdisplaytitleMenu
        function EditdisplaytitleMenuSelected(app, event)
            ChangeTitle(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.LoadMosaicMenu = uimenu(app.FileMenu);
            app.LoadMosaicMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadMosaicMenuSelected, true);
            app.LoadMosaicMenu.Text = 'Load Mosaic';
            app.LoadCTDataMenu = uimenu(app.FileMenu);
            app.LoadCTDataMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadCTDataMenuSelected, true);
            app.LoadCTDataMenu.Text = 'Load CT Data';
            app.LoadAnnotationsMenu = uimenu(app.FileMenu);
            app.LoadAnnotationsMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadAnnotationsMenuSelected, true);
            app.LoadAnnotationsMenu.Text = 'Load Annotations';
            app.SaveAnnotationsMenu = uimenu(app.FileMenu);
            app.SaveAnnotationsMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveAnnotationsMenuSelected, true);
            app.SaveAnnotationsMenu.Text = 'Save Annotations';
            app.EditMenu = uimenu(app.UIFigure);
            app.EditMenu.Text = 'Edit';
            app.DisplayparametersMenu = uimenu(app.EditMenu);
            app.DisplayparametersMenu.MenuSelectedFcn = createCallbackFcn(app, @DisplayparametersMenuSelected, true);
            app.DisplayparametersMenu.Text = 'Display parameters';
            app.DistancemapparametersMenu = uimenu(app.EditMenu);
            app.DistancemapparametersMenu.MenuSelectedFcn = createCallbackFcn(app, @DistancemapparametersMenuSelected, true);
            app.DistancemapparametersMenu.Text = 'Distance map parameters';
            app.EditdisplaytitleMenu = uimenu(app.EditMenu);
            app.EditdisplaytitleMenu.MenuSelectedFcn = createCallbackFcn(app, @EditdisplaytitleMenuSelected, true);
            app.EditdisplaytitleMenu.Text = 'Edit display title';
            app.AnalyzeMenu = uimenu(app.UIFigure);
            app.AnalyzeMenu.Text = 'Analyze';
            app.DistanceMapMenu = uimenu(app.AnalyzeMenu);
            app.DistanceMapMenu.MenuSelectedFcn = createCallbackFcn(app, @DistanceMapMenuSelected, true);
            app.DistanceMapMenu.Text = 'Distance Map';
            app.ContactMapMenu = uimenu(app.AnalyzeMenu);
            app.ContactMapMenu.MenuSelectedFcn = createCallbackFcn(app, @ContactMapMenuSelected, true);
            app.ContactMapMenu.Text = 'Contact Map';
            app.RNAspotsinROIMenu = uimenu(app.AnalyzeMenu);
            app.RNAspotsinROIMenu.Text = 'RNA spots in ROI';
            app.RNAspotsinCellMenu = uimenu(app.AnalyzeMenu);
            app.RNAspotsinCellMenu.Text = 'RNA spots in Cell';
            app.SegmentCellsMenu = uimenu(app.AnalyzeMenu);
            app.SegmentCellsMenu.Text = 'Segment Cells';
            app.CountSpotsMenu = uimenu(app.AnalyzeMenu);
            app.CountSpotsMenu.Text = 'Count Spots';
            app.RecordROIMenu = uimenu(app.AnalyzeMenu);
            app.RecordROIMenu.Text = 'Record ROI';
            app.CoverageMapMenu = uimenu(app.AnalyzeMenu);
            app.CoverageMapMenu.MenuSelectedFcn = createCallbackFcn(app, @CoverageMapMenuSelected, true);
            app.CoverageMapMenu.Text = 'Coverage Map';
            app.DisplayMenu = uimenu(app.UIFigure);
            app.DisplayMenu.Text = 'Display';
            app.ClearImageMenu = uimenu(app.DisplayMenu);
            app.ClearImageMenu.Text = 'Clear Image';
            app.RecordROIButton = uibutton(app.UIFigure, 'push');
            app.RecordROIButton.ButtonPushedFcn = createCallbackFcn(app, @RecordROIButtonPushed, true);
            app.RecordROIButton.Text = 'Record ROI';
            app.SegmentCellsButton = uibutton(app.UIFigure, 'push');
            app.SegmentCellsButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentCellsButtonPushed, true);
            app.SegmentCellsButton.Text = 'Segment Cells';
            app.BoxFOVsButton = uibutton(app.UIFigure, 'push');
            app.BoxFOVsButton.ButtonPushedFcn = createCallbackFcn(app, @BoxFOVsButtonPushed, true);
            app.BoxFOVsButton.Text = 'Box FOVs';
            app.CountSpotsButton = uibutton(app.UIFigure, 'push');
            app.CountSpotsButton.ButtonPushedFcn = createCallbackFcn(app, @CountSpotsButtonPushed, true);
            app.CountSpotsButton.Text = 'Count Spots';
            app.AddAnnotationtoTableButton = uibutton(app.UIFigure, 'push');
            app.AddAnnotationtoTableButton.ButtonPushedFcn = createCallbackFcn(app, @AddAnnotationtoTableButtonPushed, true);
            app.AddAnnotationtoTableButton.Text = 'Add Annotation to Table';
            app.LoadMosaicfirstthenLoadCTdatathenexploreLabel = uilabel(app.UIFigure);
            app.LoadMosaicfirstthenLoadCTdatathenexploreLabel.Text = 'Load Mosaic first, then Load CT data, then explore.';
            app.ShowCTspotsButton = uibutton(app.UIFigure, 'push');
            app.ShowCTspotsButton.ButtonPushedFcn = createCallbackFcn(app, @ShowCTspotsButtonPushed, true);
            app.ShowCTspotsButton.Text = 'Show CT spots';
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Value = 99;
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.ClearImageButton = uibutton(app.UIFigure, 'push');
            app.ClearImageButton.ButtonPushedFcn = createCallbackFcn(app, @ClearImageButtonPushed, true);
            app.ClearImageButton.Text = 'Clear Image';
            app.ShowAnnotationsButton = uibutton(app.UIFigure, 'push');
            app.ShowAnnotationsButton.ButtonPushedFcn = createCallbackFcn(app, @ShowAnnotationsButtonPushed, true);
            app.ShowAnnotationsButton.Text = 'Show Annotations';
            app.Tree = uitree(app.UIFigure);
            app.Tree.Multiselect = 'on';
            app.Tree.Editable = 'on';
            app.TestFeatureButton = uibutton(app.UIFigure, 'push');
            app.TestFeatureButton.ButtonPushedFcn = createCallbackFcn(app, @TestFeatureButtonPushed, true);
            app.TestFeatureButton.Text = 'Test Feature';
            app.AvailableAnnotationsLabel = uilabel(app.UIFigure);
            app.AvailableAnnotationsLabel.Text = 'Available Annotations';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MosaicAnalyzer(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end