classdef MosaicViewApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MosaicViewerAppUIFigure  matlab.ui.Figure
        FileMenu                 matlab.ui.container.Menu
        SaveTiffMenu             matlab.ui.container.Menu
        ParametersMenu           matlab.ui.container.Menu
        EditParametersMenu       matlab.ui.container.Menu
        DoneButton               matlab.ui.control.Button
        ZoomOutButton            matlab.ui.control.Button
        ZoomInHereButton         matlab.ui.control.Button
        ShowBoxesButton          matlab.ui.control.Button
        SelectTileButton         matlab.ui.control.Button
        DirectionsTextArea       matlab.ui.control.TextArea
        DirectionsTextAreaLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        Property % Description
    end
    
    properties (Access = public)
        % THIS VERSION IS MODIFIED FROM MOSAICVIEWAPP.  IT IS NOT FUNCTIONAL YET
        
        inputPars % input parameters
        mosaicIm  %
        % original data
        fullImageTiles  = {}; % original tile (not downsampled)
        fullUlPositions = []; % original tile positions
        fullMosaicIm          % original image stored for speed
        % downsample data for speed
        masterDownsample= 1; % make this editable
        allImageTiles   = {}; 
        allUlPositions  = [];
        allTileIndices  = [];
        allXYshifts     = [];
        allManualShifts = [];
        % display just a subset of data for speed
        imageTiles     = {};
        tileIndices    = [];
        ulPositions    = [];
        xyShifts       = [];
        manualShifts   = [];
        zoomStatus     = true; 
        % interactive 
        selectTile  = [];
        currShift   = [];
        currDownsample = 1;
        % other
        appHandles
    end
    
    methods (Access = public)
      
    
        %------------------------------------------------------------% 
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
             % -------- Parse variable arguments
            defaults = cell(0,3);
            defaults(end+1,:) = {'figHandle','freeType',100};
            defaults(end+1,:) = {'mosaicContrastLow','fraction',.1};
            defaults(end+1,:) = {'mosaicContrastHigh','fraction',.9999};
            defaults(end+1,:) = {'displaySize','integer',2E3};
            defaults(end+1,:) = {'displayScale','fraction',0}; % 0 for auto-display size
            defaults(end+1,:) = {'names','cell',{}};
            defaults(end+1,:) = {'verbose','boolean',true}; % doesn't propegate yet 
            % MV render parameters
            defaults(end+1,:) = {'method',{'mean','sum','edgeBlur','last','first'},'edgeBlur'};
            defaults(end+1,:) = {'flatten','boolean',false};
            defaults(end+1,:) = {'padMosaic','nonnegative',0};
            % mosaic tile adjustments
            defaults(end+1,:) = {'transpose','boolean',false};
            defaults(end+1,:) = {'fliplr','boolean',false};
            defaults(end+1,:) = {'flipud','boolean',false};
            defaults(end+1,:) = {'xyShifts','freeType',[]}; % array or empty
            pars = ParseVariableArguments(varargin(3:end),defaults,mfilename);
            pars.corrAlign = false; % The App is not for interactive alignment!
            pars.interactive = false; % The App is not for interactive alignment!
            app.inputPars = pars; % hopefully this works
   
            % appHandles for child Apps
            app.appHandles.ncaHandle = []; pause(.01);
            
            % --------- Parse required arguments
            if isempty(varargin{1}) || isempty(varargin{2})
                error('Please pass MosaicViewerGUI a cell-array of images and a vector of upper left coordinates');
            end
            imTiles = varargin{1};
            uls = round(varargin{2}); % enforce integer values
            
            %---- create downsampled data
            [nFovs, nChns] = size(imTiles);
            mData = cell(nChns,1);
            mData_s = cell(nChns,1);
            mData_ss= cell(nChns,1);
            sc = 0.2; % scale factor, should become optional variable
            disp('loading mosaic data, please wait...')
            for c=1:nChns
                mData{c} = TilesToMosaic(imTiles(:,c),ul,'padMosaic',1);
                mData_s{c} = imresize(mData{c},sc);
                mData_ss{c} = imresize(mData_s{c},sc);
            end
            app.moData = cat(3,mData{:});
            app.moData_s = cat(3,mData_s{:});
            app.moData_ss = cat(3,mData_ss{:});
            disp('mosaic data loaded'); 

            app.currZoom = 2; % original default
            
        end

        % Button pushed function: ZoomInHereButton
        function ZoomInHereButtonPushed(app, event)
            app.zoomStatus = true;
            app.currDownsample = 1; % new design, zoom in is always full res
            MosaicViewerFigure('figHandle',app.inputPars.figHandle,...
                'display','none');
            xlims = get(gca,'xlim');
            ylims = get(gca,'ylim');
            uls = app.ulPositions; 
            inView = uls(:,1) > xlims(1) & uls(:,1) < xlims(2) & ...
                     uls(:,2) > ylims(1) & uls(:,2) < ylims(2);
            if sum(inView)>0
                % uls = uls(inView,:);  % changed zoom behavior 
                uls = app.fullUlPositions(inView,:);
                rezero = min(uls,[],1);
                % app.ulPositions = [uls(:,1)-rezero(1)+1,uls(:,2)-rezero(2)+1];  % changed zoom behavior 
                app.ulPositions = [uls(:,1)-rezero(1)+1,uls(:,2)-rezero(2)+1];
                app.imageTiles = app.fullImageTiles(inView,:); % app.imageTiles(inView,:);   % changed zoom behavior 
                app.tileIndices = app.tileIndices(inView);
                app.xyShifts = app.xyShifts(inView,:);
                app.manualShifts = app.manualShifts(inView,:);
                RebuildMosaic(app);
            else
                warning('no tiles in view! try to zoom out a bit more'); 
            end
        end

        % Button pushed function: ZoomOutButton
        function ZoomOutButtonPushed(app, event)
            app.currDownsample = app.masterDownsample;
            app.zoomStatus = false;
            app.imageTiles = app.allImageTiles;  % just the local parameters
            app.ulPositions = app.allUlPositions;
            app.tileIndices = app.allTileIndices;
            app.xyShifts = app.allXYshifts;
            app.manualShifts = app.allManualShifts;
            RebuildMosaic(app);
        end

        % Button pushed function: SelectTileButton
        function SelectTileButtonPushed(app, event)
            textDir = {'Use the cross-hairs to select tile. Then use the arrow keys to move. w/s for fine up/down, a/d for fine left right.';
                'Use j/l for coarse left right, i/k for coarse up down.'; 'x to save new position'};
            app.DirectionsTextArea.Value = textDir;
            % select a tile
            boxCoords = ULsToBoxCoords(app,app.imageTiles,app.ulPositions);
            app = UpdateMosaicImage(app);
            [x,y] =  ginput(1);
            isM = x>boxCoords(:,1) & x<boxCoords(:,2) & y>boxCoords(:,3) & y<boxCoords(:,4);
            m = find(isM);
            if length(m) > 1
                m = m(1);
            elseif isempty(m)
                error('no tile found under cursor, please try again');
            end
            app.selectTile = m; % save 
            
            % remove tile from gray image;
            imTiles = app.imageTiles;
            ulPos = app.ulPositions + app.xyShifts + app.manualShifts/app.currDownsample;
            imTiles(m,:) = [];
            ulPos(m,:) = [];     
            [~,nChns] = size(imTiles);
            mosaicIms = cell(nChns,1);
            for n=1:nChns
                mosaicIms{n} = MosaicViewerRender(imTiles(:,n),ulPos,...
                                'parameters',app.inputPars,...
                                'xyShifts',[],... already added to ulPos
                                'stopOnError',true);
            end
            app.mosaicIm = cat(3,mosaicIms{:});
            app = ResetShifts(app); % set shifts back to 0 on selection of new tile;
            UpdateOverlay(app);
            pause(.1);
        end

        % Button pushed function: ShowBoxesButton
        function ShowBoxesButtonPushed(app, event)
            MosaicViewerFigure('figHandle',app.inputPars.figHandle,...
                'display','none');
            lineHandle = findobj(gca,'Type','Rectangle');
            textHandle = findobj(gca,'Type','Text');
            if ~isempty(lineHandle) || ~isempty(textHandle)
                delete(lineHandle);
                delete(textHandle);
            else
                data.xy = app.ulPositions;
                imTiles = app.imageTiles;
                nFOV = length(imTiles);
                for f=1:nFOV
                    [h,w] = size(imTiles{f});
                    data.imSize = [h,w];
                    data.f = f;
                    MosaicViewerFigure('figHandle',app.inputPars.figHandle,...
                        'display','drawBoxes','data',data,'callFig',f==1);
                end
            end
        end

        % Close request function: MosaicViewerAppUIFigure
        function MosaicViewerAppUIFigureCloseRequest(app, event)
            % outputs are saved in the workspace that called the app
            %   inside the structure mvaOut.
            mvaOut.manualShifts = app.allManualShifts;
            assignin('caller','mvaOut',mvaOut);
            try % close the figure if you haven't killed it first
                close(app.inputPars.figHandle);
            catch
            end
            delete(app);
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            % closes app
            MosaicViewerAppUIFigureCloseRequest(app, event);
        end

        % Menu selected function: EditParametersMenu
        function EditParametersMenuSelected(app, event)
            app.inputPars = SimpleParameterGUI(app.inputPars);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MosaicViewerAppUIFigure = uifigure('Visible', 'off');
            app.MosaicViewerAppUIFigure.Name = 'Mosaic Viewer App';
            app.MosaicViewerAppUIFigure.CloseRequestFcn = createCallbackFcn(app, @MosaicViewerAppUIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.MosaicViewerAppUIFigure);
            app.FileMenu.Text = 'File';
            app.SaveTiffMenu = uimenu(app.FileMenu);
            app.SaveTiffMenu.Text = 'Save Tiff';
            app.ParametersMenu = uimenu(app.MosaicViewerAppUIFigure);
            app.ParametersMenu.Text = 'Parameters';
            app.EditParametersMenu = uimenu(app.ParametersMenu);
            app.EditParametersMenu.MenuSelectedFcn = createCallbackFcn(app, @EditParametersMenuSelected, true);
            app.EditParametersMenu.Text = 'Edit Parameters';
            app.DirectionsTextAreaLabel = uilabel(app.MosaicViewerAppUIFigure);
            app.DirectionsTextAreaLabel.Text = 'Directions';
            app.DirectionsTextArea = uitextarea(app.MosaicViewerAppUIFigure);
            app.SelectTileButton = uibutton(app.MosaicViewerAppUIFigure, 'push');
            app.SelectTileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectTileButtonPushed, true);
            app.SelectTileButton.Text = 'Select Tile';
            app.ShowBoxesButton = uibutton(app.MosaicViewerAppUIFigure, 'push');
            app.ShowBoxesButton.ButtonPushedFcn = createCallbackFcn(app, @ShowBoxesButtonPushed, true);
            app.ShowBoxesButton.Text = 'Show Boxes';
            app.ZoomInHereButton = uibutton(app.MosaicViewerAppUIFigure, 'push');
            app.ZoomInHereButton.ButtonPushedFcn = createCallbackFcn(app, @ZoomInHereButtonPushed, true);
            app.ZoomInHereButton.Text = 'Zoom In Here';
            app.ZoomOutButton = uibutton(app.MosaicViewerAppUIFigure, 'push');
            app.ZoomOutButton.ButtonPushedFcn = createCallbackFcn(app, @ZoomOutButtonPushed, true);
            app.ZoomOutButton.Text = 'Zoom Out';
            app.DoneButton = uibutton(app.MosaicViewerAppUIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            % Show the figure after all components are created
            app.MosaicViewerAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MosaicViewApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MosaicViewerAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MosaicViewerAppUIFigure)
        end
    end
end