classdef MosaicViewerApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MosaicViewerAppUIFigure  matlab.ui.Figure
        FileMenu                 matlab.ui.container.Menu
        SaveTiffMenu             matlab.ui.container.Menu
        ParametersMenu           matlab.ui.container.Menu
        EditParametersMenu       matlab.ui.container.Menu
        FlipTilesMenu            matlab.ui.container.Menu
        FlattenIlluminationMenu  matlab.ui.container.Menu
        DirectionsTextAreaLabel  matlab.ui.control.Label
        DirectionsTextArea       matlab.ui.control.TextArea
        SelectTileButton         matlab.ui.control.Button
        ShowBoxesButton          matlab.ui.control.Button
        ZoomInHereButton         matlab.ui.control.Button
        ZoomOutButton            matlab.ui.control.Button
        DoneButton               matlab.ui.control.Button
    end

    
    properties (Access = private)
        Property % Description
    end
    
    properties (Access = public)
        inputPars % input parameters
        fixedPars % fixed parameters for MosaicViewerRender
        flipTilePars % parameters for flipTile menu
        backgroundPars % parameters for illumination flatten menu
        mosaicIm  %
        % original data
        fullImageTiles  = {}; % original tile (not downsampled)
        origImageTiles  = {}; % copy of original tile positions for flatten/rotation
        fullUlPositions = []; % original tile positions
        fullMosaicIm          % original image stored for speed
        % downsample data for speed
        masterDownsample= 1; % make this editable
        allImageTiles   = {}; 
        allUlPositions  = [];
        allTileIndices  = [];
        allManualShifts = [];
        % display just a subset of data for speed
        imageTiles     = {};
        tileIndices    = [];
        ulPositions    = [];
        manualShifts   = [];
        zoomStatus     = true; 
        % interactive 
        selectTile  = [];
        currShift   = [];
        currDownsample = 1;
        % other
        appHandles
    end
    
    methods (Access = public)
        
        function   app = ResetMosaicDisplay(app)
            % compute downsampled versions for faster display
            [app.allImageTiles, app.allUlPositions, app.masterDownsample] = ...
            ComputeDownsampling(app,app.fullImageTiles,app.fullUlPositions,...
            app.inputPars.displaySize,'displayScale',app.inputPars.displayScale);
            % the currently active tiles (initially all tiles)
            %   active tiles are determined by 'zoom'
            %   using only a subset of tiles speeds up viewing/processing
            app.currDownsample = app.masterDownsample;
            app.imageTiles  = app.allImageTiles;
            app.tileIndices = app.allTileIndices;
            app.ulPositions = app.allUlPositions;
            app.manualShifts= app.allManualShifts;
            app.zoomStatus  = true; % for speed, if zoom is off the app will use the originally computed mosaic
         
            % plot results            
            app = RebuildMosaic(app);
            app.fullMosaicIm = app.mosaicIm; % save full res image for later
        end
        
        
        % Compute Downsampling
        function [imageTilesSmall, ulsSmall, downsample, dataSize] = ...
             ComputeDownsampling(app,imageTiles,uls,displaySize,varargin)  %#ok<*INUSL>
             defaults = cell(0,3);
             defaults(end+1,:) = {'displayScale','fraction',0};
             pars = ParseVariableArguments(varargin,defaults,'ComputeDownsampling');
            xmin = min(uls(:,1));
            xmax = max(uls(:,1));
            ymin = min(uls(:,2));
            ymax = max(uls(:,2));
            [h_i,w_i] = size(imageTiles{1});
            h_m = round(ymax-ymin+1+3*h_i);
            w_m = round(xmax-xmin+1+3*w_i);
            dataSize = round(sqrt(h_m*w_m));
            if pars.displayScale == 0
                downsample = max(ceil(dataSize/displaySize),1);
            else
                downsample = 1/pars.displayScale;
            end
            [nTiles,nChns] = size(imageTiles);
            imageTilesSmall = cell(nTiles,nChns);
            ulsSmall = ceil(uls/downsample);
            for m=1:nTiles
                for n=1:nChns
                    imageTilesSmall{m,n} = imresize(imageTiles{m,n},1/downsample);
                end
            end
        end
        
    % Build the mosaic from image tiles
        function app = RebuildMosaic(app)
             if ~app.zoomStatus
                app.mosaicIm = app.fullMosaicIm;
             else   
                uls = app.ulPositions + app.manualShifts/app.masterDownsample;
                [nTiles,nChns] = size(app.imageTiles); %#ok<ASGLU>
                mosaicIms = cell(nChns,1);
                tic
                for n=1:nChns
                    mosaicIms{n} = MosaicViewerRender(app.imageTiles(:,n),uls,...
                        'parameters',JoinStructures(app.inputPars,app.fixedPars),...
                        'xyShifts',[],... % already handled above
                        'stopOnError',true);
                end
                toc
                app.mosaicIm = cat(3,mosaicIms{:});
                disp('assembly complete');
            end
            app = UpdateMosaicImage(app); % applies contrast
        end
        
    
    % Display the mosaic 
    %   (apply contrast, adjust colors)
        function app = UpdateMosaicImage(app)
            c_m = size(app.mosaicIm,3);
            app.selectTile = [];
            if c_m == 1
                dispMosaic = IncreaseContrast(app.mosaicIm,...
                'high',app.inputPars.mosaicContrastHigh,...
                'low',app.inputPars.mosaicContrastLow);
                figData = MosaicViewerFigure(...
                    'figHandle',app.inputPars.figHandle,...
                    'image',dispMosaic,...
                    'clf',true);
                app.inputPars.figHandle = figData.handle;
            else
                 figData = MosaicViewerFigure(...
                    'figHandle',app.inputPars.figHandle,...
                    'image',app.mosaicIm,...
                    'display','Ncolor',...
                    'names',app.inputPars.names,...
                    'clf',true,...
                    'data',app.appHandles);
                app.inputPars.figHandle = figData.handle;
                app.appHandles.ncaHandle = figData.appHandle;
            end
        end
    
    % ------ Called by SelectTile ------
       
    
        % Update 2-color overlay when moving tiles
        function app = UpdateOverlay(app)
            im2 = nanmean(app.mosaicIm,3);
            [h_target,w_target] = size(im2);
            im1 = zeros(h_target,w_target,'uint16');
            imTiles = app.imageTiles;
            boxCoords = ULsToBoxCoords(app,app.imageTiles,app.ulPositions);
            shifts = app.currShift;
            m = app.selectTile;
            [h_tile,w_tile] = size(imTiles{m});
            xyadjust = app.manualShifts/app.currDownsample; % make sure these are the right ones
            ymin_target = boxCoords(m,3)+shifts.upDownOffset+xyadjust(m,2);
            ymax_target = boxCoords(m,4)+shifts.upDownOffset+xyadjust(m,2);
            xmin_target = boxCoords(m,1)+shifts.leftRightOffset+xyadjust(m,1);
            xmax_target = boxCoords(m,2)+shifts.leftRightOffset+xyadjust(m,1);
            ymin = max(1, ymin_target);
            ymax = min(h_target, ymax_target);
            xmin = max(1, xmin_target);
            xmax = min(w_target, xmax_target);
            if ymin_target < 1 
                ymin_tile = 1-ymin_target + 1;
            else
                ymin_tile = 1;
            end
            if ymax_target > h_target
                ymax_tile = h_tile-(ymax_target-h_target);  % cut plus 1 on max
            else
                ymax_tile = h_tile;
            end
            if xmin_target < 1
                xmin_tile = 1-xmin_target + 1;
            else
                xmin_tile = 1;
            end
            if xmax_target > w_target
                xmax_tile = w_tile-(xmax_target-w_target); % cut plus 1 on max
            else
               xmax_tile = w_tile; 
            end
            try
            im1(ymin:ymax,xmin:xmax) = imTiles{m}(ymin_tile:ymax_tile,xmin_tile:xmax_tile);
            catch
                disp('debug here');
                im1_coords = [ymin,ymax,xmin,xmax] %#ok<*NASGU> 
                tile_coords = [ymin_tile,ymax_tile,xmin_tile,xmax_tile] %#ok<*NOPRT> 
                im1_size = [ymax-ymin,xmax-xmin]+1
                tile_size = [ymax_tile-ymin_tile,xmax_tile-xmin_tile]+1
            end
            im1 = IncreaseContrast(im1,'low',app.inputPars.mosaicContrastLow,'high',app.inputPars.mosaicContrastHigh);
            im2 = IncreaseContrast(im2,'low',app.inputPars.mosaicContrastLow,'high',app.inputPars.mosaicContrastHigh);
            mosaicOverlay = Ncolor(cat(3,im1,im2)); 
            MosaicViewerFigure('figHandle',app.inputPars.figHandle,...
                'image',mosaicOverlay); 
        end
    
         function boxCoords = ULsToBoxCoords(app,im,uls)
                % not sure this one is worth keeping as a separate figure
                if iscell(im)
                    im = im{1};
                end
                [h_i,w_i] = size(im); 
                boxCoords = round([uls(:,1),uls(:,1)+w_i-1,uls(:,2),uls(:,2)+h_i-1]); 
            end
        
    
        function app = UpdateTilePos(app)
            shifts = app.currShift;
            t = app.selectTile;
            m = app.tileIndices(t);
            app.manualShifts(t,1) = app.manualShifts(t,1)+shifts.leftRightOffset*app.currDownsample;
            app.manualShifts(t,2) = app.manualShifts(t,2)+shifts.upDownOffset*app.currDownsample;     
            app.allManualShifts(m,1) = app.allManualShifts(m,1)+shifts.leftRightOffset*app.currDownsample;
            app.allManualShifts(m,2) = app.allManualShifts(m,2)+shifts.upDownOffset*app.currDownsample;
            app = RebuildMosaic(app);
        end
 
    
            % reset shifts to zero
            function app = ResetShifts(app)
                shifts.upDownOffset = 0;
                shifts.leftRightOffset = 0;
                shifts.angle = 0;
                app.currShift = shifts;
            end
    
        %------------------------------------------------------------% 
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
             % -------- Parse variable arguments
            defaults = cell(0,3);
            defaults(end+1,:) = {'figHandle','freeType',100};
            defaults(end+1,:) = {'mosaicContrastLow','fraction',.1};
            defaults(end+1,:) = {'mosaicContrastHigh','fraction',.9999};
            defaults(end+1,:) = {'displaySize','integer',2E3};
            defaults(end+1,:) = {'displayScale','fraction',0}; % 0 for auto-display size
            defaults(end+1,:) = {'names','cell',{}};
            defaults(end+1,:) = {'verbose','boolean',true}; % doesn't propegate yet 
            % MV render parameters
            defaults(end+1,:) = {'method',{'mean','sum','edgeBlur','last','first'},'edgeBlur'};
            defaults(end+1,:) = {'padMosaic','nonnegative',0};
            % mosaic tile adjustments
            defaults(end+1,:) = {'xyShifts','freeType',[]}; % will be serially updated, this parameter overrides default paramaters on call to MosaicViewerRender
            pars = ParseVariableArguments(varargin(3:end),defaults,mfilename);
            app.inputPars = pars; % hopefully this works
            % fixed parameters, MosaicViewerRender allows these to change
            % by the ViewApp does not. They might be inhereted so we fix
            % them here. 
            fpars.corrAlign = false;
            fpars.interactive = false;
            fpars.flatten = false;
            fpars.fliplr = false;
            fpars.flipud = false;
            fpars.transpose = false;
            app.fixedPars = fpars;
            
            % for flipping tiles
            app.flipTilePars.fliplr = false;
            app.flipTilePars.flipud = false;
            app.flipTilePars.transpose = false;
   
            % for background subtraction
            app.backgroundPars.daxFile = '';
            app.backgroundPars.method = 'median'; % 'file','median'
            app.backgroundPars.peak = .999; % adjust contrast in background flattening
            % appHandles for child Apps
            app.appHandles.ncaHandle = []; pause(.01);
            
            % --------- Parse required arguments
            if isempty(varargin{1}) || isempty(varargin{2})
                error('Please pass MosaicViewerGUI a cell-array of images and a vector of upper left coordinates');
            end
            imTiles = varargin{1};
            uls = round(varargin{2}); % enforce integer values
            
            % rezero tile positions
            minXY = min(uls);
            uls = [uls(:,1)-minXY(1)+1,uls(:,2)-minXY(2)+1];
            
            % save the full resolution tiles sizes
            app.fullImageTiles  = imTiles; % for future, allow dynamic change of resolution /speed
            app.origImageTiles = imTiles; % allows flip/transpose/background correct
            app.fullUlPositions = uls;
            [nTiles,nChns] = size(imTiles); %#ok<ASGLU>
            app.allTileIndices = 1:nTiles;
            app.allManualShifts= zeros(nTiles,2); % this will disgard all shifts
            app = ResetMosaicDisplay(app);

            
            % make figure dynamic
            figHandle = figure(app.inputPars.figHandle);
            figHandle.Name = 'MosaicViewerGUI';
            set(figHandle,'KeyPressFcn',{@FigKeyPressApp,app});
            ResetShifts(app);
            
            % setup initial instructions
            textDir = {'Use the "Select Tile" button to reposition tiles in the mosaic.';
                        'Use the "Show Boxes" button to outline tile positions'};
            app.DirectionsTextArea.Value = textDir;
            
            
        end

        % Button pushed function: ZoomInHereButton
        function ZoomInHereButtonPushed(app, event)
            app.zoomStatus = true;
            app.currDownsample = 1; % new design, zoom in is always full res
            MosaicViewerFigure('figHandle',app.inputPars.figHandle,...
                'display','none');
            xlims = get(gca,'xlim');
            ylims = get(gca,'ylim');
            uls = app.ulPositions; 
            inView = uls(:,1) > xlims(1) & uls(:,1) < xlims(2) & ...
                     uls(:,2) > ylims(1) & uls(:,2) < ylims(2);
            if sum(inView)>0
                % uls = uls(inView,:);  % changed zoom behavior 
                uls = app.fullUlPositions(inView,:);
                rezero = min(uls,[],1);
                % app.ulPositions = [uls(:,1)-rezero(1)+1,uls(:,2)-rezero(2)+1];  % changed zoom behavior 
                app.ulPositions = [uls(:,1)-rezero(1)+1,uls(:,2)-rezero(2)+1];
                app.imageTiles = app.fullImageTiles(inView,:); % app.imageTiles(inView,:);   % changed zoom behavior 
                app.tileIndices = app.tileIndices(inView);
                app.manualShifts = app.manualShifts(inView,:);
                RebuildMosaic(app);
            else
                warning('no tiles in view! try to zoom out a bit more'); 
            end
        end

        % Button pushed function: ZoomOutButton
        function ZoomOutButtonPushed(app, event)
            app.currDownsample = app.masterDownsample;
            app.zoomStatus = false;
            app.imageTiles = app.allImageTiles;  % just the local parameters
            app.ulPositions = app.allUlPositions;
            app.tileIndices = app.allTileIndices;
            app.manualShifts = app.allManualShifts;
            RebuildMosaic(app);
        end

        % Button pushed function: SelectTileButton
        function SelectTileButtonPushed(app, event)
            textDir = {'Use the cross-hairs to select tile. Then use the arrow keys to move. w/s for fine up/down, a/d for fine left right.';
                'Use j/l for coarse left right, i/k for coarse up down.'; 'x to save new position'};
            app.DirectionsTextArea.Value = textDir;
            % select a tile
            boxCoords = ULsToBoxCoords(app,app.imageTiles,app.ulPositions);
            app = UpdateMosaicImage(app);
            [x,y] =  ginput(1);
            isM = x>boxCoords(:,1) & x<boxCoords(:,2) & y>boxCoords(:,3) & y<boxCoords(:,4);
            m = find(isM);
            if length(m) > 1
                m = m(1);
            elseif isempty(m)
                error('no tile found under cursor, please try again');
            end
            app.selectTile = m; % save 
            
            % remove tile from gray image;
            imTiles = app.imageTiles;
            ulPos = app.ulPositions + app.manualShifts/app.currDownsample;
            imTiles(m,:) = [];
            ulPos(m,:) = [];     
            [~,nChns] = size(imTiles);
            mosaicIms = cell(nChns,1);
            for n=1:nChns
                mosaicIms{n} = MosaicViewerRender(imTiles(:,n),ulPos,...
                                'parameters',JoinStructures(app.inputPars,app.fixedPars),...
                                'xyShifts',[],... already added to ulPos
                                'stopOnError',true);
            end
            app.mosaicIm = cat(3,mosaicIms{:});
            app = ResetShifts(app); % set shifts back to 0 on selection of new tile;
            UpdateOverlay(app);
            pause(.1);
        end

        % Button pushed function: ShowBoxesButton
        function ShowBoxesButtonPushed(app, event)
            MosaicViewerFigure('figHandle',app.inputPars.figHandle,...
                'display','none');
            lineHandle = findobj(gca,'Type','Rectangle');
            textHandle = findobj(gca,'Type','Text');
            if ~isempty(lineHandle) || ~isempty(textHandle)
                delete(lineHandle);
                delete(textHandle);
            else
                data.xy = app.ulPositions;
                imTiles = app.imageTiles;
                nFOV = length(imTiles);
                for f=1:nFOV
                    [h,w] = size(imTiles{f});
                    data.imSize = [h,w];
                    data.f = f;
                    MosaicViewerFigure('figHandle',app.inputPars.figHandle,...
                        'display','drawBoxes','data',data,'callFig',f==1);
                end
            end
        end

        % Close request function: MosaicViewerAppUIFigure
        function MosaicViewerAppUIFigureCloseRequest(app, event)

            try % close the figure if you haven't killed it first
                close(app.inputPars.figHandle);
            catch
            end
            delete(app);
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            % closes app
            manShifts = app.allManualShifts;
            adjustedTiles = app.fullImageTiles; % flipped, contrasted
            assignin('base','manualShifts',manShifts);
            assignin('base','adjustedTiles',adjustedTiles);
            % to readout,  manualShifts = evalin('base','manualShifts'); % pull from base workspace
            MosaicViewerAppUIFigureCloseRequest(app, event);
        end

        % Menu selected function: EditParametersMenu
        function EditParametersMenuSelected(app, event)
            app.inputPars = SimpleParameterGUI(app.inputPars);
            RebuildMosaic(app);
        end

        % Menu selected function: FlipTilesMenu
        function FlipTilesMenuSelected(app, event)
            % flip tile orientation;
            app.fullImageTiles = app.origImageTiles;
            imTiles = app.fullImageTiles;
            app.flipTilePars = SimpleParameterGUI(app.flipTilePars);
            [nTiles,nChns] = size(imTiles); % please only use this with single channel data
            for c=1:nChns
                for m=1:nTiles
                    if app.flipTilePars.transpose % in same order as DaxToImageTiles
                        imTiles{m,c} = imTiles{m,c}';
                    end
                    if app.flipTilePars.fliplr
                        app.fullImageTiles{m,c} = fliplr(imTiles{m,c});
                    end
                    if app.flipTilePars.flipud
                       imTiles{m,c} = flipud(imTiles{m,c}); 
                    end
                end
            end
            app.fullImageTiles = imTiles; 
            % zoom out and replot
            ResetMosaicDisplay(app)
        end

        % Menu selected function: FlattenIlluminationMenu
        function FlattenIlluminationMenuSelected(app, event)
            app.fullImageTiles = app.origImageTiles;
            imTiles = app.fullImageTiles;
            app.backgroundPars = SimpleParameterGUI(app.backgroundPars); 
            [nTiles,nChns] = size(imTiles); % please only use this with single channel data
            cls = class(imTiles{1,1});
            if strcmp(app.backgroundPars.method,'none')
                nChns = 0; nTiles =0;  bkd = 0;% skip
            elseif strcmp(app.backgroundPars.method,'median')
                bkd = nanmedian(double(cat(3,imTiles{:})),3);
            elseif strcmp(app.backgroundPars.method,'file') 
                % a 2-D illumination profile saved as a dax.
                [filename,filepath] = uigetfile(app.backgroundPars.file);
                bkd = ReadDax([filepath,filename]);
                [h,w,s] = size(bkd);
                [hi,wi] = size(imTiles{1,1});
                if s > 1
                    error('background illumination dax file must be a 2D file, not a 3D stack');
                end
                if hi ~= h || wi ~= w
                    cprintf([1 0 0], 'background illumination dax file must be same size as image, ');
                    cprintf('red',[hi,wi]);
                    errror('wrong image size');
                end
            end
            pk = quantile(bkd(:),app.backgroundPars.peak);
            for c=1:nChns
                for m=1:nTiles
                    imTiles{m,c}  = cast(pk*double(imTiles{m,c})./double(bkd),cls);
                end
            end
            % update tiles 
            app.fullImageTiles = imTiles; 
            % zoom out and replot
            ResetMosaicDisplay(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MosaicViewerAppUIFigure = uifigure('Visible', 'off');
            app.MosaicViewerAppUIFigure.Name = 'Mosaic Viewer App';
            app.MosaicViewerAppUIFigure.CloseRequestFcn = createCallbackFcn(app, @MosaicViewerAppUIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.MosaicViewerAppUIFigure);
            app.FileMenu.Text = 'File';
            app.SaveTiffMenu = uimenu(app.FileMenu);
            app.SaveTiffMenu.Text = 'Save Tiff';
            app.ParametersMenu = uimenu(app.MosaicViewerAppUIFigure);
            app.ParametersMenu.Text = 'Parameters';
            app.EditParametersMenu = uimenu(app.ParametersMenu);
            app.EditParametersMenu.MenuSelectedFcn = createCallbackFcn(app, @EditParametersMenuSelected, true);
            app.EditParametersMenu.Text = 'Edit Parameters';
            app.FlipTilesMenu = uimenu(app.ParametersMenu);
            app.FlipTilesMenu.MenuSelectedFcn = createCallbackFcn(app, @FlipTilesMenuSelected, true);
            app.FlipTilesMenu.Text = 'Flip Tiles';
            app.FlattenIlluminationMenu = uimenu(app.ParametersMenu);
            app.FlattenIlluminationMenu.MenuSelectedFcn = createCallbackFcn(app, @FlattenIlluminationMenuSelected, true);
            app.FlattenIlluminationMenu.Text = 'Flatten Illumination';
            app.DirectionsTextAreaLabel = uilabel(app.MosaicViewerAppUIFigure);
            app.DirectionsTextAreaLabel.Text = 'Directions';
            app.DirectionsTextArea = uitextarea(app.MosaicViewerAppUIFigure);
            app.SelectTileButton = uibutton(app.MosaicViewerAppUIFigure, 'push');
            app.SelectTileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectTileButtonPushed, true);
            app.SelectTileButton.Text = 'Select Tile';
            app.ShowBoxesButton = uibutton(app.MosaicViewerAppUIFigure, 'push');
            app.ShowBoxesButton.ButtonPushedFcn = createCallbackFcn(app, @ShowBoxesButtonPushed, true);
            app.ShowBoxesButton.Text = 'Show Boxes';
            app.ZoomInHereButton = uibutton(app.MosaicViewerAppUIFigure, 'push');
            app.ZoomInHereButton.ButtonPushedFcn = createCallbackFcn(app, @ZoomInHereButtonPushed, true);
            app.ZoomInHereButton.Text = 'Zoom In Here';
            app.ZoomOutButton = uibutton(app.MosaicViewerAppUIFigure, 'push');
            app.ZoomOutButton.ButtonPushedFcn = createCallbackFcn(app, @ZoomOutButtonPushed, true);
            app.ZoomOutButton.Text = 'Zoom Out';
            app.DoneButton = uibutton(app.MosaicViewerAppUIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            % Show the figure after all components are created
            app.MosaicViewerAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MosaicViewerApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MosaicViewerAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MosaicViewerAppUIFigure)
        end
    end
end