classdef NcolorApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NcolorAppUIFigure            matlab.ui.Figure
        EditColormapButton           matlab.ui.control.Button
        ZoomOutButton                matlab.ui.control.Button
        ZoominHereButton             matlab.ui.control.Button
        EditChannelListBox           matlab.ui.control.ListBox
        EditChannelListBoxLabel      matlab.ui.control.Label
        MaxValue                     matlab.ui.control.NumericEditField
        MinValue                     matlab.ui.control.NumericEditField
        DisplayChannelsListBox       matlab.ui.control.ListBox
        DisplayChannelsListBoxLabel  matlab.ui.control.Label
        MaxSlider                    matlab.ui.control.Slider
        MaxSliderLabel               matlab.ui.control.Label
        MinSlider                    matlab.ui.control.Slider
        MinSliderLabel               matlab.ui.control.Label
    end

    
    properties (Access = public)
        im_orig % original input image
        im_s % image downsampled 1x 
        im_ss % image downsampled 2x
        im_selected % just the selected channels
        im_scaled % downsampled input image
        im_contrast % contrasted (and downsampled)
        im_RGB % RGB display image (also contrasted and downsampled)
        inputPars % parameters 
        scale = 0.2
        maxSize = 3e3
        selectedChannels = 1
        tuneChannels = 1
        contrastMin = 0
        contrastMax = .999
        zoomState = false; % set true when zoom in
        currScale = 1 % not used / depreciated? 
        viewBox
        pixelBox
        maxBox
    end
    
    methods (Access = private)
        % Image plotting methods
                
    
        function app = UpdateContrast(app)
            % updates im_contrast based on all the values in app.contrastMin/Max
            nChns = size(app.im_scaled,3);
            app.im_contrast = app.im_scaled; % have to resize app.im_contrast;
            for c=1:nChns
                app.im_contrast(:,:,c) = IncreaseContrast(app.im_scaled(:,:,c),...
                    'low',app.contrastMin(c),'high',app.contrastMax(c));
            end
        end
    
        function app = UpdateSelectContrast(app)
            % updates contrast of only the selected channel
            % (generally just use this)
            isSelected = GetSelectedEdit(app);
            n  = find(isSelected);
            app.contrastMax(n) = app.MaxValue.Value;
            app.contrastMin(n) = app.MinValue.Value;
            app.im_contrast(:,:,n) = IncreaseContrast(app.im_scaled(:,:,n),...
                    'low',app.contrastMin(n),'high',app.contrastMax(n)); % possibly should change with app.scale
            % contrast was applied to im_scaled, so we have to rechain through selected
            app = UpdateSelected(app);
        end
        
        function app = UpdateSelected(app)
            % Update selected channels
            % note, to keep colors constant, we set the non-displays to 0
            % this does not change the size of the matrix
            isSelected = GetSelectedDisplay(app);
            imOut = app.im_contrast;
            imOut(:,:,~isSelected) = 0;  
            imOut = uint16(imOut); % non-uint16 get 'makeuint' rescaled
            app.im_RGB = Ncolor(imOut,'colormap',app.inputPars.colormap); 
        end
    
    
        function app = UpdateDisplay(app)
            isSelected = GetSelectedDisplay(app);
            cmap = app.inputPars.colormap;
            cmap(~isSelected,:) = 0;
            cbar.colormap = cmap;
            cbar.nChns = size(app.im_orig,3);
            cbar.chnNames = app.inputPars.names;
            NcolorViewerFigure('figHandle',app.inputPars.figHandle,...
                             'image',app.im_RGB,...
                             'display','imagesc',...
                             'data',cbar);
        end
    
    end
    
    methods (Access = public)
        % Helper functions
        function app = UpdateMinMax(app,isSelected)
            app.MaxValue.Value = app.contrastMax(isSelected);
            app.MinValue.Value = app.contrastMin(isSelected);
            app.MaxSlider.Value = app.contrastMax(isSelected);
            app.MinSlider.Value = app.contrastMin(isSelected);
        end
    
        function app = UpdateSelections(app)
            app.DisplayChannelsListBox.Value = app.DisplayChannelsListBox.Items(app.selectedChannels);
            app.EditChannelListBox.Value = app.EditChannelListBox.Items(app.tuneChannels);
            app.scaleValue.Value = app.currScale;
            app.scaleSlider.Value = app.currScale;
        end
    
        function isSelected = GetSelectedDisplay(app)
            nChns = size(app.im_orig,3);
            [~,selected] = intersect(app.DisplayChannelsListBox.Items,app.DisplayChannelsListBox.Value);
            isSelected = false(nChns,1);
            isSelected(selected) = true;
            app.selectedChannels = isSelected; 
            % if single selection, update Edit Channel to match
            if length(selected)==1
               app.EditChannelListBox.Value = app.DisplayChannelsListBox.Items(selected); 
            end
        end
    
        function isSelected = GetSelectedEdit(app)
            nChns = size(app.im_orig,3);
            [~,selected] = intersect(app.EditChannelListBox.Items,app.EditChannelListBox.Value);
            isSelected = false(nChns,1);
            isSelected(selected) = true;
            app.tuneChannels = isSelected;
        end
   
        function app = GetResolution(app)
            imageSize = sqrt(diff(app.viewBox.x)*diff(app.viewBox.y));
            if imageSize < app.inputPars.maxSize
                % if small enough, don't need to use any downsampling
               app.currScale = 0;
               app.pixelBox.x = ceil(app.viewBox.x);
               app.pixelBox.y = ceil(app.viewBox.y);
               app.im_scaled = app.im_orig(app.pixelBox.y(1):app.pixelBox.y(2),app.pixelBox.x(1):app.pixelBox.x(2),:);
            elseif imageSize > app.inputPars.maxSize && app.scale*imageSize < app.inputPars.maxSize
                % if 1x downsampling makes it under max size
                app.currScale = 1;
                app.pixelBox.x = ceil(app.viewBox.x*app.scale);
                app.pixelBox.y = ceil(app.viewBox.y*app.scale);
                [H,W,~] = size(app.im_s);
                app.pixelBox.y(2) = min(H,app.pixelBox.y(2));
                app.pixelBox.x(2) = min(W,app.pixelBox.x(2));
                app.im_scaled = app.im_s(app.pixelBox.y(1):app.pixelBox.y(2),app.pixelBox.x(1):app.pixelBox.x(2),:);
            else 
                % if 1x downsample not enough, use 2x downsample
                app.currScale = 2;
                app.pixelBox.x = ceil(app.viewBox.x*app.scale*app.scale);
                app.pixelBox.y = ceil(app.viewBox.y*app.scale*app.scale);
                % keep in bounds if ceil takes past edge. 
                [H,W,~] = size(app.im_ss);
                app.pixelBox.y(2) = min(H,app.pixelBox.y(2));
                app.pixelBox.x(2) = min(W,app.pixelBox.x(2));
                app.im_scaled = app.im_ss(app.pixelBox.y(1):app.pixelBox.y(2),app.pixelBox.x(1):app.pixelBox.x(2),:);
            end 
           %  size(app.im_scaled)
            % figure(1); clf; imagesc(IncreaseContrast(app.im_scaled(:,:,1),'high',.999));
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, im1, varargin)
            % load defaults into current values
            app.im_orig = im1; % varargin{1};
            defaults = cell(0,3);
            defaults(end+1,:) = {'names','cell',{}};
            defaults(end+1,:) = {'colormap','colormap','whsv'};  % No option for type 'table'
            defaults(end+1,:) = {'figHandle','freeType',100}; %  
            defaults(end+1,:) = {'contrastMax','freeType',.999};
            defaults(end+1,:) = {'contrastMin','freeType',.5};
            defaults(end+1,:) = {'restart','boolean',true};
            defaults(end+1,:) = {'scale','fraction',.3}; % amount to shrink by if over maxSize
            defaults(end+1,:) = {'maxSize','integer',2.2e3};  % maxSize of image 
            defaults(end+1,:) = {'appHandle','freeType',[]};
            defaults(end+1,:) = {'title','string','Ncolor'};
            
            pars = ParseVariableArguments(varargin,defaults,mfilename);      
            app.inputPars = pars;
            
            [yMax,xMax,nChns] = size(app.im_orig);
            
            app.inputPars.colormap = GetColorMap(app.inputPars.colormap,nChns);
            % create downsampled versions of the image
            sc = pars.scale;% 0.2;
            app.scale = sc;
            app.im_s = imresize(app.im_orig,sc);
            app.im_ss = imresize(app.im_s,sc);
            
            % current size
            app.viewBox.x = [1,xMax];
            app.viewBox.y = [1,yMax];
            app.maxBox.x = [1,xMax];
            app.maxBox.y = [1,yMax];
            app.pixelBox.x = app.viewBox.x;
            app.pixelBox.y = app.viewBox.y;
            app = GetResolution(app); % get resolution
            
            % only 1 instance with each title is allowed
            currFigs = findall(0,'name',pars.title);
            %app.NcolorApp.UIFigure.Name = pars.title;
            
            % names
            if isempty(pars.names)
                pars.names = cellstr(num2str( (1:nChns)'));
            end
            app.DisplayChannelsListBox.Items = pars.names;
            app.EditChannelListBox.Items = pars.names;   
            
            app.contrastMax = pars.contrastMax*ones(1,nChns);
            app.contrastMin = pars.contrastMin*ones(1,nChns);
                              
            % Now we plot the data       (approximate behavior & updated variables)
            app = UpdateContrast(app); % (updates app.im_contrast = app.im_scaled; )
            app = UpdateSelected(app); % (app.im_RGB = Ncolor(app.im_contrast); )
            UpdateDisplay(app); %        (imagesc(app.im_RGB); )
   
            % after we've copied properties out of earlier versions, clean up earlier windows.
            if ~isempty(currFigs)
                delete(currFigs);
            end
        end

        % Value changed function: MinSlider
        function MinSliderValueChanged(app, event)
            value = app.MinSlider.Value;
            app.MinValue.Value = value;
            app = UpdateSelectContrast(app);
            UpdateDisplay(app);
        end

        % Value changed function: MinValue
        function MinValueValueChanged(app, event)
            value = app.MinValue.Value;
            app.MinSlider.Value = value;
            app = UpdateSelectContrast(app);
            UpdateDisplay(app);
        end

        % Value changed function: MaxSlider
        function MaxSliderValueChanged(app, event)
            value = app.MaxSlider.Value;
            app.MaxValue.Value = value;
            app = UpdateSelectContrast(app);
            UpdateDisplay(app);
        end

        % Value changed function: MaxValue
        function MaxValueValueChanged(app, event)
            value = app.MaxValue.Value;
            app.MaxSlider.Value = value;
            app = UpdateSelectContrast(app);
            UpdateDisplay(app);
        end

        % Value changed function: DisplayChannelsListBox
        function DisplayChannelsListBoxValueChanged(app, event)
            % value = app.DisplayChannelsListBox.Value;
            app = UpdateSelected(app);
            UpdateDisplay(app);
        end

        % Value changed function: EditChannelListBox
        function EditChannelListBoxValueChanged(app, event)
            % value = app.EditChannelListBox.Value;
            isSelected = GetSelectedEdit(app);
            UpdateMinMax(app,isSelected);
        end

        % Callback function
        function scaleSliderValueChanged(app, event)
            % DEPRECIATED
        end

        % Callback function
        function scaleValueValueChanged(app, event)
             % DEPRECIATED
        end

        % Button pushed function: ZoominHereButton
        function ZoominHereButtonPushed(app, event)
            if ~app.zoomState
            %-- Get the zoom box
                % just call the figure axes to the foreground
           NcolorViewerFigure('figHandle',app.inputPars.figHandle,...
                             'display','none');
            xl = get(gca,'xlim');
            yl = get(gca,'ylim');
             
            app.viewBox.x = xl/(app.scale)^app.currScale;
            app.viewBox.y = yl/(app.scale)^app.currScale;
            app = GetResolution(app); % get resolution
            
%             %--- Determine the image resolution at this zoom
%             sc = app.scale;
%             dx = diff(xl);
%             dy = diff(yl);
%             scale0 = app.currScale == 0;
%             scale1 = app.currScale == 1; % currently showing 1 level of zoom
%             scale2 = app.currScale == 2;
%             d0 = max(dx,dy) < app.maxSize;
%             d1 = max(dx,dy)/sc < app.maxSize; % 1x downsample of current image fits on screen
%             d2 = max(dx,dy)/sc/sc < app.maxSize; % 2x downsample fits on screen
% 
%             if scale2 && d2
%                 yl = round(yl/sc/sc);
%                 xl = round(xl/sc/sc);
%                 ys = yl(1):yl(2);
%                 xs = xl(1):xl(2);
%                 app.im_scaled = app.im_orig(ys,xs,:); % use full resolution
%                 app.scaleUL = [xs(1),ys(1)];
%                 app.currScale = 0;
%             elseif scale2 && d1 && ~d2
%                 yl = round(yl/sc);
%                 xl = round(xl/sc);
%                 ys = yl(1):yl(2);
%                 xs = xl(1):xl(2);
%                 app.im_scaled = app.im_s(ys,xs,:); % 
%                 app.scaleUL = [xs(1),ys(1)];
%                 app.currScale = 1;
%             elseif scale1
%                 yl = round(yl/sc);
%                 xl = round(xl/sc);
%                 ys = yl(1):yl(2);
%                 xs = xl(1):xl(2);
%                 app.im_scaled = app.im_orig(ys,xs,:); %    
%                 app.scaleUL = [xs(1),ys(1)];
%                 app.currScale = 0;
%             elseif scale0
%                 % image already fits in screen
%                 app.zoomState =~app.zoomState; % don't need to change anything
%                 warning('image is already displayed at full resolution');
%             end
            app.zoomState = ~app.zoomState;
            app.im_contrast = app.im_scaled;
            app = UpdateContrast(app); % reveresed order with UpdateSelected
            app = UpdateSelected(app); % (app.im_RGB = Ncolor(app.im_contrast); )
            UpdateDisplay(app); %    
            else
                disp('already zoomed in, must zoom out first')
            end
            
        end

        % Button pushed function: ZoomOutButton
        function ZoomOutButtonPushed(app, event)
            app.zoomState = false;
            app.viewBox = app.maxBox;
            app = GetResolution(app); % get resolution
            app = UpdateContrast(app); % (updates app.im_contrast = app.im_scaled; )
            app = UpdateSelected(app); % (app.im_RGB = Ncolor(app.im_contrast); )
            UpdateDisplay(app); %        (imagesc(app.im_RGB); )
        end

        % Button pushed function: EditColormapButton
        function EditColormapButtonPushed(app, event)
            % Edit the selected channel
  
            % selChn = inputdlg({'channel: '},'change color',[1 40],{''});
            % selIdx = ismember(cNames,selChn);
            selIdx  = GetSelectedEdit(app);
            rgb_new = uisetcolor();
            app.inputPars.colormap(selIdx,:) = rgb_new; 
            app = UpdateSelectContrast(app);
            app = UpdateDisplay(app);
%             % color map edit
%             cNames = app.inputPars.names;
%             cmap = app.inputPars.colormap; % current colormap
%             nRows = size(cmap,1);
%             maxCols = 50;
%             defaultVals = repmat(' ',nRows,maxCols);
%             disp('Update RGB/color values - numbers to 1 decimal accuracy')
%             for n=1:nRows
%                 defaultVals(n,1:3) = num2str(cmap(n,1),'%.1f');
%                 defaultVals(n,7:9) = num2str(cmap(n,2),'%.1f');
%                 defaultVals(n,13:15) = num2str(cmap(n,3),'%.1f');
%                 nameLength = min(maxCols,length(cNames{n}));
%                 defaultVals(n,20:nameLength+20-1) = cNames{n}(1:nameLength);
%             end
%             choices = inputdlg({'values'},'edit RGB values',[nRows,maxCols],{defaultVals});
%             if ~isempty(choices)
%                 newMap = str2num(choices{1}(:,1:19));
%                 app.inputPars.colormap = newMap; %#ok<ST2NM>
%                 UpdateDisplay(app);
%             end   
%            %   This is simplier but makes less good use of screen space, problem for long lists            
%             defaultVals = cell(nRows,1);
%             for n=1:nRows
%                 defaultVals{n} = num2str(cmap(n,:));
%             end
%             choices = inputdlg(cNames,'select color values',repmat([1,30],nRows,1),defaultVals)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NcolorAppUIFigure = uifigure('Visible', 'off');
            app.NcolorAppUIFigure.Name = 'Ncolor App';
            app.MinSliderLabel = uilabel(app.NcolorAppUIFigure);
            app.MinSliderLabel.Text = 'Min';
            app.MinSlider = uislider(app.NcolorAppUIFigure);
            app.MinSlider.Limits = [0 1];
            app.MinSlider.ValueChangedFcn = createCallbackFcn(app, @MinSliderValueChanged, true);
            app.MaxSliderLabel = uilabel(app.NcolorAppUIFigure);
            app.MaxSliderLabel.Text = 'Max';
            app.MaxSlider = uislider(app.NcolorAppUIFigure);
            app.MaxSlider.Limits = [0.99 1];
            app.MaxSlider.ValueChangedFcn = createCallbackFcn(app, @MaxSliderValueChanged, true);
            app.MaxSlider.Value = 1;
            app.DisplayChannelsListBoxLabel = uilabel(app.NcolorAppUIFigure);
            app.DisplayChannelsListBoxLabel.Text = 'Display Channels';
            app.DisplayChannelsListBox = uilistbox(app.NcolorAppUIFigure);
            app.DisplayChannelsListBox.Multiselect = 'on';
            app.DisplayChannelsListBox.ValueChangedFcn = createCallbackFcn(app, @DisplayChannelsListBoxValueChanged, true);
            app.DisplayChannelsListBox.Value = {'Item 1'};
            app.MinValue = uieditfield(app.NcolorAppUIFigure, 'numeric');
            app.MinValue.Limits = [0 1];
            app.MinValue.ValueDisplayFormat = '%.6f';
            app.MinValue.ValueChangedFcn = createCallbackFcn(app, @MinValueValueChanged, true);
            app.MaxValue = uieditfield(app.NcolorAppUIFigure, 'numeric');
            app.MaxValue.Limits = [0.9 1];
            app.MaxValue.ValueDisplayFormat = '%.6f';
            app.MaxValue.ValueChangedFcn = createCallbackFcn(app, @MaxValueValueChanged, true);
            app.MaxValue.Value = 0.999;
            app.EditChannelListBoxLabel = uilabel(app.NcolorAppUIFigure);
            app.EditChannelListBoxLabel.Text = 'Edit Channel';
            app.EditChannelListBox = uilistbox(app.NcolorAppUIFigure);
            app.EditChannelListBox.ValueChangedFcn = createCallbackFcn(app, @EditChannelListBoxValueChanged, true);
            app.ZoominHereButton = uibutton(app.NcolorAppUIFigure, 'push');
            app.ZoominHereButton.ButtonPushedFcn = createCallbackFcn(app, @ZoominHereButtonPushed, true);
            app.ZoominHereButton.Text = 'Zoom in Here';
            app.ZoomOutButton = uibutton(app.NcolorAppUIFigure, 'push');
            app.ZoomOutButton.ButtonPushedFcn = createCallbackFcn(app, @ZoomOutButtonPushed, true);
            app.ZoomOutButton.Text = 'Zoom Out';
            app.EditColormapButton = uibutton(app.NcolorAppUIFigure, 'push');
            app.EditColormapButton.ButtonPushedFcn = createCallbackFcn(app, @EditColormapButtonPushed, true);
            app.EditColormapButton.Text = 'Edit Colormap';
            % Show the figure after all components are created
            app.NcolorAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NcolorApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NcolorAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NcolorAppUIFigure)
        end
    end
end