classdef SpacewalkApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SpacewalkAppUIFigure       matlab.ui.Figure
        FileMenu                   matlab.ui.container.Menu
        LoadspottableMenu          matlab.ui.container.Menu
        Load5DdataMenu             matlab.ui.container.Menu
        Load5DfiducialMenu         matlab.ui.container.Menu
        ParametersMenu             matlab.ui.container.Menu
        SpotFittingMenu            matlab.ui.container.Menu
        MapdisplayMenu             matlab.ui.container.Menu
        FOVdisplayMenu             matlab.ui.container.Menu
        FolderEditFieldLabel       matlab.ui.control.Label
        FolderEditField            matlab.ui.control.EditField
        UIAxes                     matlab.ui.control.UIAxes
        UIAxes_2                   matlab.ui.control.UIAxes
        ParametersButton           matlab.ui.control.Button
        ReanalyzeButton            matlab.ui.control.Button
        SpotSpinnerLabel           matlab.ui.control.Label
        SpotSpinner                matlab.ui.control.Spinner
        FOVSpinnerLabel            matlab.ui.control.Label
        FOVSpinner                 matlab.ui.control.Spinner
        ShowRawDataButton          matlab.ui.control.StateButton
        ShowDistMapButton          matlab.ui.control.StateButton
        ShowPolymerButton          matlab.ui.control.StateButton
        SelectspotsEditFieldLabel  matlab.ui.control.Label
        SelectspotsEditField       matlab.ui.control.EditField
        MedianMapButton            matlab.ui.control.Button
        ContactMapButton           matlab.ui.control.Button
        ShowFidDataButton          matlab.ui.control.StateButton
        ShowFidProjButton          matlab.ui.control.StateButton
    end

    
    properties (Access = public)
        pars % structure containing app parameters
        data % structure containing app data
    end
    
    methods (Access = private)
        function app = LoadFOV(app)
            f = app.pars.fov;
            % load the data.
            tic
            app.data.datSpots = ReadImage5D([app.pars.folder,'fov',num2str(f,'%03d'),'_DataSpots.i5d']);
            app.data.fidSpots = ReadImage5D([app.pars.folder,'fov',num2str(f,'%03d'),'_FiduSpots.i5d']);
            app.data.datTable = readtable([app.pars.folder,'fov',num2str(f,'%03d'),'_AllFits.csv']);  
            toc
            % initialize some variables for this FOV
            SelectspotsEditFieldValueChanged(app,[]); % necessary initialization
            % image of FOV
            % (need a better way to find this folder)
            hybFolders = ls([app.pars.folder,'..\Hyb_*']);
            if ~isempty(hybFolders)
                hybFolders=cellstr(hybFolders);
                try
                    hybFolder = [app.pars.folder,'..',filesep,hybFolders{1},filesep];
                    daxName = [hybFolder,'fidMax_ConvZscan_',num2str(f-1,'%02d'),'.dax'];
                    app.data.fidIm = ReadDax(daxName);
                catch ME
                    error(ME.getReport)
                end
            end
            % show in built in figure 
            %    (popup resizeable figure might be better)     
            datTable = app.data.datTable; % short hand popout;
            [~,uid] = unique(datTable.s,'stable');
            spots = [datTable.locusX(uid)/app.pars.nmXYpix,datTable.locusY(uid)/app.pars.nmXYpix,datTable.s(uid)];
            % cla(app.UIAxes,'reset');
            
            imagesc(app.UIAxes,IncreaseContrast(app.data.fidIm,'low',.5,'high',.999)); 
            colormap(app.UIAxes,hot);
            hold(app.UIAxes, 'on'); % no sture if this works
            plot(app.UIAxes,spots(:,1),spots(:,2),'yo','MarkerSize',12);
            text(app.UIAxes,spots(:,1)+10,spots(:,2),cellstr(num2str(spots(:,3))),'color','w');  
            % figure based version
            figure(app.pars.figHandle+10);
            imagesc(IncreaseContrast(app.data.fidIm,'low',.5,'high',.999)); 
            colormap(hot);
            hold('on'); % no sture if this works
            plot(spots(:,1),spots(:,2),'yo','MarkerSize',12);
            text(spots(:,1)+10,spots(:,2),cellstr(num2str(spots(:,3))),'color','w');  
            
        end
        
        function app = LoadSpot(app)
            s = app.pars.s;
            if isempty(app.data.datTable)
                error('no data table')
            end
            % show spot zoom in
            datTable = app.data.datTable; [~,uid] = unique(datTable.s,'stable');
            spots = [datTable.locusX(uid)/app.pars.nmXYpix,datTable.locusY(uid)/app.pars.nmXYpix,datTable.s(uid)];
            cla(app.UIAxes,'reset');
            imagesc(app.UIAxes,IncreaseContrast(app.data.fidIm,'low',.5,'high',.999)); 
            set(app.UIAxes,'xlim',[spots(s,1)-100,spots(s,1)+100],...
                           'ylim',[spots(s,2)-100,spots(s,2)+100]);      
            text(app.UIAxes,spots(:,1)+10,spots(:,2),cellstr(num2str(spots(:,3))),'color','w');    
            
            % show polymer
            chidTable = app.data.datTable(app.data.datTable.s==s,:);
            nH = size(app.data.datSpots,4);
            poly = nan(nH,3);
            poly(chidTable.hybe,:) = chidTable{:,1:3};
            if app.ShowPolymerButton.Value
               Spacewalk_PlotPolymerTube(poly,'figHandle',app.pars.figHandle+5);
            end
            % show dist map
            if app.ShowDistMapButton.Value
                distMap_s = squareform(pdist(poly));
                imagesc(app.UIAxes_2,distMap_s);
                caxis(app.UIAxes_2,app.pars.mapDisplay.distRange);
                colorbar(app.UIAxes_2);
            end
            if app.ShowFidProjButton.Value
                shifts = zeros(nH,3);
                fidS =app.data.fidSpots(:,:,:,:,s); 
                noData = 1:nH; noData(chidTable.hybe) = [];
                shifts(chidTable.hybe,:) = [chidTable.xshift/app.pars.fitPars.nmXYpix,chidTable.yshift/app.pars.fitPars.nmXYpix,chidTable.zshift/app.pars.fitPars.nmZpix];
                xyFid = squeeze( max(fidS,[],3)  );
                xzFid = squeeze( max(permute(fidS,[3,2,1,4]),[],3) );
                for h=1:nH
                    xyFid(:,:,h) = TranslateImage(xyFid(:,:,h),shifts(h,1),shifts(h,2),'upsample',4);
                    xzFid(:,:,h) = TranslateImage(xzFid(:,:,h),shifts(h,1),shifts(h,3),'upsample',4);
                end
                xyFid(:,:,noData) = [];
                xzFid(:,:,noData) = [];
                cla(app.UIAxes,'reset'); imagesc(app.UIAxes, IncreaseContrast(Ncolor(xyFid),'high',.999));
                cla(app.UIAxes_2,'reset'); imagesc(app.UIAxes_2, IncreaseContrast(Ncolor(xzFid),'high',.999));
            end
            
            % show raw data
            if app.ShowRawDataButton.Value
                % if removeFineDrift since raw data images are not corrected for fine drift 
                chidTable.x = chidTable.x - chidTable.xshift;
                chidTable.y = chidTable.y - chidTable.yshift;
                chidTable.z = chidTable.z - chidTable.zshift;
                % xy and xz data projection side by side
                Spacewalk_PlotProjection4D(app.data.datSpots(:,:,:,:,s),...
                    'fits',chidTable,...
                    'parameters',app.pars,...
                    'figHandle',app.pars.figHandle,...
                    'tileLabels',cellstr(num2str((1:nH)')) );
            end
            if app.ShowRawDataButton.Value
                % xy and xz data projection side by side
                Spacewalk_PlotProjection4D(app.data.fidSpots(:,:,:,:,s),...
                    'parameters',app.pars,...
                    'figHandle',app.pars.figHandle+1,...
                    'tileLabels',cellstr(num2str((1:nH)')) );
            end
        end
        
        function ComputeDistMap(app)
             distM = TableToDistMap(app.data.datTable,'removeEmpty',false);  
             distM  = distM(:,:,1:2:end);
            distM = distM(:,:,app.pars.selectSpots); % this is slow, should speed up by selecting spots in table first.
            parms = app.pars.mapDisplay;
            if ~isinf(parms.maxJump)
                badPts = RemoveBadPts(distM,'maxJump',parms.maxJump);
                distM(badPts) = NaN;
            end
            distMap = nanmedian(distM,3);
            contMap = ContactFrac(distM,'threshold',parms.contactThreshold);
            if ~isempty(parms.badHybes)
                distMap = InterpMapNans(distMap,'badHybes',parms.badHybes);
                contMap = InterpMapNans(distMap,'badHybes',parms.badHybes);
            end
            app.data.distMap = distMap;
            app.data.contMap = contMap;
            app.pars.mapDisplay.newSpots = false; % for speed, don't recompute if spots haven't changed
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            defaults = cell(0,3);
            defaults(end+1,:) = {'nmXYpix','positive',108};
            defaults(end+1,:) = {'nmZpix','positive',100};
            defaults(end+1,:) = {'folder','string','T:\Tonia_2020-02-04_mES_multiplexSox2_3MB\Analysis3_AB\'}; % make empty in later edition
            defaults(end+1,:) = {'fov','integer',1};
            defaults(end+1,:) = {'figHandle','integer',100};
            app.pars = ParseVariableArguments(varargin,defaults,mfilename);
            app.FolderEditField.Value = app.pars.folder;
            % =======fitting parameters   =============      
                defaults = cell(0,3);         
                % Fiducial alignment defaults
                defaults(end+1,:) = {'refHybe','integer',1}; % hybe to use to start alignment
                defaults(end+1,:) = {'upsample','positive',8};  % 8 for accuracy 2 for speed
                defaults(end+1,:) = {'maxXYdrift','positive',4};
                defaults(end+1,:) = {'maxZdrift','positive',6};
                % Fiducial fitting defaults
                defaults(end+1,:) = {'fidMinPeakHeight', 'positive', 200};
                defaults(end+1,:) = {'fidMaxFitWidth', 'positive', 8}; % 6
                defaults(end+1,:) = {'fidMaxFitZdepth', 'positive', 12}; % 6
                  % Data fitting defaults
                defaults(end+1,:) = {'maxXYstep','positive',5}; % box radius in pixels
                defaults(end+1,:) = {'maxZstep','positive',7}; % box radius in pixels
                defaults(end+1,:) = {'datMinPeakHeight', 'positive',0};
                defaults(end+1,:) = {'datMaxFitWidth', 'positive', 8};
                defaults(end+1,:) = {'datMaxFitZdepth', 'positive', 12};
                defaults(end+1,:) = {'datMinHBratio','nonnegative',1.2}; % peak value over background value
                defaults(end+1,:) = {'datMinAHratio','nonnegative',.25}; % fitted height over background vs peak value
                defaults(end+1,:) = {'datMaxUncert','nonnegative',2}; % pixels
                defaults(end+1,:) = {'bkdFrac','fraction',.1}; % 0 for off. dimmest bkdFrac of images will be used to compute local illumination background 
                app.pars.fitPars = ParseVariableArguments(varargin,defaults,mfilename); 
                app.pars.fitPars.nmXYpix = app.pars.nmXYpix;
                app.pars.fitPars.nmZpix = app.pars.nmZpix;
                %======== map display parameters
                defaults = cell(0,3); 
                defaults(end+1,:) = {'contactThreshold','nonnegative',150};
                defaults(end+1,:) = {'maxJump','nonnegative',inf};
                defaults(end+1,:) = {'contactColorMap','colormap',GetColorMap('parula')};
                defaults(end+1,:) = {'distColorMap','colormap',GetColorMap('parula')};
                defaults(end+1,:) = {'badHybes','freeType',[]};
                defaults(end+1,:) = {'distRange','array',[0,750]};
                defaults(end+1,:) = {'contactRange','array',[0,.4]};
                app.pars.mapDisplay = ParseVariableArguments(varargin,defaults,mfilename); 
                
        end

        % Callback function
        function fovEditFieldValueChanged(app, event)
           f = str2double(app.fovEditField.Value);   
            app.pars.fov=f; % store
            LoadFOV(app);
        end

        % Value changed function: FolderEditField
        function FolderEditFieldValueChanged(app, event)
            app.pars.folder = app.FolderEditField.Value; 
        end

        % Button pushed function: ParametersButton
        function ParametersButtonPushed(app, event)
            app.pars.fitPars = SimpleParameterGUI(app.pars.fitPars);
        end

        % Button pushed function: ReanalyzeButton
        function ReanalyzeButtonPushed(app, event)
            numSpots = size(app.data.datSpots,5);
            dataTableS = cell(numSpots,1);
            fSpots = app.data.fidSpots;
            dSpots = app.data.datSpots;
            parameters = app.pars.fitPars;
            parfor s=1:numSpots
            dataTableS{s} = ReFitData(fSpots(:,:,:,:,s),dSpots(:,:,:,:,s),...
                'parameters',parameters,...
                'saveData',true);
            end
            dataTable = cat(1,dataTableS{:});
            if ~isempty(app.pars.folder)
                writetable(dataTable,[app.pars.folder,'newFOV',num2str(app.pars.fov,'%03d'),'_AllFits.csv']);
            end
        end

        % Menu selected function: LoadspottableMenu
        function LoadspottableMenuSelected(app, event)
           
            [fileIn,folderIn] = uigetfile([app.pars.folder,'*_AllFits.csv']);
            app.data.datTable = readtable([folderIn,fileIn]); % readtable([app.pars.folder,'fov',num2str(f,'%03d'),'_AllFits.csv']);  
        end

        % Menu selected function: Load5DdataMenu
        function Load5DdataMenuSelected(app, event)
            [fileIn,folderIn] = uigetfile([app.pars.folder,'*_DataSpots.i5d']);
            app.data.datSpots = ReadImage5D([folderIn,fileIn]); % [app.pars.folder,'fov',num2str(f,'%03d'),'_DataSpots.i5d']);
        end

        % Menu selected function: Load5DfiducialMenu
        function Load5DfiducialMenuSelected(app, event)
            [fileIn,folderIn] = uigetfile([app.pars.folder,'*_FiduSpots.i5d']);
            app.data.fidSpots = ReadImage5D([folderIn,fileIn]); % app.pars.folder,'fov',num2str(f,'%03d'),'_FiduSpots.i5d']);
        end

        % Value changed function: SpotSpinner
        function SpotSpinnerValueChanged(app, event)
            app.pars.s = app.SpotSpinner.Value;
            if app.pars.s == 0
                app.pars.s = 1;
            end
            LoadSpot(app);   
        end

        % Value changed function: FOVSpinner
        function FOVSpinnerValueChanged(app, event)
            app.pars.fov=app.FOVSpinner.Value; % store
            if app.pars.fov == 0
                app.pars.fov = 1;
            end
            set(app.FOVSpinner,'enable','off'); % prevent multiple clicks
            pause(.05);
            LoadFOV(app);  
            set(app.FOVSpinner,'enable','on');
        end

        % Value changed function: ShowRawDataButton
        function ShowRawDataButtonValueChanged(app, event)
             disp('debug here');
            
        end

        % Value changed function: SelectspotsEditField
        function SelectspotsEditFieldValueChanged(app, event)
            value = app.SelectspotsEditField.Value;
            nS = size(app.data.datSpots,5);
            if strcmp(value,'all') || strcmp(value,'inf')
                app.pars.selectSpots = 1:nS;
            else
                value = regexprep(value,'end',num2str(nS)); % understands 'end'
                app.pars.selectSpots = str2num(value); %#ok<ST2NM>  accepts ranges e.g. 4:15 and lists [1:4,6:end]
            end
             app.pars.mapDisplay.newSpots = true;
            
        end

        % Button pushed function: MedianMapButton
        function MedianMapButtonPushed(app, event)
            if app.pars.mapDisplay.newSpots
                ComputeDistMap(app);
            end
            cla(app.UIAxes,'reset');
            imagesc(app.UIAxes,app.data.distMap);
            caxis(app.UIAxes,app.pars.mapDisplay.distRange);
            colormap(app.UIAxes,app.pars.mapDisplay.distColorMap);
            colorbar(app.UIAxes);
        end

        % Button pushed function: ContactMapButton
        function ContactMapButtonPushed(app, event)
            if app.pars.mapDisplay.newSpots
                ComputeDistMap(app);
            end
            cla(app.UIAxes,'reset');
            imagesc(app.UIAxes,app.data.contMap);
            caxis(app.UIAxes,app.pars.mapDisplay.contactRange);
            colormap(app.UIAxes,app.pars.mapDisplay.contactColorMap);
            colorbar(app.UIAxes);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SpacewalkAppUIFigure = uifigure('Visible', 'off');
            app.SpacewalkAppUIFigure.Name = 'Spacewalk App';
            app.FileMenu = uimenu(app.SpacewalkAppUIFigure);
            app.FileMenu.Text = 'File';
            app.LoadspottableMenu = uimenu(app.FileMenu);
            app.LoadspottableMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadspottableMenuSelected, true);
            app.LoadspottableMenu.Text = 'Load spot table';
            app.Load5DdataMenu = uimenu(app.FileMenu);
            app.Load5DdataMenu.MenuSelectedFcn = createCallbackFcn(app, @Load5DdataMenuSelected, true);
            app.Load5DdataMenu.Text = 'Load 5D data';
            app.Load5DfiducialMenu = uimenu(app.FileMenu);
            app.Load5DfiducialMenu.MenuSelectedFcn = createCallbackFcn(app, @Load5DfiducialMenuSelected, true);
            app.Load5DfiducialMenu.Text = 'Load 5D fiducial';
            app.ParametersMenu = uimenu(app.SpacewalkAppUIFigure);
            app.ParametersMenu.Text = 'Parameters';
            app.SpotFittingMenu = uimenu(app.ParametersMenu);
            app.SpotFittingMenu.Text = 'Spot Fitting';
            app.MapdisplayMenu = uimenu(app.ParametersMenu);
            app.MapdisplayMenu.Text = 'Map display';
            app.FOVdisplayMenu = uimenu(app.ParametersMenu);
            app.FOVdisplayMenu.Text = 'FOV display';
            app.FolderEditFieldLabel = uilabel(app.SpacewalkAppUIFigure);
            app.FolderEditFieldLabel.Text = 'Folder';
            app.FolderEditField = uieditfield(app.SpacewalkAppUIFigure, 'text');
            app.FolderEditField.ValueChangedFcn = createCallbackFcn(app, @FolderEditFieldValueChanged, true);
            app.UIAxes = uiaxes(app.SpacewalkAppUIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes_2 = uiaxes(app.SpacewalkAppUIFigure);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.ParametersButton = uibutton(app.SpacewalkAppUIFigure, 'push');
            app.ParametersButton.ButtonPushedFcn = createCallbackFcn(app, @ParametersButtonPushed, true);
            app.ParametersButton.Text = 'Parameters';
            app.ReanalyzeButton = uibutton(app.SpacewalkAppUIFigure, 'push');
            app.ReanalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @ReanalyzeButtonPushed, true);
            app.ReanalyzeButton.Text = 'Reanalyze';
            app.SpotSpinnerLabel = uilabel(app.SpacewalkAppUIFigure);
            app.SpotSpinnerLabel.Text = 'Spot';
            app.SpotSpinner = uispinner(app.SpacewalkAppUIFigure);
            app.SpotSpinner.ValueChangedFcn = createCallbackFcn(app, @SpotSpinnerValueChanged, true);
            app.FOVSpinnerLabel = uilabel(app.SpacewalkAppUIFigure);
            app.FOVSpinnerLabel.Text = 'FOV';
            app.FOVSpinner = uispinner(app.SpacewalkAppUIFigure);
            app.FOVSpinner.ValueChangedFcn = createCallbackFcn(app, @FOVSpinnerValueChanged, true);
            app.ShowRawDataButton = uibutton(app.SpacewalkAppUIFigure, 'state');
            app.ShowRawDataButton.ValueChangedFcn = createCallbackFcn(app, @ShowRawDataButtonValueChanged, true);
            app.ShowRawDataButton.Text = 'Show Raw Data';
            app.ShowDistMapButton = uibutton(app.SpacewalkAppUIFigure, 'state');
            app.ShowDistMapButton.Text = 'Show Dist. Map';
            app.ShowPolymerButton = uibutton(app.SpacewalkAppUIFigure, 'state');
            app.ShowPolymerButton.Text = 'Show Polymer';
            app.SelectspotsEditFieldLabel = uilabel(app.SpacewalkAppUIFigure);
            app.SelectspotsEditFieldLabel.Text = 'Select spots';
            app.SelectspotsEditField = uieditfield(app.SpacewalkAppUIFigure, 'text');
            app.SelectspotsEditField.ValueChangedFcn = createCallbackFcn(app, @SelectspotsEditFieldValueChanged, true);
            app.SelectspotsEditField.Value = 'all';
            app.MedianMapButton = uibutton(app.SpacewalkAppUIFigure, 'push');
            app.MedianMapButton.ButtonPushedFcn = createCallbackFcn(app, @MedianMapButtonPushed, true);
            app.MedianMapButton.Text = 'Median Map';
            app.ContactMapButton = uibutton(app.SpacewalkAppUIFigure, 'push');
            app.ContactMapButton.ButtonPushedFcn = createCallbackFcn(app, @ContactMapButtonPushed, true);
            app.ContactMapButton.Text = 'Contact Map';
            app.ShowFidDataButton = uibutton(app.SpacewalkAppUIFigure, 'state');
            app.ShowFidDataButton.Text = 'Show Fid. Data';
            app.ShowFidProjButton = uibutton(app.SpacewalkAppUIFigure, 'state');
            app.ShowFidProjButton.Text = 'Show Fid. Proj.';
            % Show the figure after all components are created
            app.SpacewalkAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SpacewalkApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SpacewalkAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SpacewalkAppUIFigure)
        end
    end
end