classdef ProcessTracesApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        ParametersButton  matlab.ui.control.Button
        TextArea          matlab.ui.control.TextArea
        BackStepButton    matlab.ui.control.Button
        NextStepButton    matlab.ui.control.Button
        RunStepButton     matlab.ui.control.Button
    end

    
    properties (Access = public)
        pars; % GUI parameters
        data; % GUI data
    end
    
    methods (Access = private)
        
        function SetupStep(app)
            % check if we have stepPars already or if we need to get them
            sn = ['step',num2str(app.pars.currStep)]; % step num
            if ~isfield(app.pars,(sn)) % field doesn't exist yet
                app.pars.(sn) = []; % create field
                app.pars.(sn) = ProcessTracesGUI(app.pars.currStep,app.pars.(sn),app.data);
            elseif isempty(app.pars.(sn))
                app.pars.(sn) = ProcessTracesGUI(app.pars.currStep,app.pars.(sn),app.data);
                % if field is empty, default values will be extracted from the script.  
            end
            % if we already have parameters, all we need to do is update
            % the directions
           %  app.pars.currPars = app.pars.(sn);
            appDirections = ['Step ',num2str(app.pars.currStep), ': ',app.pars.(sn).directions];
            app.TextArea.Value = appDirections;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'Process Traces App';
            app.pars.currStep = 1;
            app.pars.maxStep = 9;
            app.data.dataFolder = ''; % initialize data as a structure
            SetupStep(app);
            
        end

        % Button pushed function: NextStepButton
        function NextStepButtonPushed(app, event)
            if app.pars.currStep < app.pars.maxStep
                app.pars.currStep = app.pars.currStep+1;
                app.TextArea.Value = ['Step ',num2str(app.pars.currStep)]; 
                pause(.1);
                SetupStep(app);
            else 
                disp('max step reached!')
            end
        end

        % Button pushed function: BackStepButton
        function BackStepButtonPushed(app, event)
            if app.pars.currStep > 0
                app.pars.currStep = app.pars.currStep-1;
                app.TextArea.Value = ['Step ',num2str(app.pars.currStep)]; 
                pause(.1);
            end
            SetupStep(app);
        end

        % Button pushed function: RunStepButton
        function RunStepButtonPushed(app, event)
            sn = ['step',num2str(app.pars.currStep)]; % step num             
            newData = ProcessTracesGUI(app.pars.currStep,app.pars.(sn),app.data);
            if ~isempty(newData)
                app.data = JoinStructures(app.data,newData,'conflict','keepSecond');
            end
        end

        % Button pushed function: ParametersButton
        function ParametersButtonPushed(app, event)
            % for simplicity directions are passed with parameters, but are not an editable item. 
            %    for readability, we remove these from the list sent to the
            %    parameter selection GUI and then add them back. 
            sn = ['step',num2str(app.pars.currStep)]; % step num   
            currStep = app.pars.(sn);
            directs = '';
            if isfield(currStep,'directions')
                directs = currStep.directions;
                currStep = rmfield(currStep,'directions'); 
                
            end
            currStep = SimpleParameterGUI(currStep);
            app.pars.(sn) = currStep; % not sure it helps to keep a current
            if ~isempty(directs) % need to put the directions back if we supressed them  
                app.pars.(sn).directions = directs;
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RunStepButton = uibutton(app.UIFigure, 'push');
            app.RunStepButton.ButtonPushedFcn = createCallbackFcn(app, @RunStepButtonPushed, true);
            app.RunStepButton.Text = 'Run Step';
            app.NextStepButton = uibutton(app.UIFigure, 'push');
            app.NextStepButton.ButtonPushedFcn = createCallbackFcn(app, @NextStepButtonPushed, true);
            app.NextStepButton.Text = 'Next Step';
            app.BackStepButton = uibutton(app.UIFigure, 'push');
            app.BackStepButton.ButtonPushedFcn = createCallbackFcn(app, @BackStepButtonPushed, true);
            app.BackStepButton.Text = 'Back Step';
            app.TextArea = uitextarea(app.UIFigure);
            app.ParametersButton = uibutton(app.UIFigure, 'push');
            app.ParametersButton.ButtonPushedFcn = createCallbackFcn(app, @ParametersButtonPushed, true);
            app.ParametersButton.Text = 'Parameters';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProcessTracesApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end